classdef Quant4D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Quant4D_Fig                   matlab.ui.Figure
        guiLayout                     matlab.ui.container.GridLayout
        inputPanel                    matlab.ui.container.Panel
        diff_pattern_radio_group      matlab.ui.container.ButtonGroup
        sum_patterns                  matlab.ui.control.RadioButton
        all_patterns                  matlab.ui.control.RadioButton
        display_images                matlab.ui.control.Button
        save_images                   matlab.ui.control.Button
        import_data                   matlab.ui.control.Button
        live_update                   matlab.ui.control.CheckBox
        saveButton                    matlab.ui.control.Button
        autoSaveButton                matlab.ui.control.Button
        controlTab                    matlab.ui.container.TabGroup
        DetectorsTab                  matlab.ui.container.Tab
        detector_panel                matlab.ui.container.Panel
        adf_inner_mrad                matlab.ui.control.Label
        adf_inner_input               matlab.ui.control.NumericEditField
        adf_outer_mrad                matlab.ui.control.Label
        adf_outer_input               matlab.ui.control.NumericEditField
        adf_inner_label               matlab.ui.control.Label
        adf_inner                     matlab.ui.control.Slider
        adf_outer_label               matlab.ui.control.Label
        adf_outer                     matlab.ui.control.Slider
        adf_inner_plus                matlab.ui.control.Button
        adf_inner_minus               matlab.ui.control.Button
        adf_outer_plus                matlab.ui.control.Button
        adf_outer_minus               matlab.ui.control.Button
        linkADF                       matlab.ui.control.StateButton
        DetectorTypeButtonGroup       matlab.ui.container.ButtonGroup
        SegmentedButton               matlab.ui.control.RadioButton
        AnnularRoundButton            matlab.ui.control.RadioButton
        VirtualApertureButton         matlab.ui.control.RadioButton
        CenterofMassButton            matlab.ui.control.RadioButton
        det_rot_panel                 matlab.ui.container.Panel
        det_rot_minus                 matlab.ui.control.Button
        det_angle_deg_3               matlab.ui.control.Label
        det_angle_input               matlab.ui.control.NumericEditField
        det_angle_label               matlab.ui.control.Label
        det_angle                     matlab.ui.control.Slider
        nseg_label                    matlab.ui.control.Label
        nseg                          matlab.ui.control.NumericEditField
        nrung_label                   matlab.ui.control.Label
        nrung                         matlab.ui.control.NumericEditField
        det_rot_plus                  matlab.ui.control.Button
        BrightnessContrastTab         matlab.ui.container.Tab
        ImageLabel                    matlab.ui.control.Label
        ImageDropDown                 matlab.ui.control.DropDown
        histogram_plot                matlab.ui.control.UIAxes
        histogram_panel               matlab.ui.container.Panel
        BrightnessLabel               matlab.ui.control.Label
        brightness                    matlab.ui.control.Slider
        ContrastLabel                 matlab.ui.control.Label
        contrast                      matlab.ui.control.Slider
        GammaLabel                    matlab.ui.control.Label
        gamma                         matlab.ui.control.Slider
        brightness_reset              matlab.ui.control.Button
        contrast_reset                matlab.ui.control.Button
        gamma_reset                   matlab.ui.control.Button
        imStats                       matlab.ui.control.Table
        histLog                       matlab.ui.control.StateButton
        diffractionPanel              matlab.ui.container.Panel
        diffractionPanelGrid          matlab.ui.container.GridLayout
        diffPatternGrid               matlab.ui.container.GridLayout
        diffPattern                   matlab.ui.control.UIAxes
        probeX                        matlab.ui.control.Spinner
        probeY                        matlab.ui.control.Spinner
        stackSlider                   matlab.ui.control.Slider
        diffControlPanel              matlab.ui.container.Panel
        diffControlGrid               matlab.ui.container.GridLayout
        dpLog                         matlab.ui.control.StateButton
        dpReset                       matlab.ui.control.Button
        dpPan                         matlab.ui.control.StateButton
        dpZoom                        matlab.ui.control.StateButton
        alignmentPanel                matlab.ui.container.TabGroup
        alignmentTab                  matlab.ui.container.Tab
        alignmentTabGrid              matlab.ui.container.GridLayout
        scanRotPanelParent            matlab.ui.container.Panel
        scanRotPanelGrid              matlab.ui.container.GridLayout
        alignScanRot                  matlab.ui.control.StateButton
        scanRotPanel                  matlab.ui.container.Panel
        scanRotMinus                  matlab.ui.control.Button
        scanRotInput                  matlab.ui.control.NumericEditField
        det_angle_deg_2               matlab.ui.control.Label
        scanRotPlus                   matlab.ui.control.Button
        scanRotMirror                 matlab.ui.control.CheckBox
        ScanRotationLabel             matlab.ui.control.Label
        scanRot                       matlab.ui.control.Slider
        trans_panel                   matlab.ui.container.Panel
        trans_beam_x_label            matlab.ui.control.Label
        trans_beam_x                  matlab.ui.control.Slider
        trans_beam_y_label            matlab.ui.control.Label
        trans_beam_y                  matlab.ui.control.Slider
        trans_beam_radius_label       matlab.ui.control.Label
        trans_beam_radius             matlab.ui.control.Slider
        conv_angle_label              matlab.ui.control.Label
        alpha                         matlab.ui.control.NumericEditField
        trans_beam_x_plus             matlab.ui.control.Button
        trans_beam_x_minus            matlab.ui.control.Button
        trans_beam_y_plus             matlab.ui.control.Button
        trans_beam_y_minus            matlab.ui.control.Button
        trans_beam_rad_plus           matlab.ui.control.Button
        trans_beam_rad_minus          matlab.ui.control.Button
        align_trans_beam              matlab.ui.control.StateButton
        AutoAlignButton               matlab.ui.control.Button
        ManualCalButton               matlab.ui.control.Button
        CalibrationTab                matlab.ui.container.Tab
        calibrationTabGrid            matlab.ui.container.GridLayout
        DiffractionCalibration        matlab.ui.container.ButtonGroup
        mradButton                    matlab.ui.control.ToggleButton
        invAButton                    matlab.ui.control.ToggleButton
        pixelsmradEditFieldLabel      matlab.ui.control.Label
        transCal                      matlab.ui.control.NumericEditField
        keVLabel                      matlab.ui.control.Label
        keV                           matlab.ui.control.NumericEditField
        TransmittedDiskPosition       matlab.ui.container.Panel
        XEditFieldLabel               matlab.ui.control.Label
        centerX                       matlab.ui.control.NumericEditField
        centerX_label                 matlab.ui.control.Label
        YEditFieldLabel               matlab.ui.control.Label
        centerY                       matlab.ui.control.NumericEditField
        centerY_label                 matlab.ui.control.Label
        TransmittedDiskRadius         matlab.ui.container.ButtonGroup
        transRad_pix                  matlab.ui.control.ToggleButton
        transRad_mrad                 matlab.ui.control.ToggleButton
        transRad                      matlab.ui.control.NumericEditField
        transRad_invA                 matlab.ui.control.ToggleButton
        EditManualCalButton           matlab.ui.control.StateButton
        colorwheelTab                 matlab.ui.container.Tab
        colorwheel_plot               matlab.ui.control.UIAxes
        virtualApertureTab            matlab.ui.container.Tab
        virtualApertureGrid           matlab.ui.container.GridLayout
        SymmetryoperationsPanel       matlab.ui.container.Panel
        mirrorPanel                   matlab.ui.container.Panel
        ap_mirror_minus               matlab.ui.control.Button
        MirrorPlaneRotationLabel      matlab.ui.control.Label
        ap_mirror_angle               matlab.ui.control.Slider
        aperture_y_Label_2            matlab.ui.control.Label
        ap_mirror_input               matlab.ui.control.NumericEditField
        ap_mirror_plus                matlab.ui.control.Button
        ap_mirror_reset               matlab.ui.control.Button
        ap_mirror                     matlab.ui.control.CheckBox
        ApertureSymmetrySpinnerLabel  matlab.ui.control.Label
        aperture_symmetry             matlab.ui.control.Spinner
        ap_negative                   matlab.ui.control.CheckBox
        ap_lattice                    matlab.ui.control.CheckBox
        virtualAperturePanelParent    matlab.ui.container.Panel
        CoordinatesButtonGroup        matlab.ui.container.ButtonGroup
        CartesianButton               matlab.ui.control.RadioButton
        PolarButton                   matlab.ui.control.RadioButton
        virtualAperturePanel          matlab.ui.container.Panel
        DistanceLabel                 matlab.ui.control.Label
        aperture_x                    matlab.ui.control.Slider
        RotationLabel                 matlab.ui.control.Label
        aperture_y                    matlab.ui.control.Slider
        ap_x_plus                     matlab.ui.control.Button
        ap_x_minus                    matlab.ui.control.Button
        ap_y_plus                     matlab.ui.control.Button
        ap_y_minus                    matlab.ui.control.Button
        ap_x_reset                    matlab.ui.control.Button
        ap_y_reset                    matlab.ui.control.Button
        adf_inner_mrad_2              matlab.ui.control.Label
        aperture_x_input              matlab.ui.control.NumericEditField
        aperture_y_Label              matlab.ui.control.Label
        aperture_y_input              matlab.ui.control.NumericEditField
        ap_size_plus                  matlab.ui.control.Button
        ap_size_minus                 matlab.ui.control.Button
        SizeLabel                     matlab.ui.control.Label
        aperture_size                 matlab.ui.control.Slider
        adf_inner_mrad_4              matlab.ui.control.Label
        aperture_size_input           matlab.ui.control.NumericEditField
        ap_rotAngle                   matlab.ui.control.DropDown
        ap_left_rot                   matlab.ui.control.Button
        ap_right_rot                  matlab.ui.control.Button
        outputPanel                   matlab.ui.container.TabGroup
        bfdfTab                       matlab.ui.container.Tab
        bfdfGrid                      matlab.ui.container.GridLayout
        bfdf_image                    matlab.ui.control.UIAxes
        comTab                        matlab.ui.container.Tab
        comGrid                       matlab.ui.container.GridLayout
        comx                          matlab.ui.control.UIAxes
        comMag                        matlab.ui.control.UIAxes
        comy                          matlab.ui.control.UIAxes
        comPhase                      matlab.ui.control.UIAxes
        comxPanel                     matlab.ui.container.Panel
        comxGrid                      matlab.ui.container.GridLayout
        comxButtonGroup               matlab.ui.container.ButtonGroup
        comx_ADF                      matlab.ui.control.ToggleButton
        comx_comx                     matlab.ui.control.ToggleButton
        comx_comMag                   matlab.ui.control.ToggleButton
        comMagPanel                   matlab.ui.container.Panel
        comMagGrid                    matlab.ui.container.GridLayout
        comMagButtonGroup             matlab.ui.container.ButtonGroup
        comMag_dcom                   matlab.ui.control.ToggleButton
        comMag_comMag                 matlab.ui.control.ToggleButton
        comMag_icom                   matlab.ui.control.ToggleButton
        comyPanel                     matlab.ui.container.Panel
        comyGrid                      matlab.ui.container.GridLayout
        comyButtonGroup               matlab.ui.container.ButtonGroup
        comy_ADF                      matlab.ui.control.ToggleButton
        comy_comy                     matlab.ui.control.ToggleButton
        comy_comPhase                 matlab.ui.control.ToggleButton
        comAnglePanel                 matlab.ui.container.Panel
        comAngleGrid                  matlab.ui.container.GridLayout
        comAngleButtonGroup           matlab.ui.container.ButtonGroup
        comAngle_dcom                 matlab.ui.control.ToggleButton
        comAngle_com                  matlab.ui.control.ToggleButton
        comAngle_icom                 matlab.ui.control.ToggleButton
        vectorFieldTab                matlab.ui.container.Tab
        vectorFieldGrid               matlab.ui.container.GridLayout
        vectorField                   matlab.ui.control.UIAxes
        vectorFieldPanel              matlab.ui.container.Panel
        vectorFieldPanelGrid          matlab.ui.container.GridLayout
        vectZoom                      matlab.ui.control.StateButton
        vectPan                       matlab.ui.control.StateButton
        vectReset                     matlab.ui.control.Button
        saveVecField                  matlab.ui.control.Button
        vectSubsample                 matlab.ui.control.DropDown
        vectButtonGroup               matlab.ui.container.ButtonGroup
        VectorButton                  matlab.ui.control.RadioButton
        MagnitudeButton               matlab.ui.control.RadioButton
        PhaseButton                   matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        adf
        annotations
        aperture
        ax_choice
        center
        color
        data
        device
        image_choices
        images
        limits
        m
        mask
        params
        savePrefs
        temp_angle
        timer
    end
    
    methods (Access = public)
        
        function import_bar = autoAlign(app,event,import_bar)
            import_bar.Message = sprintf('Aligning transmitted beam\nPlease Wait...');
            
            % Align transmitted beam by trying to find the transmitted disk in the PACBED pattern (app.images.pacbed),
            % apply slight gaussaian blur of 5 pixels to help the fit if necessary
            warning('off')
            [app.center, app.params.radius, ~] = imfindcircles(app.images.pacbed,[1 round(max(app.params.nopiy,app.params.nopix)/2)]);
            warning('on')
            if isempty(app.center)
                warning('off')
                [app.center, app.params.radius, ~] = imfindcircles(imgaussfilt(app.images.pacbed,5),[1 round(max(app.params.nopiy,app.params.nopix)/2)]);
                warning('on')
            end
            
            % Close the status bar
            close(import_bar)
            
            % Find circle closest to the center of the diffraction pattern,
            % plot for user confirmation
            
            if ~isempty(app.center)
                % Assign radius and center value(s) found above using
                % imfindcircles based on whichever is found to be closest
                % to the center of the diffraction pattern (app.center)
                tmp_rad    = app.params.radius(sqrt(sum((app.center-[app.params.nopiy app.params.nopix]/2).^2,2))==min(sqrt(sum((app.center-[app.params.nopiy app.params.nopix]/2).^2,2))));
                tmp_center = app.center(sqrt(sum((app.center-[app.params.nopiy app.params.nopix]/2).^2,2))==min(sqrt(sum((app.center-[app.params.nopiy app.params.nopix]/2).^2,2))),:);
                plotCircle(app,'disk_edge',app.diffPattern,tmp_rad,tmp_center,'r')
                axis(app.diffPattern,gather([tmp_center(1)-2*tmp_rad tmp_center(1)+2*tmp_rad tmp_center(2)-2*tmp_rad tmp_center(2)+2*tmp_rad]));
                selection  = uiconfirm(app.Quant4D_Fig,'Is the transmitted beam correctly identified?',...
                    'Confirm transmitted beam location/size',...
                    'Options',{'Yes','No, Manually Align'},'DefaultOption',1);
                %'Options',{'Yes','Fine Tune','No, Manually Align'},'DefaultOption',1);
                axis(app.diffPattern,app.limits.dp);
                
                switch selection
                    case 'Yes' % Correct choice for transmitted beam
                        app.center = tmp_center;
                        app.params.radius = tmp_rad;
                        delete(app.annotations.disk_edge)
                        % Do a radial integration for BF/ADF images
                        import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','Processing Data...','Message',sprintf('Generating radial masks... Please Wait\n'),'Indeterminate','off');
                        import_bar = integrateADF(app,import_bar);
                        
                    otherwise % Wrong choice for transmitted beam
                        app.align_trans_beam.Enable='on';
                        app.align_trans_beam.Value=1;
                        
                        switch selection
                            case 'No, Manually Align'
                                delete(app.annotations.disk_edge);
                                axis(app.diffPattern,app.limits.dp);
                                % Set some initial values, these will be refined later
                                app.center = app.params.center0; % reset transmitted disk location to the center of the diffraction pattern
                                app.params.radius = 20;          % reset transmitted disk size to 20 pixels
                            otherwise % Fine Tune
                                axis(app.diffPattern,[tmp_center(1)-2*tmp_rad tmp_center(1)+2*tmp_rad tmp_center(2)-2*tmp_rad tmp_center(2)+2*tmp_rad]);
                                app.center = tmp_center;
                                app.params.radius = tmp_rad;
                        end
                        app.params.rmax   = app.params.max_dist;
                        app.params.mrad_per_pix = app.alpha.Value/app.params.radius;
                        set(app.trans_beam_x,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                        set(app.trans_beam_y,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                        set(app.trans_beam_radius,'Limits',[0,app.params.rmax],'Value',app.params.radius);
                        set(app.stackSlider,'Limits',[1,app.params.frames]);
                        set(app.adf_inner,'Limits',[0,app.params.rmax],'Value',app.params.radius);app.adf_inner_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                        set(app.adf_outer,'Limits',[0,app.params.rmax],'Value',app.params.rmax/2);app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                        set(app.det_angle,'Limits',[-pi,pi],'Value',app.det_angle_input.Value*pi/180);app.det_angle_input.Value=app.det_angle.Value*180/pi;
                        set(app.diffControlGrid.Children,'Enable','on');
                        
                        tmp.Source = app.align_trans_beam;
                        alignment(app,tmp)
                        
                        % Wait until user finishes aligning the transmitted beam
                        waitfor(app.align_trans_beam,'Value',0)
                        
                        % Reset any zoom on the transmitted beam
                        tmp.Source=app.dpReset;
                        imageControls(app,tmp)
                        
                        % Repopulate the status bar for the next part of the
                        % import process
                        import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','Processing Data...','Message',sprintf('100%% Complete!\nForming Images... Please Wait'),'Indeterminate','off');
                end
            else % No transmitted beam automatically detected
                uiconfirm(app.Quant4D_Fig,'Please manually align the transmitted beam location/size','No transmitted beam automatically detected...','Options',{'OK'},'Icon','warning');
                
                try delete(app.annotations.disk_edge); catch; end
                app.align_trans_beam.Enable='on';
                app.align_trans_beam.Value=1;
                
                % Set some initial values, these will be refined later
                app.center = app.params.center0; % reset transmitted disk location to the center of the diffraction pattern
                app.params.radius = 20;          % reset transmitted disk size to 20 pixels
                app.params.rmax   = app.params.max_dist;
                app.params.mrad_per_pix = app.alpha.Value/app.params.radius;
                set(app.trans_beam_x,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                set(app.trans_beam_y,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                set(app.trans_beam_radius,'Limits',[0,app.params.rmax],'Value',app.params.radius);
                set(app.stackSlider,'Limits',[1,app.params.frames]);
                set(app.adf_inner,'Limits',[0,app.params.rmax],'Value',app.params.radius);app.adf_inner_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                set(app.adf_outer,'Limits',[0,app.params.rmax],'Value',app.params.rmax/2);app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                set(app.det_angle,'Limits',[-pi,pi],'Value',app.det_angle_input.Value*pi/180);app.det_angle_input.Value=app.det_angle.Value*180/pi;
                set(app.diffControlGrid.Children,'Enable','on');
                
                alignment(app,event)
                
                % Wait until user finishes aligning the transmitted beam
                waitfor(app.align_trans_beam,'Value',0)
                
                % Reset any zoom on the transmitted beam
                tmp.Source=app.dpReset;
                imageControls(app,tmp)
                
                % Repopulate the status bar for the next part of the
                % import process
                import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','Processing Data...','Message',sprintf('100%% Complete!\nForming Images... Please Wait'),'Indeterminate','off');
                import_bar = integrateADF(app,import_bar);
            end
            app.centerX.Value = app.center(1);
            app.centerY.Value = app.center(2);
        end
        
        % Function to clear any plotted images/data
        function clearPlots(app)
            app.bfdf_image.Visible='off';cla(app.bfdf_image);
            cla(app.comMag);
            cla(app.comPhase);
            cla(app.comx);
            cla(app.comy);
            cla(app.vectorField);
        end
        
        % Function to delete any plotted detectors if they exist
        function clearDetectors(app)
            delete(findall(app.Quant4D_Fig,'Parent',app.diffPattern,'-not','Type','Image'));
        end
        
        % Show the BF/DF image
        function displayADF(app,event)
            integrateMasks(app,event)
            plotImage(app,'app.images.adf');
        end
        
        function displayCoM(app)
            % CoM angle is calculated from the arctangent of the difference images (CoMx and CoMy)
            app.images.com_phase = atan2(-app.images.comy,-app.images.comx);%-app.det_angle.Value+app.scanRot.Value;
            app.images.com_phase = app.images.com_phase .* (app.images.com_phase >= 0) + (app.images.com_phase + 2 * pi) .* (app.images.com_phase < 0); %adjust atan2 range from [-pi/2:pi/2) to [0:2pi)
            
            % CoM magnitude is the length of the vector -- in mrad
            app.images.com_mag = sqrt(app.images.comx.^2+app.images.comy.^2);
            
            % Normalize com_mag range to [0:1] for alpha channel
            app.images.com_mag_norm = rescale(app.images.com_mag);
            
            % Make iCoM/iDPC and dCoM/dDPC images - based on maths from Lazic et al.,
            % (https://doi.org/10.1016/j.ultramic.2015.10.011)
            [k.x,k.y]=meshgrid(floor(-app.params.framesx/2):floor(-app.params.framesx/2)+app.params.framesx-1,...
                               floor(-app.params.framesy/2):floor(-app.params.framesy/2)+app.params.framesy-1);
            k.radius = sqrt(k.x.^2+k.y.^2); k.radius(k.radius==0) = 1; % a zero pixel will mess up the maths here
            
            k.x = fftshift(k.x);
            k.y = fftshift(k.y);
            k.radius = fftshift(k.radius);
            
            % integrated center of mass
            app.images.icom = real(ifft2(k.x.*fft2(app.images.comx)./(2*pi*1j*k.radius.^2))+...
                ifft2(k.y.*fft2(app.images.comy)./(2*pi*1j*k.radius.^2)));
            % differential center of mass
            app.images.dcom = real(ifft2(2*pi*1i*k.x.*fft2(app.images.comx)+2*pi*1i*k.y.*fft2(app.images.comy)));
            
            % Plot difference/com(x) images - first clear any
            % instances of 'app.comx' axis in app.image_choices.axis
            app.image_choices.axis(cellfun(@(c) any(strcmp(c, 'app.comx')), app.image_choices.axis))={''};
            switch app.comxButtonGroup.SelectedObject
                case app.comx_ADF
                    % add 'app.comx' axis to the first empty column in the row corresponding to the image 'app.images.adf' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.adf'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.adf'),:)),1)} = 'app.comx';
                    plotImage(app,'app.images.adf');
                case app.comx_comx
                    % add 'app.comx' axis to the first empty column in the row corresponding to the image 'app.images.comx' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.comx'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.comx'),:)),1)} = 'app.comx';
                    plotImage(app,'app.images.comx');
                case app.comx_comMag
                    % add 'app.comx' axis to the first empty column in the row corresponding to the image 'app.images.com_mag' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.com_mag'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.com_mag'),:)),1)} = 'app.comx';
                    plotImage(app,'app.images.com_mag');
            end
            
            % Plot difference/com(y) images - first clear any
            % instances of 'app.comy' axis in app.image_choices.axis
            app.image_choices.axis(cellfun(@(c) any(strcmp(c, 'app.comy')), app.image_choices.axis))={''};
            switch app.comyButtonGroup.SelectedObject
                case app.comy_ADF
                    % add 'app.comy' axis to the first empty column in the row corresponding to the image 'app.images.adf' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.adf'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.adf'),:)),1)} = 'app.comy';
                    plotImage(app,'app.images.adf');
                case app.comy_comy
                    % add 'app.comy' axis to the first empty column in the row corresponding to the image 'app.images.comy' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.comy'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.comy'),:)),1)} = 'app.comy';
                    plotImage(app,'app.images.comy');
                case app.comy_comPhase
                    % add 'app.comy' axis to the first empty column in the row corresponding to the image 'app.images.com_phase' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.com_phase'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.com_phase'),:)),1)} = 'app.comy';
                    plotImage(app,'app.images.com_phase');
            end
            
            % Plot CoM/DPC magnitude, iCom/iDPC, and dCoM/dDPC images - first clear any
            % instances of 'app.comMag' axis in app.image_choices.axis
            app.image_choices.axis(cellfun(@(c) any(strcmp(c, 'app.comMag')), app.image_choices.axis))={''};
            switch app.comMagButtonGroup.SelectedObject
                case app.comMag_dcom
                    % add 'app.comMag' axis to the first empty column in the row corresponding to the image 'app.images.dcom' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.dcom'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.dcom'),:)),1)} = 'app.comMag';
                    plotImage(app,'app.images.dcom');
                case app.comMag_comMag
                    % add 'app.comMag' axis to the first empty column in the row corresponding to the image 'app.images.com_mag' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.com_mag'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.com_mag'),:)),1)} = 'app.comMag';
                    plotImage(app,'app.images.com_mag');
                case app.comMag_icom
                    % add 'app.comMag' axis to the first empty column in the row corresponding to the image 'app.images.icom' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.icom'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.icom'),:)),1)} = 'app.comMag';
                    plotImage(app,'app.images.icom');
            end
            
            % Plot CoM/DPC phase, iCom/iDPC, and dCoM/dDPC images - first clear any
            % instances of 'app.comPhase' axis in app.image_choices.axis
            app.image_choices.axis(cellfun(@(c) any(strcmp(c, 'app.comPhase')), app.image_choices.axis))={''};
            switch app.comAngleButtonGroup.SelectedObject
                case app.comAngle_dcom
                    % add 'app.comPhase' axis to the first empty column in the row corresponding to the image 'app.images.dcom' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.dcom'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.dcom'),:)),1)} = 'app.comPhase';
                    plotImage(app,'app.images.dcom');
                case app.comAngle_com
                    app.comPhase.NextPlot='replace';
                    % plot the CoM/DPC vector colorized by phase and saturated by magnitude
                    imagesc(app.comPhase,app.images.com_phase,'AlphaData',app.images.com_mag_norm);
                    colormap(app.comPhase,hsv);caxis(app.comPhase,[0 2*pi]);
                    set(app.comPhase,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','Color','k','XLim',[1 app.params.framesx],'YLim',[1 app.params.framesy]);
                    set(app.comPhase,'Color','k');app.comPhase.Toolbar.Visible='off';
                case app.comAngle_icom
                    % add 'app.comPhase' axis to the first empty column in the row corresponding to the image 'app.images.icom' in structured array 'app.image_choices'
                    app.image_choices.axis{strcmp(app.image_choices.image,'app.images.icom'),find(cellfun(@(c) any(strcmp(c, '')), app.image_choices.axis(strcmp(app.image_choices.image,'app.images.icom'),:)),1)} = 'app.comPhase';
                    plotImage(app,'app.images.icom');
            end
            
            plotVectorField(app)
            
        end
        
        function import4D(app,params_in,mem_in)
            % nopiy, nopix          -	number of pixels in the diffraction pattern
            % framesy, framesx      -	number of probe positions in the image
            % bitdepth              -   bitdepth of the dataset
            % header                -   BYTES before each diffraction pattern
            % footer                -   BYTES after each diffraction patterns
            % ordering              -   endianness (big or little)
            % gpu                   -   device selection if acceleration is enabled
            % mem.type              -   physical or virtual memory loading
            % mem.convertFloat      -   only used for integer type datasets, convert int to float
            
            app.params.nopiy        = params_in.nopiy;
            app.params.nopix        = params_in.nopix;
            app.params.framesy      = params_in.framesy;
            app.params.framesx      = params_in.framesx;
            app.params.header       = params_in.header;
            app.params.footer       = params_in.footer;
            app.params.ordering     = params_in.ordering;
            app.params.bitdepth     = params_in.bitdepth{:};
            app.params.gpu          = params_in.gpu;
            app.params.mem          = mem_in.type;
            app.params.convertFloat = mem_in.convertFloat;
            
            if app.params.gpu > 0
                app.device = gpuDevice(app.params.gpu);
            end
            
            % figure out number of bytes per element
            tmp = ones(1,1,app.params.bitdepth);
            app.params.bytesize = whos('tmp').bytes;
            
        end
        
        function import_bar = integrateADF(app,import_bar)
            % Make pixel array for mask
            [xgrid, ygrid] = meshgrid(1:app.params.nopix,1:app.params.nopiy);
            if app.params.gpu>0
                xgrid = gpuArray(xgrid);
                ygrid = gpuArray(ygrid);
            end
            
            % Find pixel farthest from center of transmitted disk
            app.params.max_dist=gather(floor(max(max(sqrt((xgrid-app.center(1)).^2+(ygrid-app.center(2)).^2)))*2)/2);
            
            % Integrate in 0.5 pixel increments all the way out to the furthest corner
            import_bar.Indeterminate = 'on';
            import_bar.Message = 'Generating radial masks... Please Wait';
            dist = 0:0.5:app.params.max_dist;
            app.mask.adf = sqrt((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2);
            app.mask.adf = permute(bsxfun(@and,bsxfun(@ge,app.mask.adf(:),dist),bsxfun(@lt,app.mask.adf(:),dist+0.5)),[2 1]);
            
            % Multiply data by the ADF masks
            import_bar.Message = sprintf('Performing radial integration... Please Wait\n');
            % Matlab does not implement mtimes() for integer class arrays,
            % so check if it is an integer. If it is an integer class array
            % (i.e. Medipix data), check if it should be multiplied as
            % single or double precision float
            if isinteger(app.data) && app.params.bytesize<=4 % int32/uint32 (or less) data
                app.adf.images = permute(reshape(app.mask.adf*single(app.data),[size(dist,2) app.params.framesx app.params.framesy]),[3 2 1]);
            elseif isinteger(app.data) && app.params.bytesize>4 % int64/uint64 data
                app.adf.images = permute(reshape(app.mask.adf*double(app.data),[size(dist,2) app.params.framesx app.params.framesy]),[3 2 1]);
            else % floating point data
                app.adf.images = permute(reshape(app.mask.adf*app.data,[size(dist,2) app.params.framesx app.params.framesy]),[3 2 1]);
            end
            
        end
        
        function integrateMasks(app,event)
            % Make pixel array for masks
            [xgrid, ygrid] = meshgrid(1:app.params.nopix,1:app.params.nopiy);
            
            % move to GPU if selected
            if app.params.gpu>0
                xgrid = gpuArray(xgrid);ygrid=gpuArray(ygrid);
            end
            
            % switch between detector types (except annular/round)
            switch app.DetectorTypeButtonGroup.SelectedObject
                case app.CenterofMassButton
                    % Mask off diffraction pattern
                    app.mask.sum = ((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) <= app.adf_outer.Value^2 & ... % less than outer angle
                                   ((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) >= app.adf_inner.Value^2;      % greater than inner angle
                    app.mask.sum = app.mask.sum(:);
                    
                    % Set up a pixel grid
                    if app.params.gpu>0
                        app.images.com = zeros([size(app.data,2),2],app.params.bitdepth,'gpuArray');
                    else
                        app.images.com = zeros([size(app.data,2),2],app.params.bitdepth);
                    end
                    [xx,yy] = ndgrid(1:size(app.images.pacbed));
                    xx = xx(:).*app.mask.sum; yy = yy(:).*app.mask.sum;
                    
                    % Adjust for scan rotation
                    R = [cos(app.scanRot.Value) -sin(app.scanRot.Value); sin(app.scanRot.Value) cos(app.scanRot.Value)];
                    
                    % Integrated mass of each diffraction pattern within the mask:
                    % Center of Mass adjusted to the center of the diffraction pattern
                    % Matlab does not implement mtimes() for integer class arrays,
                    % so check if it is an integer. If it is an integer class array
                    % (i.e. Medipix data), check if it should be multiplied as
                    % single or double precision float
                    if isinteger(app.data) && app.params.bytesize<=4 % int32/uint32 (or less) data
                        M = app.mask.sum'*single(app.data);
                        app.images.com(:,1) = xx'*single(app.data)./M-app.center(1);
                        app.images.com(:,2) = yy'*single(app.data)./M-app.center(2);
                        app.images.com = single(app.images.com)*R;
                    elseif isinteger(app.data) && app.params.bytesize>4 % int64/uint64 data
                        M = app.mask.sum'*double(app.data);
                        app.images.com(:,1) = xx'*double(app.data)./M-app.center(1);
                        app.images.com(:,2) = yy'*double(app.data)./M-app.center(2);
                        app.images.com = double(app.images.com)*R;
                    else % floating point data
                        M = app.mask.sum'*app.data;
                        app.images.com(:,1) = xx'*app.data./M-app.center(1);
                        app.images.com(:,2) = yy'*app.data./M-app.center(2);
                        app.images.com = app.images.com*R;
                    end
                    
                    % CoM components
                    app.images.comx = reshape(app.images.com(:,2),[app.params.framesx app.params.framesy])'*app.params.mrad_per_pix;
                    app.images.comy = reshape(app.images.com(:,1),[app.params.framesx app.params.framesy])'*app.params.mrad_per_pix;
                    
                case app.SegmentedButton
                    xgrid = xgrid(:);
                    ygrid = ygrid(:);
                    
                    % Calculate the atan of all pixel positions within the image
                    a = atan2(ygrid-app.center(2),xgrid-app.center(1));     % Get atan from -pi to pi
                    a = a - app.det_angle.Value;                            % subtract off any detector rotation applied
                    a = a + pi/app.nseg.Value;                              % shift atan by half of a segment span
                    a = a .* (a >= 0) + (a + 2 * pi) .* (a < 0);            % shift the negative values of atan up by 2pi
                    a = a .* (a<2*pi) + (a-2*pi) .* (a>=2*pi);              % Get total range of atan from [0,2pi)
                    
                    % Preallocate a DPC/COM vector image that will be the sum of all segments (i.e. DPC_A, DPC_B, ...)
                    if app.params.gpu>0
                        app.images.com=zeros(app.params.framesy,app.params.framesx,2,app.params.bitdepth,'gpuArray');
                        com_length = zeros(app.nrung.Value*app.nseg.Value,2,app.params.bitdepth,'gpuArray');
                        app.mask.all = zeros(app.nrung.Value*app.nseg.Value,app.params.nopix*app.params.nopiy,'logical','gpuArray');
                    else
                        app.images.com=zeros(app.params.framesy,app.params.framesx,2,app.params.bitdepth);
                        com_length = zeros(app.nrung.Value*app.nseg.Value,2,app.params.bitdepth);
                        app.mask.all = zeros(app.nrung.Value*app.nseg.Value,app.params.nopix*app.params.nopiy,'logical');
                    end
                    
                    for j=1:app.nrung.Value
                        for i=1:app.nseg.Value
                            % Define vectors k1 and k2 that correspond to the inner and outer angles of the detector for a given number of rungs
                            k1=(j-1)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                            k2=(j)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                            
                            app.mask.all(app.nseg.Value*(j-1)+i,:)=((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) < k2^2 & ...   % less than outer angle
                                ((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) >= k1^2 & ...  % greater than/equal to inner angle
                                a >= (i-1)*2*pi/app.nseg.Value & ...                               % greater than the start of the segment angle
                                a <  (i)*2*pi/app.nseg.Value;                                      % less than the end of the segmenet angle
                            
                            % Adjust for scan rotation
                            R = [cos(-app.scanRot.Value) -sin(-app.scanRot.Value); sin(-app.scanRot.Value) cos(-app.scanRot.Value)];
                            
                            % Find center of mass of each segment in each rung
                            com_length(app.nseg.Value*(j-1)+i,:) = 2*sin(2*pi/app.nseg.Value/2)/(3*2*pi/app.nseg.Value/2)*(k1^3-k2^3)/(k1^2-k2^2)*...
                                [cos((i-1)*2*pi/app.nseg.Value+app.det_angle.Value),...
                                sin((i-1)*2*pi/app.nseg.Value+app.det_angle.Value)]*R;
                        end
                    end
                    
                    app.mask.sum = sum(app.mask.all,1);
                    
                    % integrated mass of each diffraction pattern within the mask:
                    % Matlab does not implement mtimes() for integer class arrays,
                    % so check if it is an integer. If it is an integer class array
                    % (i.e. Medipix data), check if it should be multiplied as
                    % single or double precision float
                    if isinteger(app.data) && app.params.bytesize<=4 % int32/uint32 (or less) data
                        M = app.mask.sum*single(app.data);
                        app.images.com = permute(reshape(permute(single(app.mask.all)*single(app.data)./M,[2 1])*single(com_length),[app.params.framesx app.params.framesy 2]),[2 1 3]);
                    elseif isinteger(app.data) && app.params.bytesize>4 % int64/uint64 data
                        M = app.mask.sum*double(app.data);
                        app.images.com = permute(reshape(permute(double(app.mask.all)*double(app.data)./M,[2 1])*double(com_length),[app.params.framesx app.params.framesy 2]),[2 1 3]);
                    else % floating point data
                        M = app.mask.sum*(app.data);
                        app.images.com = permute(reshape(permute(app.mask.all*(app.data)./M,[2 1])*com_length,[app.params.framesx app.params.framesy 2]),[2 1 3]);
                    end
                    
                    % pull out horizontal (x) and vertical (y) components
                    % of the CoM and convert to mrad
                    app.images.comx = app.images.com(:,:,2)*app.params.mrad_per_pix;
                    app.images.comy = app.images.com(:,:,1)*app.params.mrad_per_pix;
                    
                case app.VirtualApertureButton
                    xgrid=xgrid(:); ygrid=ygrid(:);
                    % Clear any previous virtual aperture mask
                    app.mask.bfdf=zeros(app.params.nopix*app.params.nopiy,1,'logical');
                    app.images.adf=zeros(app.params.framesx,app.params.framesy,app.params.bitdepth);
                    
                    for ii=1:app.aperture_symmetry.Value
                        % Determine the aperture angle and rotation matrix
                        theta=2*pi/app.aperture_symmetry.Value*(ii-1);
                        R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                        
                        % Determine whether the aperture is to be mirrored
                        % or not, then plot the aperture
                        switch app.ap_mirror.Value
                            case 0 % Not mirrored
                                temp_center=(app.aperture.center-app.center)*R+app.center;
                            case 1 % Mirrored
                                if strcmp(event.EventName,'ValueChanging')
                                    switch event.Source
                                        case app.aperture_y    % changing aperture rotation angle
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                        case app.ap_mirror_angle % changing mirror plane roation angle
                                            R_mirror = [cos(event.Value-pi/2) -sin(event.Value-pi/2); sin(event.Value-pi/2) cos(event.Value-pi/2)];
                                        case app.aperture_x    % changing aperture distance
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                            app.aperture_x.Value = event.Value;
                                        case app.aperture_size   % changing aperture size
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                            app.aperture_size.Value = event.Value;
                                        otherwise
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                    end
                                    temp_center=[(app.aperture.center-app.center)*R;((app.aperture.center-app.center)*R)*R_mirror.*[-1 1]*(R_mirror.*[1 -1;-1 1])]+app.center;
                                else
                                    R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                    temp_center=[(app.aperture.center-app.center)*R;((app.aperture.center-app.center)*R)*R_mirror.*[-1 1]*(R_mirror.*[1 -1;-1 1])]+app.center;
                                end
                        end
                        
                        % Generate aperture mask, add to any previous
                        % apertures if more than 1
                        for jj=1:size(temp_center,1)
                            app.mask.bfdf=logical(app.mask.bfdf+(((xgrid-temp_center(jj,1)).^2 + (ygrid-temp_center(jj,2)).^2) <= app.aperture.radius^2));
                        end
                        
                    end
                    
                    % Negative mask checkbox?
                    if app.ap_negative.Value==1
                        app.mask.bfdf = 1 - app.mask.bfdf;
                    end
                    
                    % Integrate wihtin the mask
                    % Matlab does not implement mtimes() for integer class arrays,
                    % so check if it is an integer. If it is an integer class array
                    % (i.e. Medipix data), check if it should be multiplied as
                    % single or double precision float
                    if isinteger(app.data) && app.params.bytesize<=4 % int32/uint32 (or less) data
                        app.images.adf = reshape(app.mask.bfdf'*single(app.data),[app.params.framesx app.params.framesy])';
                    elseif isinteger(app.data) && app.params.bytesize>4 % int64/uint64 data
                        app.images.adf = reshape(app.mask.bfdf'*double(app.data),[app.params.framesx app.params.framesy])';
                    else % floating point data
                        app.images.adf = reshape(app.mask.bfdf'*app.data,[app.params.framesx app.params.framesy])';
                    end
            end
        end
        
        function output = mrad2invA(app,input,type)
            % calculate wavevector for a given accelerating voltage - app.keV
            c1  = 9.78475598e-07;
            c2  = 12.2642596;
            ak1 = sqrt((app.keV.Value*1000)+c1*(app.keV.Value*1000)^2)/c2;
            
            switch type
                case 'mrad'
                    output=tan(input/1000)*ak1;
                case 'invA'
                    output=1000*atan(input/ak1);
            end
        end
        
        % Generic funciton for plotting cirlces with a
        % specified name, axis, radius, center point, and line color
        function plotCircle(app,name,ax,radius,center,color)
            % Make sure not to overwrite the image already plotted
            ax.NextPlot='add';
            
            if contains(name,'.')
                name = strsplit(name,'.');
                % try to remove the previous verison of the circle if it exists
                try delete(app.annotations.(name{1}).(name{2}));catch;end
                
                % Plot circle - allow for multiple circles to be plotted
                % with one call (i.e. for mirrored virtual apertures)
                app.annotations.(name{1}).(name{2})=plot(ax,...
                    cos(0:pi/60:2*pi)'*radius'+center(:,1)',...
                    sin(0:pi/60:2*pi)'*radius'+center(:,2)',...
                    'Color',color,'LineWidth',4);
            else
                % try to remove the previous verison of the circle if it exists
                try delete(app.annotations.(name));catch;end
                
                % Plot circle - allow for multiple circles to be plotted
                % with one call (i.e. for mirrored virtual apertures)
                app.annotations.(name)=plot(ax,...
                    cos(0:pi/60:2*pi)'*radius'+center(:,1)',...
                    sin(0:pi/60:2*pi)'*radius'+center(:,2)',...
                    'Color',color,'LineWidth',4);
            end
            
            % Allow next call to plot on the selected axis to replace the
            % current plotted information
            ax.NextPlot='replace';
        end
        
        % Plot the selected detector over top of the PACBED pattern (or
        % individual diffraction patterns)
        function plotDetector(app,event)
            
            switch app.DetectorTypeButtonGroup.SelectedObject
                % Plot segmented detector + segments
                case app.SegmentedButton
                    plotSegments(app);
                    plotCircle(app,'inner_disk',app.diffPattern,app.adf_inner.Value,app.center,'g')
                    plotCircle(app,'outer_disk',app.diffPattern,app.adf_outer.Value,app.center,'r')
                    
                    % Plot virtual aperture(s)
                case app.VirtualApertureButton
                    for ii=1:app.aperture_symmetry.Value
                        % Determine the aperture angle and rotation matrix
                        theta=2*pi/app.aperture_symmetry.Value*(ii-1);
                        R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                        
                        % Determine whether the aperture is to be mirrored
                        % or not, then plot the aperture
                        switch app.ap_mirror.Value
                            case 0 % Not mirrored
                                temp_center(ii,:)=(app.aperture.center-app.center)*R+app.center; %#ok<*AGROW>
                            case 1 % Mirrored
                                app.diffPattern.NextPlot='add';
                                
                                if strcmp(event.EventName,'ValueChanging')
                                    switch event.Source
                                        case app.aperture_y    % changing aperture rotation angle
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                        case app.ap_mirror_angle % changing mirror plane roation angle
                                            R_mirror = [cos(event.Value-pi/2) -sin(event.Value-pi/2); sin(event.Value-pi/2) cos(event.Value-pi/2)];
                                        case app.aperture_x    % changing aperture distance
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                            app.aperture_x.Value = event.Value;
                                        case app.aperture_size   % changing aperture size
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                            app.aperture_size.Value = event.Value;
                                        otherwise
                                            R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                    end
                                    temp_center(2*(ii-1)+1:2*ii,:)=[(app.aperture.center-app.center)*R;((app.aperture.center-app.center)*R)*R_mirror.*[-1 1]*(R_mirror.*[1 -1;-1 1])]+app.center;
                                else % ValueChanged
                                    R_mirror = [cos(app.ap_mirror_angle.Value-pi/2) -sin(app.ap_mirror_angle.Value-pi/2); sin(app.ap_mirror_angle.Value-pi/2) cos(app.ap_mirror_angle.Value-pi/2)];
                                    temp_center(2*(ii-1)+1:2*ii,:)=[(app.aperture.center-app.center)*R;((app.aperture.center-app.center)*R)*R_mirror.*[-1 1]*(R_mirror.*[1 -1;-1 1])]+app.center;
                                    app.diffPattern.NextPlot='add';
                                end
                                % Plot the mirror axis line (unless the
                                % user is dragging the mirror axis rotation
                                % slider, then plot live above using event.Value
                                try delete(app.annotations.mirror_line);catch;end
                                if strcmp(event.EventName,'ValueChanging') && event.Source==app.ap_mirror_angle
                                    app.annotations.mirror_line = plot(app.diffPattern,[1 app.params.nopix],tan(event.Value)*([1 app.params.nopix]-app.center(1))+app.center(2),'g--','LineWidth',2);
                                else
                                    app.annotations.mirror_line = plot(app.diffPattern,[1 app.params.nopix],tan(app.ap_mirror_angle.Value)*([1 app.params.nopix]-app.center(1))+app.center(2),'g--','LineWidth',2);
                                end
                        end
                    end
                    plotCircle(app,'virtual_ap',app.diffPattern,app.aperture.radius,temp_center,'b')
                    
                otherwise % 'Center of Mass' or 'Annular/Round'
                    plotCircle(app,'inner_disk',app.diffPattern,app.adf_inner.Value,app.center,'g')
                    plotCircle(app,'outer_disk',app.diffPattern,app.adf_outer.Value,app.center,'r')
            end
            
            updateImStats(app)
        end
        
        % Generic function for plotting an image on a given axis with
        % brightness/contrast/gamma values
        function plotImage(app,im)
            % Define brightness, contrast, and gamma values for current image
            brightness = app.image_choices.brightness(strcmp(im,app.image_choices.image)); %#ok<*ADPROPLC>
            contrast   = app.image_choices.contrast(strcmp(im,app.image_choices.image));
            gamma      = app.image_choices.gamma(strcmp(im,app.image_choices.image));
            
            % loop over any/all axes this image should be displayed on
            for ii=1:sum(~strcmp((app.image_choices.axis(strcmp(im,app.image_choices.image),:)),''))
                ax = app.image_choices.axis{strcmp(im,app.image_choices.image),ii};
                ax_limits=axis(eval(ax));
                
                cla(eval(ax));imagesc(eval(ax),imadjust(rescale(eval(im)),[],[],gamma)*range(eval(im),'all')+min(min(eval(im))));
                set(eval(ax),'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse');
                axis(eval(ax),ax_limits);
                
                
                switch im
                    case 'app.images.com_phase'
                        colormap(eval(ax),hsv);
                        caxis(eval(ax),[0 2*pi]);set(eval(ax),'Color','k');
                    otherwise
                        colormap(eval(ax),gray);
                        
                        % Change intensity limits on selected image based on
                        % brightness and contrast values
                        try
                            set(eval(ax),'CLim',gather([min(min(eval(im))) max(max(eval(im)))]-...
                                brightness*range(eval(strcat(im,'(:)')))-...
                                contrast*range(eval(strcat(im,'(:)')))*[-1 1]));
                        catch
                            if numel(eval(im))~=sum(isnan(eval(im)),'all')
                                set(eval(ax),'CLim',mean2(eval(im))+[-1 1]);
                            else
                                set(eval(ax),'CLim',[-1 1]);
                            end
                        end
                end
                set(eval(ax).Toolbar,'Visible','off');
            end
            
        end
        
        % Plot the probe position on the BF/DF image when displaying all
        % diffraction patterns, not just the PACBED
        function plotMarker(app)
            try delete(app.annotations.marker.adf);catch;end
            try delete(app.annotations.marker.comx);catch;end
            try delete(app.annotations.marker.comy);catch;end
            try delete(app.annotations.marker.comMag);catch;end
            try delete(app.annotations.marker.comPhase);catch;end
            
            if app.all_patterns.Value
                app.bfdf_image.NextPlot='add';
                app.annotations.marker.adf=plot(app.bfdf_image,app.probeX.Value,app.probeY.Value,'o','Color','r','MarkerFace','r','MarkerSize',12,'Tag','marker');
                app.bfdf_image.NextPlot='replace';
                
                % if in CoM or DPC imaging, also plot markers on those four images
                if any(app.DetectorTypeButtonGroup.SelectedObject==[app.SegmentedButton;app.CenterofMassButton])
                    app.comx.NextPlot='add';
                    app.annotations.marker.comx=plot(app.comx,app.probeX.Value,app.probeY.Value,'o','Color','r','MarkerFace','r','MarkerSize',12,'Tag','marker');
                    app.comx.NextPlot='replace';
                    
                    app.comy.NextPlot='add';
                    app.annotations.marker.comy=plot(app.comy,app.probeX.Value,app.probeY.Value,'o','Color','r','MarkerFace','r','MarkerSize',12,'Tag','marker');
                    app.comy.NextPlot='replace';
                    
                    app.comMag.NextPlot='add';
                    app.annotations.marker.comMag=plot(app.comMag,app.probeX.Value,app.probeY.Value,'o','Color','r','MarkerFace','r','MarkerSize',12,'Tag','marker');
                    app.comMag.NextPlot='replace';
                    
                    app.comPhase.NextPlot='add';
                    app.annotations.marker.comPhase=plot(app.comPhase,app.probeX.Value,app.probeY.Value,'o','Color','r','MarkerFace','r','MarkerSize',12,'Tag','marker');
                    app.comPhase.NextPlot='replace';
                end
            end
        end
        
        % Generic function for plotting segments between detectors
        function plotSegments(app)
            det='A':'Z';
            clearDetectors(app)
            
            app.temp_angle=app.det_angle.Value;
            
            % Plot segment lines separating different parts of the detector
            if app.nseg.Value>1
                
                % Turn on GUI parts if there are more than 1 segment in the
                % ADF detector
                app.det_angle.Enable='on'; app.det_angle_label.Enable='on'; app.det_angle_input.Enable='on';
                app.det_rot_minus.Enable='on';app.det_rot_plus.Enable='on';
                app.nrung.Enable='on'; app.nrung_label.Enable='on';
                
                % For as many rungs as there are (default = 1), plot the
                % separating circlular and linear segments
                app.diffPattern.NextPlot='add';
                for j=1:app.nrung.Value
                    % Define vectors k1 and k2 that correspond to the inner and outer angles of the detector for a given number of rungs
                    k1=(j-1)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                    k2=(j)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                    
                    % Plot linear segments separating each portion of the
                    % detector
                    for i=1:app.nseg.Value
                        
                        % If there are multiple rungs, append a number
                        % behind the letter ID of the segment (i.e. A1, A2...)
                        if app.nrung.Value>1
                            temp_name=sprintf('%s%d',det(i),j);
                        else
                            temp_name=det(i);
                        end
                        
                        % Plot line separating different segments (i.e. between
                        % segments A and B)
                        app.annotations.seg.(temp_name)=plot(app.diffPattern,...
                            cos(app.temp_angle-pi/app.nseg.Value)*[k1 k2]+app.center(1),...
                            sin(app.temp_angle-pi/app.nseg.Value)*[k1 k2]+app.center(2),...
                            ':b','LineWidth',4);
                        
                        % Plot segment label (i.e. A, B, C... or A1, B1, C1...)
                        app.annotations.seg.label.(temp_name)=text(app.diffPattern,...
                            app.center(1)+0.5*(k1+k2)*cos(app.temp_angle),...
                            app.center(2)+0.5*(k1+k2)*sin(app.temp_angle),...
                            temp_name,'FontSize',20,'FontWeight','bold','Color','r','HorizontalAlignment','center');
                        % Increment the angle based on the number of segments and repeat
                        app.temp_angle=app.temp_angle+2*pi/app.nseg.Value;
                    end
                end
                % Plot the circular segment between different rungs
                if app.nrung.Value>1
                    k2=(1:app.nrung.Value-1)'*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                    plotCircle(app,'seg.divider',app.diffPattern,k2,app.center,'w')
                end
                app.diffPattern.NextPlot='replace';
            else
                % If there is only segment (i.e. an annulus), disable parts
                % of the GUI
                app.det_angle.Enable='off'; app.det_angle_label.Enable='off'; app.det_angle_input.Enable='off';
                app.det_rot_minus.Enable='off';app.det_rot_plus.Enable='off';
                app.nrung.Enable='off'; app.nrung_label.Enable='off';
            end
        end
        
        % Function to plot a vector field image over top of the DPC or COM
        % images (either magnitude, angle, or combination)
        function plotVectorField(app)
            switch app.vectButtonGroup.SelectedObject
                case app.VectorButton
                    imagesc(app.vectorField,app.images.com_phase,'AlphaData',app.images.com_mag_norm);app.vectorField.Visible='on';
                    set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','Color','k');
                    colormap(app.vectorField,hsv);
                    app.color='w';
                case app.PhaseButton
                    imagesc(app.vectorField,app.images.com_phase);app.vectorField.Visible='on';
                    set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','Color','k');
                    colormap(app.vectorField,hsv);
                    app.color='w';
                case app.MagnitudeButton
                    imagesc(app.vectorField,app.images.com_mag);app.vectorField.Visible='on';
                    set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse');
                    colormap(app.vectorField,gray);
                    app.color='r';
            end
            
            % Plot vector arrows over the selected image
            try delete(app.annotations.vectors);delete(get(app.annotations.vectors,'Children'));catch;end
            set(app.vectorField,'NextPlot','add');
            app.annotations.vectors=quiver(1:app.params.subsample:app.params.framesx,...
                1:app.params.subsample:app.params.framesy,...
                -app.images.comx(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample,...
                -app.images.comy(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample,...
                1/app.params.subsample,app.color,'Parent', app.vectorField);%'LineWidth',2,
            set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','NextPlot','replace');
            axis(app.vectorField,app.limits.im);
        end
        
        function import_bar = readFile(app,import_bar)
            % Set the framesize for the importbar
            framesize=app.params.nopix*app.params.nopiy*app.params.bytesize;
            
            switch app.params.mem
                case 'p' % read data into physical memory
                    % Open the user-selected file for reading
                    fid=fopen(app.params.filename,'r');
                    
                    % Pre-allocate the array for speed
                    app.data=zeros(app.params.nopiy*app.params.nopix,app.params.frames,app.params.bitdepth);
                    
                    % Read in the 4D dataset in bunches the size of one diffraction pattern and
                    % loop over the total number of diffraction patterns (# of probe positions)
                    import_bar.Indeterminate = 'off';
                    
                    for j=1:app.params.frames
                        if mod(j,100)==0 tic; end %#ok<*SEPEX>
                        if contains(app.params.filename,{'.mrc','.dm4'},'IgnoreCase',1) % .mrc and .dm4 files have a single header offset at the beginning of the file
                            if j==1
                                %fread(fid,1024,'*int8',0,'l');
                                fread(fid,app.params.header,'*int8',0,'l');
                            end
                            app.data(1:app.params.nopiy*app.params.nopix,j)=fread(fid,app.params.nopiy*app.params.nopix,app.params.bitdepth,0,app.params.ordering);
                        else
                            fread(fid,app.params.header,app.params.bitdepth,0,app.params.ordering);
                            app.data(1:app.params.nopiy*app.params.nopix,j)=fread(fid,app.params.nopiy*app.params.nopix,app.params.bitdepth,0,app.params.ordering);
                            fread(fid,app.params.footer,app.params.bitdepth,0,app.params.ordering);
                        end
                        
                        if mod(j,100)==0
                            import_bar.Value = j/app.params.frames;
                            import_bar.Message = sprintf('%.1f%% Complete\n%6.2f MB/s \t %02.0f:%02.0f',100*j/app.params.frames,framesize/toc/1e6,floor(toc(app.timer)/60),toc(app.timer)-floor(toc(app.timer)/60)*60);
                        end
                    end
                    fclose(fid);
                case 'v' % Virtual memory mapping
                    % Create a memory mapped file, rather than reading the file
                    % directly into memory
                    
                    import_bar.Indeterminate = 'off';
                    if app.params.header == 0 && app.params.footer == 0
                        app.m = memmapfile(app.params.filename,'Format',app.params.bitdepth);
                        app.data=reshape(app.m.Data,[app.params.nopiy*app.params.nopix app.params.frames]);
                    elseif contains(app.params.filename,{'.mrc','.dm4'},'IgnoreCase',1) % .mrc and .dm4 files only have a single header offset at the beginning of the file
                        app.m = memmapfile(app.params.filename,'Format',{app.params.bitdepth,[app.params.nopix*app.params.nopiy app.params.frames],'data'},'Offset',app.params.header,'Repeat',1);
                        app.data=reshape(app.m.Data.data,[app.params.nopiy*app.params.nopix app.params.frames]);
                    else
                        app.m = memmapfile(app.params.filename,'Format',app.params.bitdepth);
                        for j=1:app.params.frames
                            if mod(j,100)==0 tic; end
                            start  = (j-1)*app.params.nopiy*app.params.nopix + (j-1)*(app.params.header+app.params.footer) + app.params.header + 1;
                            finish = start+app.params.nopiy*app.params.nopix-1;
                            app.data(:,j)=app.m.Data(start:finish);
                            if mod(j,100)==0
                                import_bar.Value = j/app.params.frames;
                                import_bar.Message = sprintf('%.1f%% Complete\n%.2f MB/s \t %02.0f:%02.0f',100*j/app.params.frames,framesize/toc/1e6,floor(toc(app.timer)/60),toc(app.timer)-floor(toc(app.timer)/60)*60);
                            end
                        end
                    end
                    
                    % Check the endianness of the data vs that of the
                    % computer, perform a byteswap if necessary
                    [~,~,endian] = computer;
                    if ~strcmpi(endian,app.params.ordering)
                        app.data = swapbytes(app.data);
                    end
                    
                otherwise
                    return;
            end
            
            % convert int=>float if selected
            if app.params.convertFloat
                switch app.params.bytesize
                    case 8
                        app.data = double(app.data);
                        app.params.bytesize = 8;
                        app.params.bitdepth = 'double';
                    otherwise
                        app.data = single(app.data);
                        app.params.bytesize = 4;
                        app.params.bitdepth = 'single';
                end
            end
            
            
            % move to GPU device if selected
            if app.params.gpu>0
                app.data = gpuArray(app.data);
            end
        end
        
        function resetGui(app)
            app.live_update.Enable='off';
            app.align_trans_beam.Text='Align Transmitted Beam';app.align_trans_beam.Value=0;
            app.alignScanRot.Enable='off';
            app.display_images.Enable='off';
            app.save_images.Enable='off';
            set(app.DetectorTypeButtonGroup.Children,'Enable','off');
            app.save_images.Visible=1;
            app.saveButton.Visible=0;app.saveButton.Enable=0;
            app.autoSaveButton.Visible=0;app.autoSaveButton.Enable=0;
            app.Quant4D_Fig.Name = 'Quant4D';
            
            % Default to the detector panel
            app.controlTab.SelectedTab=app.DetectorsTab;
            
            % Remove all data from GPU if in use
            try
                if app.params.gpu>0
                    reset(app.device);
                end
            catch
            end
            
            % Clear any previous dataset, release memory
            app.data=[];
            
            % Clear previous ADF images (as a function of angle)
            app.adf.images=[];
            
            % Clear any previous images
            try
                tmp = fieldnames(app.images);
                for ii=1:size(tmp,1)
                    app.images.(tmp{ii}) = [];
                end
            catch
            end
            
            % Clear any previous masks
            try
                tmp = fieldnames(app.mask);
                for ii=1:size(tmp,1)
                    app.mask.(tmp{ii}) = [];
                end
            catch
            end
            
            % Clear any previous images
            clearPlots(app);
            
            % Hide panels that are not in use (default to annular/round
            % detector geometry)
            app.comTab.Parent=[];
            app.vectorFieldTab.Parent=[];
            app.virtualApertureTab.Parent=[];
            app.colorwheelTab.Parent=[];cla(app.colorwheel_plot);
            
            % Structured array including the different images (1), their
            % respective axes (2), variables (3), brightness (4), contrast
            % (5) and gamma (6) values. The user can change these using the
            % 'Brightness/Contrast' tab. Axis has up to 3 positions to
            % allow for the same image to be shown on multiple axes (i.e.
            % the ADF image can be shown up to 3 times simultaneously, and
            % CoM mag can be shown up to 2 times).
            app.image_choices = table({'app.diffPattern','','';'app.bfdf_image','','';'app.comx','','';'app.comy','','';'app.comMag','','';'app.comPhase','','';'','','';'','',''},...
                {'app.images.pacbed';'app.images.adf';'app.images.comx';'app.images.comy';'app.images.com_mag';'app.images.com_phase';'app.images.dcom';'app.images.icom'},...
                zeros(8,1),...
                zeros(8,1),...
                ones(8,1),'VariableNames',{'axis';'image';'brightness';'contrast';'gamma'});
            app.ax_choice = 'app.diffPattern';
            app.ImageDropDown.Value = 'app.images.pacbed';
            
            % turn off new matlab toolbar popups
            app.diffPattern.Toolbar.Visible = 'off';
            app.bfdf_image.Toolbar.Visible = 'off';
            app.histogram_plot.Toolbar.Visible = 'off';
            app.comx.Toolbar.Visible = 'off';
            app.comy.Toolbar.Visible = 'off';
            app.comMag.Toolbar.Visible = 'off';
            app.comPhase.Toolbar.Visible = 'off';
            
        end
        
        function saveImagePrefs(app,prefix_in,innerBox_in,outerBox_in,saveAllBox_in,selection_in)
            app.savePrefs.prefix = prefix_in;
            app.savePrefs.InnerAngleCheckBox.Value = innerBox_in;
            app.savePrefs.OuterAngleCheckBox.Value = outerBox_in;
            app.savePrefs.SaveAllCheckBox.Value = saveAllBox_in;
            app.savePrefs.previous_list = selection_in;
        end
        
        function saveVect(app)
            % Create temporary figure with axes - saveas() is not supported as of r2019a in AppDesigner
            fig=figure;
            fig.Visible='off';
            figAxes=axes(fig);
            figAxes.NextPlot='replace';
            imagesc(figAxes,app.images.com_phase,'AlphaData',app.images.com_mag_norm);
            set(figAxes,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','Color','k');
            axis(figAxes,app.limits.im);
            colormap(figAxes,hsv);
            
            set(figAxes,'NextPlot','add');
            quiver(1:app.params.subsample:app.params.framesx,...
                1:app.params.subsample:app.params.framesy,...
                -app.images.comx(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample,...
                -app.images.comy(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample,...
                1/app.params.subsample,app.color,'Parent', figAxes);
            set(figAxes,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','reverse','NextPlot','replace');
            axis(figAxes,app.limits.im);
            
            % Save as a .png, .fig, and .eps (vector image, not pixelated)
            set(fig, 'Renderer', 'Painters');
            fig.InvertHardcopy = 'off';
            saveas(fig,strcat(app.savePrefs.prefix,'VectField.png'),'png');
            fig.Visible='on';savefig(fig,strcat(app.savePrefs.prefix,'VectField.fig'));fig.Visible='off';
            %saveas(fig,strcat(app.savePrefs.prefix,'VectField.svg'),'svg');
            
            csvwrite(strcat(app.savePrefs.prefix,'vector_field.txt'),[-app.images.comx(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample ...
                -app.images.comy(1:app.params.subsample:end,1:app.params.subsample:end)./app.params.subsample])
        end
        
        function updateImStats(app)
            %app.im_choice            = app.image_choices{ismember(app.image_choices(:,3),app.ImageDropDown.Value),3};
            app.imStats.ColumnFormat = {'numeric'};
            app.imStats.ColumnName   = strcat(app.ImageDropDown.Items{strcmp(app.ImageDropDown.Value,app.ImageDropDown.ItemsData)},' stats');
            app.imStats.Data         = gather([min(min((eval(app.ImageDropDown.Value))));
                max(max(eval(app.ImageDropDown.Value)));
                mean(mean(eval(app.ImageDropDown.Value)));
                std(std(eval(app.ImageDropDown.Value)));
                sum(sum(eval(app.ImageDropDown.Value)))]);
            % Generate an intensity histogram for the selected image
            if numel(eval(app.ImageDropDown.Value))~=sum(isnan(eval(app.ImageDropDown.Value)),'all')
                h=histogram(app.histogram_plot,eval(app.ImageDropDown.Value),100);
            else
                h=histogram(app.histogram_plot,zeros(size(eval(app.ImageDropDown.Value))),100);
            end
            app.histogram_plot.Toolbar.Visible = 'off';
            
            if app.histLog.Value
                set(app.histogram_plot,'YScale','log');
            else
                set(app.histogram_plot,'YScale','linear');
            end
            
            % Set histogram plot axes
            try
                axis(app.histogram_plot,gather([min(min(eval(app.ImageDropDown.Value)))-0.05*range(eval(strcat(app.ImageDropDown.Value,'(:)'))),...
                    max(max(eval(app.ImageDropDown.Value)))+0.05*range(eval(strcat(app.ImageDropDown.Value,'(:)'))),...
                    1,...
                    max(h.BinCounts)]));
            catch
                if numel(eval(app.ImageDropDown.Value))~=sum(isnan(eval(app.ImageDropDown.Value)),'all')
                    axis(app.histogram_plot,gather([mean2(eval(app.ImageDropDown.Value))+[-1 1] 1 max(h.BinCounts)]));
                else
                    axis(app.histogram_plot,gather([-1 1 1 max(h.BinCounts)]));
                end
                
            end
            % To account for any negative values in the diffraction
            % pattern while in log scale
            if strcmp('app.diffPattern',app.ax_choice) && app.dpLog.Value
                app.diffPattern.ColorScale = 'log';
                if app.diffPattern.CLim(2)<1e-7 app.diffPattern.CLim(2)=1e-7;end
                if app.diffPattern.CLim(1)<=0 app.diffPattern.CLim(1)=1e-8; end
            end
            
            % Add annotations to the histogram plot for min and max values,
            % as well as a gamma curve
            hold(app.histogram_plot,'on');
            plot(app.histogram_plot,min(get(eval(app.ax_choice),'CLim'))*[1 1],app.histogram_plot.YLim,'LineWidth',2,'Color','g');
            plot(app.histogram_plot,max(get(eval(app.ax_choice),'CLim'))*[1 1],app.histogram_plot.YLim,'LineWidth',2,'Color','g');
            plot(app.histogram_plot,linspace(min(get(eval(app.ax_choice),'CLim')),max(get(eval(app.ax_choice),'CLim')),100),(linspace(0,app.histogram_plot.YLim(2),100).^app.gamma.Value)./app.histogram_plot.YLim(2)^app.gamma.Value*app.histogram_plot.YLim(2),'Color','k');
            app.histogram_plot.NextPlot='replace';
            app.histogram_plot.YTick = [];
            
            % display min, max, and gamma values on the histogram
            try delete(app.annotations.gamma_text);catch;end
            app.annotations.gamma_text  = text(app.histogram_plot,0.98,0.98,...
                sprintf('min: %0.2f\nmax: %0.2f\ngamma: %0.2f',eval(strcat(app.ax_choice,'.CLim(1)')),eval(strcat(app.ax_choice,'.CLim(2)')),app.gamma.Value),...
                'FontSize',12,'HorizontalAlignment','right','VerticalAlignment','top',...
                'BackgroundColor','w','Units','normalized');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetGui(app)
            % Hide GUI before first import only
            if isempty(app.params)
                app.Quant4D_Fig.Visible = 'off';
                app.params.first_open='false';
            end
            
            % Set some default values, limits, and colors
            app.nseg.Value=4;
            app.det_angle.Value=0;app.det_angle_input.Value=0;
            set(app.det_angle,'Limits',[-pi pi],'Value',0);app.det_angle_input.Value=0;
            app.probeX.Value=1;app.probeY.Value=1;
            app.stackSlider.Value=1;
            app.params.subsample=1;
            app.color='w';
            app.scanRot.Value=0;
            app.scanRotInput.Value=0;
            
            % Initial save image preferences
            app.savePrefs.prefix = '';
            app.savePrefs.InnerAngleCheckBox.Value = 1;
            app.savePrefs.OuterAngleCheckBox.Value = 1;
            app.savePrefs.SaveAllCheckBox.Value = 0;
            app.savePrefs.previous_list = 1;
            app.savePrefs.autosave = 0;
            
            % Start the GUI with all options disabled to force user to
            % select the "Import Dataset" button
            set(app.trans_panel.Children,'Enable','off');
            set(app.diffControlGrid.Children,'Enable','off');
            set(app.detector_panel.Children,'Enable','off');
            app.sum_patterns.Enable='off';app.all_patterns.Enable='off';
            app.align_trans_beam.Enable='off';
            app.AnnularRoundButton.Value=1;
            app.sum_patterns.Value=1;
            app.stackSlider.Visible='off';
            app.probeX.Visible='off';app.probeY.Visible='off';
            
            movegui(app.Quant4D_Fig,'center')
            app.Quant4D_Fig.Visible = 'on';
            figure(app.Quant4D_Fig);
        end

        % Button pushed function: import_data
        function importData(app, event)
            % Allow for importing binary files with extensions:
            %   .mib	-  Medipix
            %   .raw	-  EMPAD, ImageJ, Fiji, Matlab, etc.
            %   .mrc    - Fast CETA data
            %   .dm4    - Gatan K2/K3
            startupFcn(app)
            app.diffPattern.Visible='off';cla(app.diffPattern);
            [app.params.filename,pathname]=uigetfile('*.raw;*.mib;*.mrc;*.dm4','Select 4DSTEM Dataset');
            figure(app.Quant4D_Fig);
            app.controlTab.SelectedTab=app.DetectorsTab;
            if app.params.filename==0
                resetGui(app)
            else
                cd(pathname)
                
                % Ask the user for some basic parameters of the 4D data set and save them
                % to the GUI structure (app)
                
                % Import the 4D dataset
                appImport=import4Dstem(app,app.params.filename);
                uiwait(appImport.import_4d)
                
                % If the user clicks cancel, return to the initial GUI state
                if app.params.nopiy==0 || app.params.nopix==0 || app.params.framesy==0 || app.params.framesx==0
                    resetGui(app)
                else % User has clicked "Import" in the import GUI
                    app.Quant4D_Fig.Name = strcat(pathname,app.params.filename);
                    
                    % Set some initial values as guess, update later in function
                    app.center = [app.params.nopix app.params.nopix]/2; app.params.center0=app.center;
                    app.centerX.Value = app.center(1);app.centerY.Value = app.center(2);
                    app.params.radius = 20;
                    app.params.max_dist = floor(sqrt(sum((0.5*[app.params.nopiy app.params.nopix]).^2)));
                    
                    % Calculate the total number of frames; update the stack slider for later use
                    app.params.frames=app.params.framesx*app.params.framesy;
                    app.stackSlider.Limits=[1 app.params.frames];
                    
                    % Start import timer
                    app.timer = tic;
                    import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','Importing Data...','Message','Starting...','Indeterminate',"on");
                    import_bar = readFile(app,import_bar);
                    
                    
                    import_bar.Title = 'Processing Data...';
                    import_bar.Indeterminate = 'on';
                    import_bar.Message = sprintf('Forming PACBED\nPlease Wait...');
                    
                    % Create a summed image of all diffraction patterns simply for display
                    % purposes and alignment and calibration
                    app.limits.im=[1 app.params.framesx 1 app.params.framesy];
                    app.limits.dp=[1 app.params.nopix 1 app.params.nopiy];
                    app.images.pacbed=gather(reshape(sum(app.data,2),[app.params.nopix app.params.nopiy]))/app.params.frames;
                    app.diffPattern.Visible='on';
                    plotImage(app,'app.images.pacbed');
                    
                    % Set initial axis limits
                    axis(app.bfdf_image,app.limits.im);
                    axis(app.comx,app.limits.im);
                    axis(app.comy,app.limits.im);
                    axis(app.comMag,app.limits.im);
                    axis(app.comPhase,app.limits.im);
                    axis(app.vectorField,app.limits.im);
                    
                    % Try to autoAlign the transmitted beam
                    import_bar = autoAlign(app,event,import_bar);
                    
                    % Form BF/ADF images
                    import_bar.Value = 1;
                    import_bar.Message = sprintf('100%% Complete!\nForming Images... Please Wait');
                    app.images.adf = sum(app.adf.images(:,:,round(app.params.radius*2):round(app.params.max_dist)),3);
                    app.mask.bfdf = reshape(sum(app.mask.adf(round(app.params.radius*2):round(app.params.max_dist),:),1),[app.params.nopix app.params.nopiy]);
                    plotImage(app,'app.images.adf')
                    
                    import_bar.Message = sprintf('Setting up GUI\nPlease Wait');
                    import_bar.Indeterminate = 'on';
                    
                    % Setup some default starting values
                    app.params.center0=app.center; app.centerX.Value = app.center(1);app.centerY.Value = app.center(2);
                    app.aperture.center=app.center;
                    app.params.rmax=app.params.max_dist;
                    app.transRad.Value = app.params.radius;
                    app.params.mrad_per_pix=app.alpha.Value/app.params.radius; app.transCal.Value = 1/app.params.mrad_per_pix;
                    app.aperture_x.Limits=[0 app.params.rmax];
                    app.aperture_y.Limits=[-pi pi];
                    app.scanRot.Limits=[-pi pi];
                    
                    % Enable GUI options and set limits/values for things like
                    % slider bars, numeric fields, etc.
                    set(app.trans_beam_x,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                    set(app.trans_beam_y,'Limits',[-app.params.rmax,app.params.rmax],'Value',0);
                    set(app.trans_beam_radius,'Limits',[0,app.params.rmax],'Value',app.params.radius);
                    set(app.stackSlider,'Limits',[1,app.params.frames]);
                    set(app.adf_inner,'Limits',[0,app.params.rmax],'Value',app.params.radius);app.adf_inner_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                    set(app.adf_outer,'Limits',[0,app.params.rmax],'Value',app.params.rmax/2);app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                    set(app.det_angle,'Limits',[-pi,pi],'Value',app.det_angle_input.Value*pi/180);app.det_angle_input.Value=app.det_angle.Value*180/pi;
                    set(app.diffControlGrid.Children,'Enable','on');
                    set(app.detector_panel.Children,'Enable','on');
                    set(app.DetectorTypeButtonGroup.Children,'Enable','on')
                    app.live_update.Enable='on';
                    app.align_trans_beam.Enable='on';
                    app.alignScanRot.Enable='on';
                    app.alpha.Enable='on';app.conv_angle_label.Enable='on';
                    app.display_images.Enable='on';
                    app.save_images.Enable='on';
                    app.sum_patterns.Enable='on';
                    app.all_patterns.Enable='on';
                    set(app.histogram_panel.Children,'Enable','on');
                    app.ImageDropDown.Enable='on';
                    
                    % Make colorwheel for DPC/COM imaging - this color scheme will
                    % stay constant for all DPC/COM images regardless of their
                    % individual parameters like scan or detector rotation for
                    % consistency
                    width=app.params.nopix;cw_radius=width/2;
                    [xgrid, ygrid] = meshgrid(1:width, 1:width);
                    a=atan2(ygrid-app.center(2),xgrid-app.center(1));       % Get atan from -pi to pi
                    a = a .* (a >= 0) + (a + 2 * pi) .* (a < 0);        % shift the negative values of atan up by 2pi
                    app.images.colorwheel=a;
                    angles=0:pi/100:2*pi;
                    imagesc(app.colorwheel_plot,app.images.colorwheel);
                    colormap(app.colorwheel_plot,hsv(512));
                    set(app.colorwheel_plot,'NextPlot','add');
                    fill(app.colorwheel_plot,[cos(angles)*cw_radius+cw_radius,width,width,0,0,width],[sin(angles)*cw_radius+cw_radius,cw_radius,0,0,width,width],'k');
                    set(app.colorwheel_plot,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'XLim',[0 width],'YLim',[0 width],'YDir','reverse');
                    set(app.colorwheel_plot,'NextPlot','replace');
                    colorbar(app.colorwheel_plot,'off')
                    app.colorwheel_plot.Toolbar.Visible = 'off';
                    
                    % Setup the initial brightness/contrast/gamma values
                    imageControls(app, event)
                    
                    % Set up the initial detector (default to Annular/Round)
                    detectorType(app,event)
                    
                    % Close status bar
                    close(import_bar)
                end
            end
        end

        % Callback function: AutoAlignButton, alignScanRot, 
        % align_trans_beam, alpha, scanRot, scanRot, scanRotInput, 
        % scanRotMinus, scanRotMirror, scanRotPlus, 
        % trans_beam_rad_minus, trans_beam_rad_plus, 
        % trans_beam_radius, trans_beam_radius, trans_beam_x, 
        % trans_beam_x, trans_beam_x_minus, trans_beam_x_plus, 
        % trans_beam_y, trans_beam_y, trans_beam_y_minus, 
        % trans_beam_y_plus
        function alignment(app, event)
            switch event.Source
                case app.alpha
                    % Reset the scaling factor in mrad/pix and the max
                    % radius size allowed
                    app.params.mrad_per_pix=app.alpha.Value/app.params.radius;
                    app.params.rmax=floor(sqrt((app.params.nopiy-app.center(1)).^2+(app.params.nopix-app.center(2)).^2));
                    
                    % Modify inner/outer limits for ADF
                    app.adf_inner.Limits=[0,app.params.rmax]; app.adf_inner_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                    app.adf_outer.Limits=[0,app.params.rmax]; app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                    
                case app.trans_beam_x % x translate slider
                    app.center=app.params.center0+[event.Value -app.trans_beam_y.Value];
                    
                case app.trans_beam_y % y translate slider
                    app.center=app.params.center0+[app.trans_beam_x.Value -event.Value];
                    
                case app.trans_beam_radius % radius size slider
                    app.params.radius=event.Value;
                    app.params.mrad_per_pix=app.alpha.Value/app.params.radius;
                    
                case app.trans_beam_x_plus % x plus button
                    app.center=app.center+[0.25 0];
                    app.trans_beam_x.Value=app.trans_beam_x.Value+0.25;
                    
                case app.trans_beam_x_minus % x minus button
                    app.center=app.center-[0.25 0];
                    app.trans_beam_x.Value=app.trans_beam_x.Value-0.25;
                    
                case app.trans_beam_y_plus % y plus button
                    app.center=app.center-[0 0.25];
                    app.trans_beam_y.Value=app.trans_beam_y.Value+0.25;
                    
                case app.trans_beam_y_minus % y minus button
                    app.center=app.center+[0 0.25];
                    app.trans_beam_y.Value=app.trans_beam_y.Value-0.25;
                    
                case app.trans_beam_rad_plus % radius plus button
                    app.params.radius=app.params.radius+0.25;
                    app.trans_beam_radius.Value=app.trans_beam_radius.Value+0.25;
                    
                case app.trans_beam_rad_minus % radius minus button
                    app.params.radius=app.params.radius-0.25;
                    app.trans_beam_radius.Value=app.trans_beam_radius.Value-0.25;
                    
                case app.AutoAlignButton
                    import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','Auto Align Transmitted Beam...','Message','Preparing','Indeterminate','on');
                    import_bar = autoAlign(app,event,import_bar); %#ok<*NASGU>
                    app.align_trans_beam.Value = 0;
                    tmp.Source = app.align_trans_beam;
                    alignment(app,tmp);
                    
                case app.align_trans_beam % "Align Transmitted Beam" button
                    switch app.align_trans_beam.Value
                        case 0 % Finish alignment
                            set(app.detector_panel.Children,'Enable','on');
                            set(app.trans_panel.Children,'Enable','off');
                            set(app.diffControlGrid.Children,'Enable','on');
                            set(app.DetectorTypeButtonGroup.Children,'Enable','on');
                            app.AutoAlignButton.Visible='off';
                            app.ManualCalButton.Visible='off';
                            app.align_trans_beam.Text='Align Transmitted Beam';
                            app.display_images.Enable='on';
                            app.alpha.Enable='on';app.conv_angle_label.Enable='on';
                            app.alignScanRot.Enable='on';
                            app.import_data.Enable='on';
                            app.align_trans_beam.Enable='on';
                            set(app.adf_inner,'Limits',[0,app.params.rmax]);app.adf_inner_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                            set(app.adf_outer,'Limits',[0,app.params.rmax]);app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                            set(app.det_angle,'Limits',[-pi,pi],'Value',deg2rad(app.det_angle_input.Value));
                            
                            if isempty(app.center)
                                app.center=[app.params.nopiy app.params.nopix]/2;
                                app.centerX.Value = app.center(1);
                                app.centerY.Value = app.center(2);
                            end
                            
                            % Rerun the ADF integration based on the newly aligned
                            % location of the transmitted disk
                            if ~all((app.params.prev_center-app.center)==0)
                                import_bar = uiprogressdlg(app.Quant4D_Fig,'Title','','Message',sprintf('Performing radial integration... Please Wait\n'),'Indeterminate',"on");
                                import_bar = integrateADF(app,import_bar);
                                close(import_bar)
                            end
                            
                            detectorType(app,event);
                        case 1 % Start alignment
                            app.AutoAlignButton.Visible='on';
                            %app.ManualCalButton.Visible='on';
                            app.align_trans_beam.Text='Finish Alignment';
                            delete(app.annotations.disk_edge);
                            
                            set(app.DetectorTypeButtonGroup.Children,'Enable','off');
                            set(app.detector_panel.Children,'Enable','off')
                            set(app.trans_panel.Children,'Enable','on')
                            app.align_trans_beam.Enable='on';
                            app.import_data.Enable='off';
                            app.sum_patterns.Enable='on';
                            app.all_patterns.Enable='on';
                            app.alpha.Enable='on';app.conv_angle_label.Enable='on';
                            app.display_images.Enable='off';
                            app.alignScanRot.Enable='off';
                            app.import_data.Enable='off';
                            
                            % Record previous transmitted disk location to see if
                            % the ADF integration needs to be performed again
                            app.params.prev_center = app.center;
                    end
                case app.alignScanRot
                    % Align scan rotation on the diffraction pattern
                    switch app.alignScanRot.Value
                        case 0 % Button off
                            % Change button text
                            app.alignScanRot.Text='Align Scan Direction';
                            
                            % Delete any annotations on the diffraction pattern
                            clearDetectors(app)
                            
                            % Re-enable most GUI options
                            app.display_images.Enable='on';
                            set(app.detector_panel.Children,'Enable','on')
                            set(app.trans_panel.Children,'Enable','off')
                            set(app.scanRotPanel.Children,'Enable','off')
                            app.alpha.Enable='on';app.conv_angle_label.Enable='on';
                            set(app.DetectorTypeButtonGroup.Children,'Enable','on');
                            app.align_trans_beam.Enable='on';
                            app.import_data.Enable='on';
                            
                            % Check which type of detector is currently selected
                            detectorType(app,event);
                        case 1 % Button on
                            % Disable most options in the GUI, enable the few that
                            % may be useful for aligning scan rotation
                            set(app.detector_panel.Children,'Enable','off')
                            set(app.trans_panel.Children,'Enable','off')
                            set(app.scanRotPanel.Children,'Enable','on')
                            set(app.DetectorTypeButtonGroup.Children,'Enable','off');
                            app.align_trans_beam.Enable='off';
                            app.import_data.Enable='off';
                            app.sum_patterns.Enable='on';
                            app.all_patterns.Enable='on';
                            app.alpha.Enable='on';app.conv_angle_label.Enable='on';
                            app.display_images.Enable='off';
                            
                            % Change button text
                            app.alignScanRot.Text='Finish Alignment';
                            
                            clearDetectors(app);
                            app.diffPattern.NextPlot='add';
                            %scan_rot_Callback(app,event);
                    end
                case app.scanRot % scan rotation slider
                    app.scanRot.Value=event.Value;
                    app.scanRotInput.Value=rad2deg(app.scanRot.Value);
                case app.scanRotPlus % scan rotation plus button - 4 degree increments
                    app.scanRot.Value=(app.scanRot.Value+deg2rad(4))-2*pi.*((app.scanRot.Value+deg2rad(4))>180);
                    app.scanRotInput.Value=rad2deg(app.scanRot.Value);
                case app.scanRotMinus % scan rotation minus button 4 degree increments
                    app.scanRot.Value=(app.scanRot.Value-deg2rad(4))+2*pi.*((app.scanRot.Value-deg2rad(4))<-180);
                    app.scanRotInput.Value=rad2deg(app.scanRot.Value);
                case app.scanRotInput % scan rotation numerical field
                    app.scanRot.Value=deg2rad(event.Value);
            end
            
            if app.alignScanRot.Value % only replot scan direction vectors if in scan rotation alignment mode
                % Rotation matrix based on the scan rotation angle
                R = [cos(app.scanRot.Value) sin(app.scanRot.Value); -sin(app.scanRot.Value) cos(app.scanRot.Value)];
                
                scanx_vect=[app.params.rmax/2 0]*R;
                scanx_pos=0.6*app.params.rmax*[cos(app.scanRot.Value) sin(app.scanRot.Value)]+app.center;
                
                switch app.scanRotMirror.Value
                    case 0 % not mirrored
                        scany_vect=[0 app.params.rmax/2]*R;
                        scany_pos=0.6*app.params.rmax*[cos(app.scanRot.Value+pi/2) sin(app.scanRot.Value+pi/2)]+app.center;
                    case 1 % mirrored
                        scany_vect=[0 -app.params.rmax/2]*R;
                        scany_pos=0.6*app.params.rmax*[cos(app.scanRot.Value-pi/2) sin(app.scanRot.Value-pi/2)]+app.center;
                end
                
                clearDetectors(app)
                
                app.annotations.scanx_arrow=quiver(app.diffPattern,app.center(1),app.center(2),scanx_vect(1),scanx_vect(2),'LineWidth',4,'Color',[252 99 94]/255);
                app.annotations.scany_arrow=quiver(app.diffPattern,app.center(1),app.center(2),scany_vect(1),scany_vect(2),'LineWidth',4,'Color',[252 99 94]/255);
                
                app.annotations.scanx_text=text(app.diffPattern,scanx_pos(1),scanx_pos(2),'scan_x','Color',[252 99 94]/255,'FontWeight','bold','FontSize',16,'HorizontalAlignment','center');
                app.annotations.scany_text=text(app.diffPattern,scany_pos(1),scany_pos(2),'scan_y','Color',[252 99 94]/255,'FontWeight','bold','FontSize',16,'HorizontalAlignment','center');
            else
                % set a maximum radius to the nearest corner of the diffraction pattern
                app.params.rmax=floor(sqrt((app.params.nopiy-app.center(1)).^2+(app.params.nopix-app.center(2)).^2));
                
                % set initial virtual aperture size to that of the condenser aperture
                app.aperture.radius=app.params.radius;
                
                % delete any old disk outlines, plot new/updated one if in
                % alignment mode
                if app.align_trans_beam.Value
                    clearDetectors(app);
                    plotCircle(app,'disk_edge',app.diffPattern,app.params.radius,app.center,'b')
                end
            end
            
            app.centerX.Value = app.center(1);
            app.centerY.Value = app.center(2);
        end

        % Callback function: diff_pattern_radio_group, dpLog, probeX, 
        % probeX, probeY, probeY, stackSlider, stackSlider
        function patternViewer(app, event)
            XLim_temp=app.diffPattern.XLim;
            YLim_temp=app.diffPattern.YLim;
            switch event.Source
                case app.diff_pattern_radio_group
                    switch app.diff_pattern_radio_group.SelectedObject
                        case app.sum_patterns % PACBED
                            app.stackSlider.Visible='off';
                            app.probeX.Visible='off';app.probeY.Visible='off';
                            app.params.markerStatus = 0;
                            plotMarker(app)
                        case app.all_patterns % Individual patterns
                            app.probeX.Visible='on';app.probeY.Visible='on';
                            app.probeX.Limits=[1 app.params.framesx];app.probeY.Limits=[1 app.params.framesy];
                            app.stackSlider.Visible='on';
                            app.params.markerStatus = 0;
                    end
                    
                case app.stackSlider % Slider bar
                    app.probeX.Value=mod(round(event.Value),app.params.framesy)+app.params.framesy.*(mod(round(event.Value),app.params.framesy)==0);
                    app.probeY.Value=floor(round(event.Value)/app.params.framesy)+1.*(mod(round(event.Value),app.params.framesy)~=0);
                    app.stackSlider.Value=event.Value;
                    
                case app.probeX % Up/down arrows for x
                    app.stackSlider.Value = (app.probeY.Value-1)*app.params.framesx + event.Value;
                    app.probeX.Value = event.Value;
                    
                case app.probeY % Up/down arrows for y
                    app.stackSlider.Value = (event.Value-1)*app.params.framesx + app.probeX.Value;
                    app.probeY.Value = event.Value;
            end
            
            % See whether the user has selected to view the PACBED pattern
            % or to see each diffraction pattern individually
            if app.all_patterns.Value
                % redefine pacbed image as individual cbed pattern from the
                % given probe position
                app.images.pacbed = double(reshape(app.data(:,round(app.stackSlider.Value)),[app.params.nopix app.params.nopiy]));
                
                % plot cbed pattern and marker
                plotImage(app,'app.images.pacbed'); plotMarker(app);
            else
                % define pacbed image as sum of all cbed patterns
                app.images.pacbed = double(reshape(sum(app.data,2),[app.params.nopix app.params.nopiy]));
                 
                % plot pacbed pattern
                plotImage(app,'app.images.diff');
            end
            
            % log intensity on the diffraction pattern?
            if app.dpLog.Value
                set(app.diffPattern,'ColorScale','log');
                % To account for any negative values in the diffraction
                % pattern while in log scale
                if app.diffPattern.CLim(2)<1e-7 app.diffPattern.CLim(2)=1e-7;end
                if app.diffPattern.CLim(1)<=0 app.diffPattern.CLim(1)=1e-8; end
            else
                set(app.diffPattern,'ColorScale','linear');
            end
            
            set(app.diffPattern,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'XLim',XLim_temp,'YLim',YLim_temp,'YDir','reverse');
            colormap(app.diffPattern,gray);
            
            % Plot detector or transmitted disk outline on diffraction pattern
            switch app.align_trans_beam.Value
                case 0 % Alignment off
                    plotDetector(app,event);
                case 1 % Alignment on
                    plotCircle(app,'disk_edge',app.diffPattern,app.params.radius,app.center,'b')
            end
        end

        % Selection changed function: DetectorTypeButtonGroup
        function detectorType(app, event)
            clearPlots(app);
            clearDetectors(app);
            app.display_images.Enable='on';
            switch app.DetectorTypeButtonGroup.SelectedObject
                case app.SegmentedButton
                    app.comTab.Title = 'Differential Phase Contrast';
                    buttons = findall(app.comTab,'Type','uiToggleButton');
                    for ii=1:size(buttons,1)
                        buttons(ii).Text = strrep(buttons(ii).Text,'CoM','DPC');
                    end
                    app.comTab.Parent=app.outputPanel;
                    app.vectorFieldTab.Parent=app.outputPanel;
                    app.virtualApertureTab.Parent=[];
                    app.colorwheelTab.Parent=app.alignmentPanel;
                    app.outputPanel.SelectedTab=app.comTab;
                    set(app.virtualAperturePanel.Children,'Enable','off');
                    set(app.detector_panel.Children,'Enable','on');
                    set(app.det_rot_panel.Children,'Enable','on');
                    app.alignmentPanel.SelectedTab=app.colorwheelTab;
                    app.CartesianButton.Enable='off';app.PolarButton.Enable='off';
                    app.aperture_symmetry.Enable='off';
                    set(app.virtualAperturePanel.Children,'Enable','off');
                    set(app.mirrorPanel.Children,'Enable','off');
                    app.ImageDropDown.Items={'Diffraction Pattern';
                        'BF/DF';
                        'DPC X';
                        'DPC Y';
                        'DPC Magnitude'};
                    app.ImageDropDown.ItemsData={'app.images.pacbed';
                        'app.images.adf';
                        'app.images.comx';
                        'app.images.comy';
                        'app.images.com_mag'};
                    integrateMasks(app,event)
                    displayCoM(app)
                case app.AnnularRoundButton
                    app.display_images.Enable='off';
                    set(app.detector_panel.Children,'Enable','on');
                    set(app.mirrorPanel.Children,'Enable','off');
                    set(app.det_rot_panel.Children,'Enable','off');
                    app.outputPanel.SelectedTab=app.bfdfTab;
                    app.CartesianButton.Enable='off';app.PolarButton.Enable='off';
                    app.aperture_symmetry.Enable='off';
                    set(app.virtualAperturePanel.Children,'Enable','off');
                    app.outputPanel.SelectedTab=app.bfdfTab;
                    app.alignmentPanel.SelectedTab=app.alignmentTab;
                    app.ImageDropDown.Items={'Diffraction Pattern';
                        'BF/DF'};
                    app.ImageDropDown.ItemsData={'app.images.pacbed';
                        'app.images.adf'};
                    app.comTab.Parent=[];
                    app.vectorFieldTab.Parent=[];
                    app.virtualApertureTab.Parent=[];
                    app.colorwheelTab.Parent=[];
                case app.VirtualApertureButton
                    if ~isfield(app.aperture,'radius') app.aperture.radius = app.params.radius; end
                    app.virtualApertureTab.Parent=app.alignmentPanel;
                    set(app.detector_panel.Children,'Enable','off');
                    set(app.mirrorPanel.Children,'Enable','off');
                    set(app.CoordinatesButtonGroup.Children,'Enable','on');
                    app.aperture_symmetry.Enable='on';
                    app.ap_mirror.Enable='on';
                    app.outputPanel.SelectedTab=app.bfdfTab;
                    app.alignmentPanel.SelectedTab=app.virtualApertureTab;
                    set(app.virtualAperturePanel.Children,'Enable','on');
                    set(app.aperture_size,'Limits',[0 app.params.rmax/2],'Value',app.params.radius)
                    set(app.aperture_size_input,'Limits',app.aperture_size.Limits*app.params.mrad_per_pix,'Value',app.aperture_size.Value*app.params.mrad_per_pix);
                    app.ImageDropDown.Items={'Diffraction Pattern';
                        'BF/DF'};
                    app.ImageDropDown.ItemsData={'app.images.pacbed';
                        'app.images.adf'};
                    app.comTab.Parent=[];
                    app.vectorFieldTab.Parent=[];
                    app.colorwheelTab.Parent=[];
                    integrateMasks(app,event)
                    displayADF(app,event)
                case app.CenterofMassButton
                    app.comTab.Title = 'Center of Mass';
                    buttons = findall(app.comTab,'Type','uiToggleButton');
                    for ii=1:size(buttons,1)
                        buttons(ii).Text = strrep(buttons(ii).Text,'DPC','CoM');
                    end
                    app.comTab.Parent=app.outputPanel;
                    app.vectorFieldTab.Parent=app.outputPanel;
                    app.virtualApertureTab.Parent=[];
                    app.colorwheelTab.Parent=app.alignmentPanel;
                    app.outputPanel.SelectedTab=app.comTab;
                    set(app.detector_panel.Children,'Enable','on');
                    set(app.det_rot_panel.Children,'Enable','off');
                    app.alignmentPanel.SelectedTab=app.colorwheelTab;
                    app.ImageDropDown.Items={'Diffraction Pattern';
                        'BF/DF';
                        'COM X';
                        'COM Y';
                        'COM Magnitude'};
                    app.ImageDropDown.ItemsData={'app.images.pacbed';
                        'app.images.adf';
                        'app.images.comx';
                        'app.images.comy';
                        'app.images.com_mag'};
                    1
                    integrateMasks(app,event)
                    2
                    displayCoM(app)
                    3
            end
            
            if ~app.VirtualApertureButton.Value
                % Plot ADF image
                app.images.adf = sum(app.adf.images(:,:,round(app.adf_inner.Value*2)+1:round(app.adf_outer.Value*2)+1),3);
                plotImage(app,'app.images.adf');
            end
            
            plotDetector(app, event);
            imageControls(app, event);
        end

        % Callback function: adf_inner, adf_inner, adf_inner_input, 
        % adf_inner_minus, adf_inner_plus, adf_outer, adf_outer, 
        % adf_outer_input, adf_outer_minus, adf_outer_plus, 
        % det_angle, det_angle, det_angle_input, det_rot_minus, 
        % det_rot_plus, linkADF, nrung, nseg
        function annularDetector(app, event)
            switch event.Source
                case app.adf_inner_minus % adf inner angle minus button
                    if app.adf_inner.Value-0.25<0
                        app.adf_inner.Value=0;
                        app.adf_inner_input.Value=0;
                    else
                        app.adf_inner.Value=app.adf_inner.Value-0.25;
                        app.adf_inner_input.Value=app.adf_inner_input.Value-0.25*app.params.mrad_per_pix;
                    end
                case app.adf_inner_plus % adf inner angle plus button
                    if app.adf_inner.Value+0.25<app.adf_outer.Value
                        app.adf_inner.Value=app.adf_inner.Value+0.25;
                        app.adf_inner_input.Value=app.adf_inner_input.Value+0.25*app.params.mrad_per_pix;
                    else
                        app.adf_inner.Value=app.adf_outer.Value;
                        app.adf_inner_input.Value=app.adf_outer_input.Value;
                    end
                case app.adf_inner % adf inner angle slider
                    if event.Value>app.adf_outer.Value && ~app.linkADF.Value
                        app.adf_inner.Value=app.adf_outer.Value;
                    else
                        app.adf_inner.Value = event.Value;
                    end
                    app.adf_inner_input.Value=event.Value*app.params.mrad_per_pix;
                case app.adf_inner_input % adf inner angle numerical field
                    if event.Value>app.adf_outer_input.Value && ~app.linkADF.Value
                        app.adf_inner_input.Value=app.adf_outer_input.Value;
                        app.adf_inner.Value=app.adf_outer.Value;
                    elseif app.linkADF.Value && event.Value>app.adf_outer.Limits(2)-app.params.detWidth
                        app.adf_inner.Value = app.adf_outer.Limits(2)-app.params.detWidth;
                        app.adf_inner_input.Value = app.adf_inner.Value*app.params.mrad_per_pix;
                    elseif event.Value<0
                        app.adf_inner_input.Value=0;
                        app.adf_inner.Value=0;
                    else
                        app.adf_inner_input.Value = event.Value;
                        app.adf_inner.Value=event.Value/app.params.mrad_per_pix;
                    end
                case app.adf_outer_minus % adf outer angle minus button
                    if app.adf_outer.Value-0.25>=app.adf_inner.Value
                        app.adf_outer.Value=app.adf_outer.Value-0.25;
                        app.adf_outer_input.Value=app.adf_outer_input.Value-0.25*app.params.mrad_per_pix;
                    else
                        app.adf_outer.Value=app.adf_inner.Value;
                        app.adf_outer_input.Value=app.adf_inner_input.Value;
                    end
                case app.adf_outer_plus % adf outer angle plut button
                    if app.adf_outer.Value+0.25<app.params.rmax
                        app.adf_outer.Value=app.adf_outer.Value+0.25;
                        app.adf_outer_input.Value=app.adf_outer_input.Value+0.25*app.params.mrad_per_pix;
                    else
                        app.adf_outer.Value=app.params.rmax;
                        app.adf_outer_input.Value=app.params.rmax*app.params.mrad_per_pix;
                    end
                case app.adf_outer % adf outer angle slider
                    if event.Value<app.adf_inner.Value && ~app.linkADF.Value
                        app.adf_outer.Value=app.adf_inner.Value;
                    else
                        app.adf_outer.Value = event.Value;
                    end
                    app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                case app.adf_outer_input % adf outer angle numerical field
                    if event.Value>app.params.rmax*app.params.mrad_per_pix
                        app.adf_outer_input.Value=app.adf_outer.Value*app.params.mrad_per_pix;
                        app.adf_outer.Value=app.params.rmax;
                    elseif app.linkADF.Value && event.Value<app.adf_inner.Limits(1)+app.params.detWidth
                        app.adf_outer.Value = app.adf_inner.Limits(1)+app.params.detWidth;
                        app.adf_outer_input.Value = app.adf_outer.Value*app.params.mrad_per_pix;
                    elseif event.Value<app.adf_inner_input.Value && ~app.linkADF.Value
                        app.adf_outer.Value=app.adf_inner.Value;
                        app.adf_outer_input.Value=app.adf_inner.Value*app.params.mrad_per_pix;
                    else
                        app.adf_outer.Value=event.Value/app.params.mrad_per_pix;
                    end
                case app.det_angle
                    app.det_angle.Value=event.Value;
                    app.det_angle_input.Value=rad2deg(app.det_angle.Value);
                case app.det_angle_input
                    app.det_angle_input.Value=app.det_angle_input.Value+...
                        360.*(app.det_angle_input.Value<-180)-...
                        360.*(app.det_angle_input.Value>180);
                    app.det_angle.Value=deg2rad(event.Value);
                case app.det_rot_minus
                    app.det_angle_input.Value=app.det_angle_input.Value-0.25+...
                        360.*(app.det_angle_input.Value-0.25<-180);
                    app.det_angle.Value=deg2rad(app.det_angle_input.Value);
                case app.det_rot_plus
                    app.det_angle_input.Value=app.det_angle_input.Value+0.25+...
                        360.*(app.det_angle_input.Value+0.25>180);
                    app.det_angle.Value=deg2rad(app.det_angle_input.Value);
                case app.nseg
                    app.nseg.Value=round(event.Value);
            end
            
            if app.linkADF.Value
                switch event.Source
                    case {app.adf_inner,app.adf_inner_input,app.adf_inner_plus,app.adf_inner_minus}
                        if app.adf_inner.Value+app.params.detWidth <= app.adf_outer.Limits(2)
                            app.adf_outer.Value = app.adf_inner.Value+app.params.detWidth;
                            app.adf_outer_input.Value = app.adf_outer.Value * app.params.mrad_per_pix;
                        else
                            app.adf_outer.Value = app.adf_outer.Limits(2);
                            app.adf_outer_input.Value = app.adf_outer.Value * app.params.mrad_per_pix;
                            app.adf_inner.Value = app.adf_outer.Limits(2)-app.params.detWidth;
                            app.adf_inner_input.Value = app.adf_inner.Value * app.params.mrad_per_pix;
                        end
                    case {app.adf_outer,app.adf_outer_input,app.adf_outer_plus,app.adf_outer_minus}
                        if app.adf_outer.Value-app.params.detWidth >= app.adf_inner.Limits(1)
                            app.adf_inner.Value = app.adf_outer.Value-app.params.detWidth;
                            app.adf_inner_input.Value = app.adf_inner.Value * app.params.mrad_per_pix;
                        else
                            app.adf_inner.Value = app.adf_outer.Limits(1);
                            app.adf_inner_input.Value = app.adf_inner.Value * app.params.mrad_per_pix;
                            app.adf_outer.Value = app.adf_inner.Limits(1)+app.params.detWidth;
                            app.adf_outer_input.Value = app.adf_outer.Value * app.params.mrad_per_pix;
                        end
                end
            end
            
            plotDetector(app,event)
            
            % Display BF/ADF images **NOTE** the factors of 2 here are
            % because the radial integration was done on a 1/2 pixel step.
            % The plus 1 is because the array begins at 0, but is 1-indexed.
            start  = round(min(app.adf_inner.Value,max(app.adf_outer.Value,app.adf_inner.Value))*2)+1;
            finish = round(max(app.adf_outer.Value,app.adf_inner.Value)*2)+1;
            
            app.images.adf = sum(app.adf.images(:,:,start:finish),3);
            app.mask.bfdf = reshape(sum(app.mask.adf(start:finish,:),1),[app.params.nopix app.params.nopiy]);
            plotImage(app,'app.images.adf');
%             app.bfdf_image.YDir='reverse';
%             axis(app.bfdf_image,'image')
%             %axis(app.bfdf_image,[1 app.params.framesx 1 app.params.framesy])
%             app.bfdf_image.YTick=[];
%             app.bfdf_image.XTick=[];
%             caxis(app.bfdf_image,'auto')
            updateImStats(app)
            
            if app.all_patterns.Value; plotMarker(app);end
            
            if app.live_update.Value
                switch app.DetectorTypeButtonGroup.SelectedObject
                    case {app.CenterofMassButton,app.SegmentedButton}
                        integrateMasks(app,event)
                        displayCoM(app)
                end
            end
            
            % save tmp values for current adf_inner and adf_out values
            % in case they are set to move together
            app.params.detWidth = app.adf_outer.Value - app.adf_inner.Value;
        end

        % Callback function: CoordinatesButtonGroup, ap_left_rot, 
        % ap_mirror, ap_mirror_angle, ap_mirror_angle, 
        % ap_mirror_input, ap_mirror_minus, ap_mirror_plus, 
        % ap_mirror_reset, ap_negative, ap_right_rot, ap_rotAngle, 
        % ap_size_minus, ap_size_plus, ap_x_minus, ap_x_plus, 
        % ap_x_reset, ap_y_minus, ap_y_plus, ap_y_reset, 
        % aperture_size, aperture_size, aperture_size_input, 
        % aperture_symmetry, aperture_x, aperture_x, 
        % aperture_x_input, aperture_y, aperture_y, aperture_y_input
        function virtualAperture(app, event)
            % Drop down value
            rot_value=deg2rad(str2double(app.ap_rotAngle.Value));
            
            switch event.Source
                case app.CoordinatesButtonGroup % radio button group to swtich between polar and cartesian coordinates
                    switch app.CoordinatesButtonGroup.SelectedObject
                        case app.CartesianButton
                            app.DistanceLabel.Text='X';
                            app.RotationLabel.Text='Y';
                            app.aperture_y_Label.Text='mrad';
                            
                            app.aperture_x.Limits=[-app.params.nopix/2 app.params.nopix/2];
                            app.aperture_x_input.Limits=app.aperture_x.Limits*app.params.mrad_per_pix;
                            app.aperture_x.Value=app.aperture_x.Value*cos(app.aperture_y.Value);
                            app.aperture_x_input.Value=app.aperture_x.Value*app.params.mrad_per_pix;
                            
                            app.aperture_y.Limits=[-app.params.nopiy/2 app.params.nopiy/2];
                            app.aperture_y_input.Limits=app.aperture_y.Limits*app.params.mrad_per_pix;
                            app.aperture_y.Value=app.aperture_x.Value*sin(app.aperture_y.Value);
                            app.aperture_y_input.Value=app.aperture_y.Value*app.params.mrad_per_pix;
                            
                        case app.PolarButton
                            app.DistanceLabel.Text='Distance';
                            app.RotationLabel.Text='Rotation';
                            app.aperture_y_Label.Text='°';
                            
                            app.aperture_x.Limits=[0 app.params.nopix/2];
                            app.aperture_x_input.Limits=app.aperture_x.Limits*app.params.mrad_per_pix;
                            app.aperture_x.Value=min(sqrt(sum((app.aperture.center-app.center).^2)),app.aperture_x.Limits(2));
                            app.aperture_x_input.Value=app.aperture_x.Value*app.params.mrad_per_pix;
                            
                            app.aperture_y.Limits=[-pi pi];
                            app.aperture_y_input.Limits=rad2deg(app.aperture_y.Limits);
                            app.aperture_y.Value=atan2(app.aperture.center(2)-app.center(2),app.aperture.center(1)-app.center(1));
                            app.aperture_y_input.Value=rad2deg(app.aperture_y.Value);
                    end
                    
                case app.aperture_size % aperture size slider
                    app.aperture.radius=event.Value;
                    app.aperture_size_input.Value=event.Value*app.params.mrad_per_pix;
                    
                case app.aperture_size_input % aperture size numerical field
                    if (app.aperture_size_input.Value/app.params.mrad_per_pix>=app.aperture_size.Limits(1)) && (app.aperture_size_input.Value/app.params.mrad_per_pix<=app.aperture_size.Limits(2))
                        app.aperture_size.Value=event.Value/app.params.mrad_per_pix;
                        app.aperture.radius=app.aperture_size.Value;
                    else % out of bounds, move to nearest bound
                        [~,indexOf]=min(sqrt((app.ap_size_input.Value/app.params.mrad_per_pix-app.aperture_size.Limits).^2));
                        app.aperture_size.Value=app.aperture_size.Limits(indexOf);
                        app.ap_size_input.Value=app.aperture_size.Limits(indexOf);
                    end
                    
                case app.ap_size_plus % apperture size+ button
                    app.aperture.radius=app.aperture.radius+0.25.*(app.aperture_size.Value+0.25<=app.aperture_size.Limits(2));
                    app.aperture_size.Value=app.aperture_size.Value+0.25.*(app.aperture_size.Value+0.25<=app.aperture_size.Limits(2));
                    
                case app.ap_size_minus % aperture size- button
                    app.aperture.radius=app.aperture.radius-0.25.*(app.aperture_size.Value-0.25>=0);
                    app.aperture_size.Value=app.aperture_size.Value-0.25.*(app.aperture_size.Value-0.25>0);
                    
                case app.aperture_symmetry % aperture symmetry number spinner
                    % On symmetry # change (ignore symmetry value of 5)
                    clearDetectors(app);
                    if event.Value==5 && event.PreviousValue<5
                        app.aperture_symmetry.Value=6;
                    elseif event.Value==5 && event.PreviousValue==6
                        app.aperture_symmetry.Value=4;
                    end
                    
                case app.ap_mirror% mirror aperture(s) checkbox
                    switch app.ap_mirror.Value
                        case 0 % Unchecked
                            set(app.mirrorPanel.Children,'Enable','off');
                            try delete(app.annotations.mirror_line);catch;end
                        case 1 % Checked
                            set(app.mirrorPanel.Children,'Enable','on');
                    end
                    
                case app.ap_mirror_angle % mirror plane angle slider
                    app.ap_mirror_input.Value=rad2deg(event.Value);
                    try delete(app.annotations.mirror_line);catch;end
                    app.diffPattern.NextPlot='add';
                    app.annotations.mirror_line = plot(app.diffPattern,[1 app.params.nopix],tan(event.Value)*([1 app.params.nopix]-app.center(1))+app.center(2),'g--','LineWidth',2);
                    
                case app.ap_mirror_input % mirror plan angle numerical input field
                    app.ap_mirror_angle.Value=deg2rad(rem(event.Value,180)+180.*(event.Value<=-180)-180.*(event.Value>180));
                    try delete(app.annotations.mirror_line);catch;end
                    app.diffPattern.NextPlot='add';
                    app.annotations.mirror_line = plot(app.diffPattern,[1 app.params.nopix],tan(app.ap_mirror_angle.Value)*([1 app.params.nopix]-app.center(1))+app.center(2),'g--','LineWidth',2);
                    
                case app.ap_mirror_plus % mirror plane right rotation button
                    app.ap_mirror_angle.Value=app.ap_mirror_angle.Value+deg2rad(0.5);
                    app.ap_mirror_input.Value=app.ap_mirror_input.Value+0.5;
                    
                case app.ap_mirror_minus % mirror plane left rotation
                    app.ap_mirror_angle.Value=app.ap_mirror_angle.Value-deg2rad(0.5);
                    app.ap_mirror_input.Value=app.ap_mirror_input.Value-0.5;
                    
                case app.ap_mirror_reset % mirror plane reset button
                    app.ap_mirror_angle.Value=0;
                    app.ap_mirror_input.Value=0;
                    
                case app.aperture_x_input
                    if (event.Value/app.params.mrad_per_pix>=app.aperture_x.Limits(1)) && (event.Value/app.params.mrad_per_pix<=app.aperture_x.Limits(2))
                        app.aperture_x.Value=app.aperture_x_input.Value/app.params.mrad_per_pix;
                    else %out of bounds, move to nearest bound
                        [~,indexOf] = min(sqrt((event.Value - app.aperture_x.Limits/app.params.mrad_per_pix).^2));
                        app.aperture_x.Value = app.aperture_x.Limits(indexOf);
                        app.aperture_x_input.Value=app.aperture_x.Limits(indexOf)*app.params.mrad_per_pix;
                    end
                    
                    
                otherwise % all of the buttons, sliders, numerical fields that change based on polar/cartesian selection
                    switch app.CoordinatesButtonGroup.SelectedObject
                        case app.CartesianButton
                            switch event.Source
                                case app.aperture_x % x location slider
                                    app.aperture.center=app.center+[event.Value app.aperture_y.Value];
                                    app.aperture_x_input.Value=event.Value*app.params.mrad_per_pix;
                                    
                                case app.aperture_y % y location slider
                                    app.aperture.center=app.center+[app.aperture_x.Value event.Value];
                                    app.aperture_y_input.Value=event.Value*app.params.mrad_per_pix;
                                    
                                case app.ap_x_plus % x+ button
                                    app.aperture.center=app.aperture.center+[0.25 0].*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2)); % && sqrt((app.aperture_x.Value+0.2)^2+()^2)<=min(app.aperture_x.Limit()
                                    app.aperture_x.Value=app.aperture_x.Value+0.25.*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2));
                                    
                                case app.ap_x_minus % x- button
                                    app.aperture.center=app.aperture.center-[0.25 0].*(app.aperture_x.Value-0.25>=app.aperture_x.Limits(1));
                                    app.aperture_x.Value=app.aperture_x.Value-0.25.*(app.aperture_x.Value-0.25>=app.aperture_x.Limits(1));
                                    
                                case app.ap_x_reset % x0 button
                                    app.aperture.center=[app.center(1) app.aperture.center(2)];
                                    app.aperture_x.Value=0;
                                    
                                case app.ap_y_plus % y+ button
                                    app.aperture.center=app.aperture.center+[0 0.25].*(app.aperture_y.Value+0.25<=app.aperture_y.Limits(2));
                                    app.aperture_y.Value=app.aperture_y.Value+0.25.*(app.aperture_y.Value+0.25<=app.aperture_y.Limits(2));
                                    
                                case app.ap_y_minus % y- button
                                    app.aperture.center=app.aperture.center-[0 0.25].*(app.aperture_y.Value-0.25>=app.aperture_y.Limits(1));
                                    app.aperture_y.Value=app.aperture_y.Value-0.25.*(app.aperture_y.Value-0.25>=app.aperture_y.Limits(1));
                                    
                                case app.ap_y_reset % y0 button
                                    app.aperture.center=[app.aperture.center(1) app.center(2)];
                                    app.aperture_y.Value=0;
                                    
                                case app.ap_left_rot % left rotation button
                                    temp_rad=sqrt(sum((app.aperture.center-app.center).^2)).*(sqrt(sum((app.aperture.center-app.center).^2))<=min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)))+...
                                        min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)).*(sqrt(sum((app.aperture.center-app.center).^2))>min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)));
                                    temp_ang=atan2(app.aperture.center(2)-app.center(2),app.aperture.center(1)-app.center(1))-rot_value;
                                    app.aperture_x.Value=temp_rad*cos(temp_ang);
                                    app.aperture_y.Value=temp_rad*sin(temp_ang);
                                    app.aperture.center=app.center+[app.aperture_x.Value app.aperture_y.Value];
                                    
                                case app.ap_right_rot % right rotation button
                                    temp_rad=sqrt(sum((app.aperture.center-app.center).^2)).*(sqrt(sum((app.aperture.center-app.center).^2))<=min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)))+...
                                        min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)).*(sqrt(sum((app.aperture.center-app.center).^2))>min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)));
                                    temp_ang=atan2(app.aperture.center(2)-app.center(2),app.aperture.center(1)-app.center(1))+rot_value;
                                    app.aperture_x.Value=temp_rad*cos(temp_ang);
                                    app.aperture_y.Value=temp_rad*sin(temp_ang);
                                    app.aperture.center=app.center+[app.aperture_x.Value app.aperture_y.Value];
                                    
                                case app.aperture_y_input
                                    if (app.aperture_y_input.Value/app.params.mrad_per_pix>=app.aperture_y.Limits(1)) && (app.aperture_y_input.Value/app.params.mrad_per_pix<=app.aperture_y.Limits(2))
                                        app.aperture_y.Value=app.aperture_y_input.Value/app.params.mrad_per_pix;
                                    else % out of bounds, move to nearest bound
                                        [~,indexOf]=min(sqrt((app.aperture_y.Value/app.params.mrad_per_pix-app.aperture_y.Limits).^2));
                                        app.aperture_y.Value=app.aperture_y.Limits(indexOf);
                                        app.aperture_y_input.Value=app.aperture_y.Limits(indexOf)*app.params.mrad_per_pix;
                                    end
                            end
                        case app.PolarButton
                            switch event.Source
                                case app.aperture_x % distance slider
                                    app.aperture.center=app.center+event.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    app.aperture_x_input.Value=event.Value*app.params.mrad_per_pix;
                                    
                                case app.aperture_y % rotation slider
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(event.Value) sin(event.Value)];
                                    app.aperture_y_input.Value=rad2deg(event.Value);
                                    
                                case app.ap_x_plus % distance+ button
                                    app.aperture_x.Value=app.aperture_x.Value+0.25.*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2));
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.ap_x_minus % distance- button
                                    app.aperture_x.Value=app.aperture_x.Value-0.25.*(app.aperture_x.Value-0.25>=0);
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.ap_x_reset % distance0 button
                                    app.aperture.center=app.center;
                                    app.aperture_x.Value=0;
                                    
                                case app.ap_y_plus % rotation+ button
                                    app.aperture_y.Value=app.aperture_y.Value+deg2rad(0.25)-range(app.aperture_y.Limits).*(app.aperture_y.Value+deg2rad(0.25)>app.aperture_y.Limits(2));
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.ap_y_minus % rotation- button
                                    app.aperture_y.Value=app.aperture_y.Value-deg2rad(0.25)+range(app.aperture_y.Limits).*(app.aperture_y.Value-deg2rad(0.25)<app.aperture_y.Limits(1));
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.ap_y_reset % rotation0 button
                                    app.aperture.center=app.center+[app.aperture_x.Value 0];
                                    app.aperture_y.Value=0;
                                    
                                case app.ap_left_rot % left rotation button
                                    app.aperture_y.Value=app.aperture_y.Value-rot_value+2*pi.*(app.aperture_y.Value-rot_value<-pi);
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.ap_right_rot % right rotation button
                                    app.aperture_y.Value=app.aperture_y.Value+rot_value-2*pi.*(app.aperture_y.Value+rot_value>pi);
                                    app.aperture.center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                                    
                                case app.aperture_y_input
                                    if (deg2rad(app.aperture_y_input.Value)>=app.aperture_y.Limits(1) && deg2rad(app.aperture_y_input.Value)<=app.aperture_y.Limits(2))
                                        app.aperture_y.Value=deg2rad(app.aperture_y_input.Value);
                                    else % out of bounds (-pi,pi] , move mod(input,360) to within bounds
                                        app.aperture_y.Value=deg2rad(mod(app.aperture_y_input.Value,360))+2*pi.*(deg2rad(mod(app.aperture_y_input.Value,360))<-pi)-2*pi.*(deg2rad(mod(app.aperture_y_input.Value,360))>pi);
                                        app.aperture_y_input.Value=rad2deg(app.aperture_y.Value);
                                    end
                            end
                    end
            end
            
            % update other fields based on the event that took place
            % (could go in each case above, but this is more compact)
            switch event.Source.Type
                case 'uibutton'
                    if app.CartesianButton.Value==1
                        app.aperture_x_input.Value=app.aperture_x.Value*app.params.mrad_per_pix;
                        app.aperture_y_input.Value=app.aperture_y.Value*app.params.mrad_per_pix;
                        app.aperture_size_input.Value=app.aperture_size.Value*app.params.mrad_per_pix;
                    elseif app.PolarButton.Value==1
                        app.aperture_x_input.Value=app.aperture_x.Value*app.params.mrad_per_pix;
                        app.aperture_y_input.Value=rad2deg(app.aperture_y.Value);
                        app.aperture_size_input.Value=app.aperture_size.Value*app.params.mrad_per_pix;
                    end
                case 'uinumericeditfield'
                    if app.CartesianButton.Value==1
                        app.aperture.center = app.center + [app.aperture_x.Value app.aperture_y.Value];
                    elseif app.PolarButton.Value==1
                        app.aperture.center = app.center + app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                    end
            end
            
            plotDetector(app,event);
            
            if app.ap_mirror.Value && (event.Source ~= app.ap_mirror_angle) % mirror plane enabled
                try delete(app.annotations.mirror_line);catch;end
                app.diffPattern.NextPlot='add';
                app.annotations.mirror_line = plot(app.diffPattern,[1 app.params.nopix],tan(app.ap_mirror_angle.Value)*([1 app.params.nopix]-app.center(1))+app.center(2),'g--','LineWidth',2);
            end
            
            % Live update images
            if app.live_update.Value
                displayADF(app,event);
            end
        end

        % Callback function: comAngleButtonGroup, comMagButtonGroup, 
        % comxButtonGroup, comyButtonGroup, vectButtonGroup, 
        % vectSubsample
        function firstMoment(app, event)
            
            app.limits.im=[app.vectorField.XLim app.vectorField.YLim];
            app.vectorField.NextPlot='replace';
            
            switch event.Source
                case app.vectSubsample % subsample the vector field map
                    app.params.subsample=find(ismember(app.vectSubsample.Items,app.vectSubsample.Value));
                    if find(ismember(app.vectSubsample.Items,app.vectSubsample.Value))>2
                        app.params.subsample=2^(app.params.subsample-2);
                    else
                        app.params.subsample=1;
                    end
                    plotVectorField(app)
                case app.vectButtonGroup % change image on vector field tab
                    plotVectorField(app)
                otherwise % change button group item on main CoM tab to change which image is displayed
                    displayCoM(app)
            end
            
        end

        % Callback function: ImageDropDown, autoSaveButton, 
        % brightness, brightness, brightness_reset, contrast, 
        % contrast, contrast_reset, display_images, dpPan, dpReset, 
        % dpZoom, gamma, gamma, gamma_reset, histLog, saveButton, 
        % saveVecField, save_images, vectPan, vectReset, vectZoom
        function imageControls(app, event)
            switch event.Source
                case app.display_images
                    app.save_images.Enable='on';
                    
                    tmp_msg = uiprogressdlg(app.Quant4D_Fig,'Title','Forming Images...','Message','Please Wait...','Indeterminate',"on");
                    
                    switch app.DetectorTypeButtonGroup.SelectedObject
                        case app.SegmentedButton
                            integrateMasks(app,event)
                            displayCoM(app)
                        case app.CenterofMassButton
                            integrateMasks(app,event)
                            displayCoM(app)
                        case app.VirtualApertureButton
                            displayADF(app,event)
                    end
                    
                    close(tmp_msg)
                case app.vectPan % pan vector field image
                    if app.vectPan.Value
                        pan(app.vectorField,'on');
                        zoom(app.vectorField,'off');
                        app.vectZoom.Value=0;
                    else
                        pan(app.vectorField,'off');
                    end
                    
                case app.dpPan % pan on the diffraction pattern
                    if app.dpPan.Value
                        pan(app.diffPattern,'on');
                        zoom(app.diffPattern,'off');
                        app.dpZoom.Value=0;
                    else
                        pan(app.diffPattern,'off');
                    end
                    
                case app.vectZoom % zoom vector field image
                    if app.vectZoom.Value
                        zoom(app.vectorField,'on');
                        pan(app.vectorField,'off');
                        app.vectPan.Value=0;
                    else
                        zoom(app.vectorField,'off');
                    end
                    
                case app.dpZoom % zoom diffraction pattern
                    if app.dpZoom.Value
                        zoom(app.diffPattern,'on');
                        pan(app.diffPattern,'off');
                        app.dpPan.Value=0;
                    else
                        zoom(app.diffPattern,'off');
                    end
                    
                case app.vectReset % reset vector field image axes
                    app.limits.im=[1 app.params.framesx 1 app.params.framesy];
                    axis(app.vectorField,app.limits.im);
                    app.vectPan.Value=0;
                    app.vectZoom.Value=0;
                    zoom(app.vectorField,'off');
                    pan(app.vectorField,'off');
                    
                case app.dpReset % reset diffraction pattern axes
                    app.limits.dp=[1 app.params.nopix 1 app.params.nopiy];
                    axis(app.diffPattern,app.limits.dp);
                    app.dpPan.Value=0;
                    app.dpZoom.Value=0;
                    zoom(app.diffPattern,'off');
                    pan(app.diffPattern,'off');
                    
                case app.saveVecField % save vector field as image and figure
                    saveVect(app);
                    figure(app.Quant4D_Fig);
                    
                case {app.save_images,app.saveButton,app.autoSaveButton} % regular image save modes
                    % Try to close any previous 'Save Images' windows
                    if ~isempty(findall(0,'Name','Save Images'))
                        close(findall(0,'Name','Save Images'))
                    end
                    
                    % switch from "save images" to the save/autosave buttons
                    if app.save_images.Visible
                        app.save_images.Visible=0;
                        app.saveButton.Visible=1;app.saveButton.Enable=1;
                        app.autoSaveButton.Visible=1;app.autoSaveButton.Enable=1;
                    end
                    
                    switch event.Source
                        case app.autoSaveButton
                            app.savePrefs.autosave=1;
                        otherwise
                            app.savePrefs.autosave=0;
                    end
                    
                    save4Dimages(app,app.savePrefs);
                    figure(app.Quant4D_Fig);
                    
                otherwise % Brightness/contrast/gamma settings:
                    % Initial contrast will be set as the total range of min:max
                    % A change in contrast will expand or contract the width of the range
                    % Initial brightness will be also set as the total range of min:max
                    % A change in brightness will result in shift of the range up or down
                    % Initial gamma is set to 1
                    % A change in gamma will result in a powerlaw change in contrast I=I0^gamma
                    
                    if strcmp(event.Source,app.ImageDropDown)
                        app.brightness.Value = app.image_choices.brightness(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                        app.contrast.Value   = app.image_choices.contrast(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                        app.gamma.Value      = app.image_choices.gamma(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                    end
                    app.ax_choice = app.image_choices.axis{strcmp(app.ImageDropDown.Value,app.image_choices.image)};
                    % if the user has selected an image that is not
                    % currently displayed, the axis will still be supplied,
                    % even though app.image_choices.axis is empty for that
                    % image (i.e. if ADF is displayed instead of CoMx).
                    % This is only possible for CoMx, CoMy, and CoM
                    % Magnitude. The others are either never hidden or do
                    % not have brightness/contrast/gamma controls
                    if strcmp(app.ax_choice,'')
                        ax = {'';'';'app.comx';'app.comy';'app.comMag';'';'';''};
                        app.ax_choice = ax{strcmp(app.ImageDropDown.Value,app.image_choices.image)};
                    end
                    
                    switch event.Source
                        case app.brightness
                            app.brightness.Value=event.Value;
                            app.image_choices.brightness(strcmp(app.ImageDropDown.Value,app.image_choices.image)) = app.brightness.Value;
                        case app.brightness_reset
                            app.brightness.Value=0;
                            app.image_choices.brightness(strcmp(app.ImageDropDown.Value,app.image_choices.image)) = 0;
                        case app.contrast
                            app.contrast.Value=event.Value;
                            app.image_choices.contrast(strcmp(app.ImageDropDown.Value,app.image_choices.image))   = app.contrast.Value;
                        case app.contrast_reset
                            app.contrast.Value=0;
                            app.image_choices.contrast(strcmp(app.ImageDropDown.Value,app.image_choices.image))   = 0;
                        case {app.gamma,app.gamma_reset} %gamma and gamma_reset
                            if event.Source == app.gamma_reset
                                app.gamma.Value=1;
                                app.image_choices.gamma(strcmp(app.ImageDropDown.Value,app.image_choices.image))      = 1;
                            elseif event.Source == app.gamma
                                app.gamma.Value=event.Value;
                                app.image_choices.gamma(strcmp(app.ImageDropDown.Value,app.image_choices.image))      = app.gamma.Value;
                            end
                        case app.ImageDropDown
                            % Define the currently selected image from the dropdown
                            % and its associated brightness, contrast, and gamma values
                            % to update slider bars
                            app.brightness.Value = app.image_choices.brightness(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                            app.contrast.Value   = app.image_choices.contrast(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                            app.gamma.Value      = app.image_choices.gamma(strcmp(app.ImageDropDown.Value,app.image_choices.image));
                    end
                    
                    % replot images and update stats and histogram for the given image
                    plotImage(app,app.ImageDropDown.Value)
                    updateImStats(app)
                    
                    % if not in alignment mode, replot the detectors over
                    % the image
                    if ~app.align_trans_beam.Value
                        plotDetector(app,event)
                    end
            end
            
            
        end

        % Callback function: DiffractionCalibration, 
        % EditManualCalButton, TransmittedDiskRadius, centerX, 
        % centerY, keV, transCal, transRad
        function calibration_TODO(app, event)
        switch event.Source
            case app.ManualCalButton % Push the "Auto Align" button in the Alignment Tab
                app.EditManualCalButton.Value=1;
                app.alignmentPanel.SelectedTab = app.CalibrationTab;
                app.EditManualCalButton.Text = 'Finish';
                app.keV.Editable = 'on';
                app.transCal.Editable = 'on';
                app.transRad.Editable = 'on';
                app.centerX.Editable = 'on';
                app.centerY.Editable = 'on';
            case app.EditManualCalButton % Push the "edit/finish" button in the "Calibration" Tab
                switch app.EditManualCalButton.Value
                    case 0 % Edit off, turn off all features
                        app.EditManualCalButton.Text = 'Edit';
                        app.keV.Editable = 'off';
                        app.transCal.Editable = 'off';
                        app.transRad.Editable = 'off';
                        app.centerX.Editable = 'off';
                        app.centerY.Editable = 'off';
                        app.alignmentPanel.SelectedTab = app.alignmentTab;
                        app.align_trans_beam.Value = 0;
                        clearDetectors(app);
                        alignment(app, event); % send back to main alignment routine and end
                        
                    case 1 % Edit on, allow user to change values
                        app.EditManualCalButton.Text = 'Finish';
                        app.keV.Editable = 'on';
                        app.transCal.Editable = 'on';
                        app.transRad.Editable = 'on';
                        app.centerX.Editable = 'on';
                        app.centerY.Editable = 'on';
                end
            case app.DiffractionCalibration % Diffraction Calibration units
                switch app.DiffractionCalibration.SelectedObject
                    case app.mradButton
                        app.transCal.Value = 1/app.params.mrad_per_pix;
                        app.pixelsmradEditFieldLabel.Text = 'pixels / mrad';
                    case app.invAButton
                        app.transCal.Value = app.params.radius/mrad2invA(app,app.alpha.Value,'mrad');
                        app.pixelsmradEditFieldLabel.Text = 'pixels / invA';
                end
            case app.TransmittedDiskRadius % Transmitted Disk Radius units
                switch app.TransmittedDiskRadius.SelectedObject
                    case app.transRad_pix
                        app.transRad.Value = app.params.radius;
                    case app.transRad_mrad
                        app.transRad.Value = app.alpha.Value;
                    case app.transRad_invA
                        app.transRad.Value = mrad2invA(app,app.alpha.Value,'mrad');
                end
            case app.transCal % Manually changing the calibration in pixles/mrad or pixels/invA
                switch app.DiffractionCalibration.SelectedObject
                    case app.mradButton
                        app.params.mrad_per_pix = 1/app.transCal.Value;
                    case app.invAButton
                        app.params.mrad_per_pix = 1/mrad2invA(app,app.params.mrad_per_pix,'mrad');
                end
            case app.transRad % Manually changing the transmitted disk radius size in pixels, mrad, or invA
                switch app.TransmittedDiskRadius.SelectedObject
                    case app.transRad_pix
                        app.params.radius = app.transRad.Value;
                    case app.transRad_mrad
                        app.alpha.Value = app.transRad.Value;
                    case app.transRad_invA
                        app.alpha.Value = mrad2invA(app,app.transRad.Value,'invA');
                end
        end
        
        % if the 'edit' button is 'on':
        if app.EditManualCalButton.Value
            % update the diffraction pattern center with the user-defined values
            app.params.prev_center = app.center;
            app.center = [app.centerX.Value app.centerY.Value];
            
            % replot the transmitted disk outline
            clearDetectors(app);
            plotCircle(app,'disk_edge',app.diffPattern,app.params.radius,app.center,'b')
        end

        end

        % Callback function: Quant4D_Fig, Quant4D_Fig, Quant4D_Fig
        function mouseMovement(app, event)
            % if individual patterns are to be shown (i.e. a probe marker
            % on the BF/DF image) allow the user to click around the image
            % to display the diffraction pattern closest to the clicked
            % point. Also allows for click-and-drag, though this is laggy
            % so it is limited to a refresh rate of 4Hz, at the moment
            if app.all_patterns.Value
                % current position - screen origin
                curPos = app.Quant4D_Fig.Position(1:2)+event.Source.CurrentPoint;
                if app.outputPanel.SelectedTab == app.bfdfTab % clicking around the single, large BF/DF image
                    % set axis
                    ax = 'app.bfdf_image';
                    
                    % find center of axis area in screen pixels
                    imCenter = floor(eval(ax).InnerPosition(3:4)/2) + (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.bfdfTab.Position(1:2)+eval(ax).InnerPosition(1:2));

                elseif app.outputPanel.SelectedTab == app.comTab
                    % find out in which axis the user is clicking
                    axPos = [[(app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comx.InnerPosition(1:2)) (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comx.InnerPosition(1:2))+app.comx.Position(3:4)];
                             [(app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comy.InnerPosition(1:2)) (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comy.InnerPosition(1:2))+app.comy.Position(3:4)];
                             [(app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comMag.InnerPosition(1:2)) (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comMag.InnerPosition(1:2))+app.comMag.Position(3:4)];
                             [(app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comPhase.InnerPosition(1:2)) (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+app.comPhase.InnerPosition(1:2))+app.comPhase.Position(3:4)]];
                    axName = {'app.comx';'app.comy';'app.comMag';'app.comPhase'};
                    axIdx = curPos(1)>= axPos(:,1) & curPos(1)<=axPos(:,3) & curPos(2)>=axPos(:,2) & curPos(2)<=axPos(:,4);
                    
                    % if the pointer is within the bounds of one of the 4
                    % axes on the CoM/DPC tab, proceed
                    if sum(axIdx==1)
                        % set axis
                        ax = axName{axIdx};
                        
                        % find center of axis area in screen pixels
                        imCenter = floor(eval(ax).InnerPosition(3:4)/2) + (app.Quant4D_Fig.Position(1:2)+app.outputPanel.Position(1:2)+app.comTab.Position(1:2)+eval(ax).InnerPosition(1:2));
                    else 
                        ax = '';
                    end
                else
                    ax = '';
                end
                
                % move marker and update diffraction patterns
                if (~strcmp(ax,'')) && (~strcmp(event.EventName,'WindowMouseMotion') || round(toc*16)/16==round(toc*4)/4)
                    if app.params.markerStatus == 1
                        % pixel:pixel scaling ratio... only the
                        % larger ratio matters since all images
                        % are displayed with axis('image'), i.e.
                        % square pixels
                        axRatio = max([range(eval(ax).XLim) range(eval(ax).YLim)]./eval(ax).InnerPosition(3:4));
                        
                        % get mouse position converted from screen to image pixels
                        mousePos = round([1 -1].*(curPos - imCenter)*axRatio+0.5*[range(eval(ax).XLim) range(eval(ax).YLim)]);
                        
                        % change pointer to a hand if it is within the cllickable
                        % bounds. Only available after R2020a
                        ver = version('-release');
                        if str2double(ver(1:4))>=2020 && mousePos(1) >= eval(ax).XLim(1) && mousePos(1)<= eval(ax).XLim(2) && mousePos(2) >= eval(ax).YLim(1) && mousePos(2)<= eval(ax).YLim(2)
                            app.Quant4D_Fig.Pointer = 'hand';
                        else
                            app.Quant4D_Fig.Pointer = 'arrow';
                        end
                        
                        % make sure the marker stays within the image
                        if mousePos(1)>eval(ax).XLim(2); mousePos(1)=eval(ax).XLim(2);end
                        if mousePos(1)<eval(ax).XLim(1); mousePos(1)=eval(ax).XLim(1);end
                        if mousePos(2)>eval(ax).YLim(2); mousePos(2)=eval(ax).YLim(2);end
                        if mousePos(2)<eval(ax).YLim(1); mousePos(2)=eval(ax).YLim(1);end
                        
                        % only update images if the probe has moved to a
                        % new pixel in the data
                        if mousePos~=[app.probeX.Value app.probeY.Value]
                            app.probeX.Value = mousePos(1);
                            app.probeY.Value = mousePos(2);
                            app.stackSlider.Value = (mousePos(2)-1)*app.params.framesx + mousePos(1);
                            
                            % refresh diffraction pattern to match new probe position
                            patternViewer(app,event)
                        end
                    end
                    
                    % sort out if it is mouse down, mouse up, or mouse
                    % movement, and then update the marker status flag so that
                    % the listener knows when to make changes and when to stop
                    switch event.EventName
                        case 'WindowMouseRelease'
                            app.params.markerStatus = 0;
                        case 'WindowMousePress'
                            app.params.markerStatus = 1;
                            %case 'WindowMouseMotion'
                            % do nothing
                    end
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Quant4D_Fig = uifigure('Visible', 'off');
            app.Quant4D_Fig.Name = 'Quant4D';
            app.Quant4D_Fig.WindowButtonDownFcn = createCallbackFcn(app, @mouseMovement, true);
            app.Quant4D_Fig.WindowButtonUpFcn = createCallbackFcn(app, @mouseMovement, true);
            app.Quant4D_Fig.WindowButtonMotionFcn = createCallbackFcn(app, @mouseMovement, true);
            app.guiLayout = uigridlayout(app.Quant4D_Fig);
            app.inputPanel = uipanel(app.guiLayout);
            app.diff_pattern_radio_group = uibuttongroup(app.inputPanel);
            app.diff_pattern_radio_group.SelectionChangedFcn = createCallbackFcn(app, @patternViewer, true);
            app.sum_patterns = uiradiobutton(app.diff_pattern_radio_group);
            app.sum_patterns.Enable = 'off';
            app.sum_patterns.Text = 'Sum all diffraction patterns';
            app.sum_patterns.Value = true;
            app.all_patterns = uiradiobutton(app.diff_pattern_radio_group);
            app.all_patterns.Enable = 'off';
            app.all_patterns.Text = 'Display all diffraction patterns';
            app.display_images = uibutton(app.inputPanel, 'push');
            app.display_images.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.display_images.Enable = 'off';
            app.display_images.Text = 'Update Images';
            app.save_images = uibutton(app.inputPanel, 'push');
            app.save_images.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.save_images.Enable = 'off';
            app.save_images.Text = 'Save Images';
            app.import_data = uibutton(app.inputPanel, 'push');
            app.import_data.ButtonPushedFcn = createCallbackFcn(app, @importData, true);
            app.import_data.Text = 'Import Dataset';
            app.live_update = uicheckbox(app.inputPanel);
            app.live_update.Enable = 'off';
            app.live_update.Tooltip = {'Update images as detectors are changed.'; ''; 'If this is too slow, uncheck and use the Update Images button above after setting up the detectors '};
            app.live_update.Text = 'Update Images Live';
            app.live_update.Value = true;
            app.saveButton = uibutton(app.inputPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.saveButton.Icon = 'save.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Visible = 'off';
            app.saveButton.Tooltip = {'Open save dialog'};
            app.saveButton.Text = '';
            app.autoSaveButton = uibutton(app.inputPanel, 'push');
            app.autoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.autoSaveButton.Icon = 'autoSave.png';
            app.autoSaveButton.Enable = 'off';
            app.autoSaveButton.Visible = 'off';
            app.autoSaveButton.Tooltip = {'Autosave images using the same preferences as the last time the "Save Dialog" was used.'};
            app.autoSaveButton.Text = '';
            app.controlTab = uitabgroup(app.guiLayout);
            app.DetectorsTab = uitab(app.controlTab);
            app.DetectorsTab.Title = 'Detectors';
            app.detector_panel = uipanel(app.DetectorsTab);
            app.adf_inner_mrad = uilabel(app.detector_panel);
            app.adf_inner_mrad.Enable = 'off';
            app.adf_inner_mrad.Text = 'mrad';
            app.adf_inner_input = uieditfield(app.detector_panel, 'numeric');
            app.adf_inner_input.ValueDisplayFormat = '%.2f';
            app.adf_inner_input.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_inner_input.Enable = 'off';
            app.adf_outer_mrad = uilabel(app.detector_panel);
            app.adf_outer_mrad.Enable = 'off';
            app.adf_outer_mrad.Text = 'mrad';
            app.adf_outer_input = uieditfield(app.detector_panel, 'numeric');
            app.adf_outer_input.ValueDisplayFormat = '%.2f';
            app.adf_outer_input.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_outer_input.Enable = 'off';
            app.adf_inner_label = uilabel(app.detector_panel);
            app.adf_inner_label.Enable = 'off';
            app.adf_inner_label.Text = 'Detector Inner Angle';
            app.adf_inner = uislider(app.detector_panel);
            app.adf_inner.Limits = [0 1000];
            app.adf_inner.MajorTicks = [];
            app.adf_inner.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_inner.ValueChangingFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_inner.MinorTicks = [];
            app.adf_inner.Enable = 'off';
            app.adf_outer_label = uilabel(app.detector_panel);
            app.adf_outer_label.Enable = 'off';
            app.adf_outer_label.Text = 'Detector Outer Angle';
            app.adf_outer = uislider(app.detector_panel);
            app.adf_outer.Limits = [0 1000];
            app.adf_outer.MajorTicks = [];
            app.adf_outer.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_outer.ValueChangingFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_outer.MinorTicks = [];
            app.adf_outer.Enable = 'off';
            app.adf_inner_plus = uibutton(app.detector_panel, 'push');
            app.adf_inner_plus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_inner_plus.Icon = 'plus_icon.png';
            app.adf_inner_plus.Enable = 'off';
            app.adf_inner_plus.Text = '';
            app.adf_inner_minus = uibutton(app.detector_panel, 'push');
            app.adf_inner_minus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_inner_minus.Icon = 'minus_icon.png';
            app.adf_inner_minus.Enable = 'off';
            app.adf_inner_minus.Text = '';
            app.adf_outer_plus = uibutton(app.detector_panel, 'push');
            app.adf_outer_plus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_outer_plus.Icon = 'plus_icon.png';
            app.adf_outer_plus.Enable = 'off';
            app.adf_outer_plus.Text = '';
            app.adf_outer_minus = uibutton(app.detector_panel, 'push');
            app.adf_outer_minus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.adf_outer_minus.Icon = 'minus_icon.png';
            app.adf_outer_minus.Enable = 'off';
            app.adf_outer_minus.Text = '';
            app.linkADF = uibutton(app.detector_panel, 'state');
            app.linkADF.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.linkADF.Enable = 'off';
            app.linkADF.Tooltip = {'Move inner/outer ADF sliders together'};
            app.linkADF.Icon = 'link.png';
            app.linkADF.Text = '';
            app.DetectorTypeButtonGroup = uibuttongroup(app.DetectorsTab);
            app.DetectorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @detectorType, true);
            app.DetectorTypeButtonGroup.Title = 'Detector Type:';
            app.SegmentedButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.SegmentedButton.Enable = 'off';
            app.SegmentedButton.Text = 'Segmented';
            app.AnnularRoundButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.AnnularRoundButton.Enable = 'off';
            app.AnnularRoundButton.Text = 'Annular/Round';
            app.AnnularRoundButton.Value = true;
            app.VirtualApertureButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.VirtualApertureButton.Enable = 'off';
            app.VirtualApertureButton.Text = 'Virtual Aperture';
            app.CenterofMassButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.CenterofMassButton.Enable = 'off';
            app.CenterofMassButton.Text = 'Center of Mass';
            app.det_rot_panel = uipanel(app.DetectorsTab);
            app.det_rot_minus = uibutton(app.det_rot_panel, 'push');
            app.det_rot_minus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.det_rot_minus.Icon = 'left_rotate.png';
            app.det_rot_minus.Enable = 'off';
            app.det_rot_minus.Text = '';
            app.det_angle_deg_3 = uilabel(app.det_rot_panel);
            app.det_angle_deg_3.Enable = 'off';
            app.det_angle_deg_3.Text = '°';
            app.det_angle_input = uieditfield(app.det_rot_panel, 'numeric');
            app.det_angle_input.Limits = [-180 180];
            app.det_angle_input.ValueDisplayFormat = '%.2f';
            app.det_angle_input.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.det_angle_input.Enable = 'off';
            app.det_angle_label = uilabel(app.det_rot_panel);
            app.det_angle_label.Enable = 'off';
            app.det_angle_label.Text = 'Detector Rotation';
            app.det_angle = uislider(app.det_rot_panel);
            app.det_angle.Limits = [-3.14159265358979 3.14159265358979];
            app.det_angle.MajorTicks = [];
            app.det_angle.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.det_angle.ValueChangingFcn = createCallbackFcn(app, @annularDetector, true);
            app.det_angle.MinorTicks = [];
            app.det_angle.Enable = 'off';
            app.nseg_label = uilabel(app.det_rot_panel);
            app.nseg_label.Enable = 'off';
            app.nseg_label.Text = '# of Segments:';
            app.nseg = uieditfield(app.det_rot_panel, 'numeric');
            app.nseg.Limits = [1 16];
            app.nseg.RoundFractionalValues = 'on';
            app.nseg.ValueDisplayFormat = '%d';
            app.nseg.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.nseg.Enable = 'off';
            app.nseg.Value = 4;
            app.nrung_label = uilabel(app.det_rot_panel);
            app.nrung_label.Enable = 'off';
            app.nrung_label.Text = '# of Rungs:';
            app.nrung = uieditfield(app.det_rot_panel, 'numeric');
            app.nrung.Limits = [1 8];
            app.nrung.RoundFractionalValues = 'on';
            app.nrung.ValueDisplayFormat = '%d';
            app.nrung.ValueChangedFcn = createCallbackFcn(app, @annularDetector, true);
            app.nrung.Enable = 'off';
            app.nrung.Value = 1;
            app.det_rot_plus = uibutton(app.det_rot_panel, 'push');
            app.det_rot_plus.ButtonPushedFcn = createCallbackFcn(app, @annularDetector, true);
            app.det_rot_plus.Icon = 'right_rotate.png';
            app.det_rot_plus.Enable = 'off';
            app.det_rot_plus.Text = '';
            app.BrightnessContrastTab = uitab(app.controlTab);
            app.BrightnessContrastTab.Title = 'Brightness/Contrast';
            app.ImageLabel = uilabel(app.BrightnessContrastTab);
            app.ImageLabel.Enable = 'off';
            app.ImageLabel.Text = 'Image:';
            app.ImageDropDown = uidropdown(app.BrightnessContrastTab);
            app.ImageDropDown.Items = {'Diffraction Pattern'};
            app.ImageDropDown.ItemsData = {'app.images.pacbed'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.ImageDropDown.Enable = 'off';
            app.ImageDropDown.Value = 'app.images.pacbed';
            app.histogram_plot = uiaxes(app.BrightnessContrastTab);
            app.histogram_plot.XTick = [];
            app.histogram_plot.XTickLabel = {'[ ]'};
            app.histogram_plot.YTick = [];
            app.histogram_plot.YTickLabel = {'[ ]'};
            app.histogram_panel = uipanel(app.BrightnessContrastTab);
            app.BrightnessLabel = uilabel(app.histogram_panel);
            app.BrightnessLabel.Enable = 'off';
            app.BrightnessLabel.Text = 'Brightness';
            app.brightness = uislider(app.histogram_panel);
            app.brightness.Limits = [-0.49999 0.49999];
            app.brightness.MajorTicks = [];
            app.brightness.MajorTickLabels = {};
            app.brightness.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.brightness.ValueChangingFcn = createCallbackFcn(app, @imageControls, true);
            app.brightness.MinorTicks = [];
            app.brightness.Enable = 'off';
            app.ContrastLabel = uilabel(app.histogram_panel);
            app.ContrastLabel.Enable = 'off';
            app.ContrastLabel.Text = 'Contrast';
            app.contrast = uislider(app.histogram_panel);
            app.contrast.Limits = [-0.49999 0.49999];
            app.contrast.MajorTicks = [];
            app.contrast.MajorTickLabels = {};
            app.contrast.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.contrast.ValueChangingFcn = createCallbackFcn(app, @imageControls, true);
            app.contrast.MinorTicks = [];
            app.contrast.Enable = 'off';
            app.GammaLabel = uilabel(app.histogram_panel);
            app.GammaLabel.Enable = 'off';
            app.GammaLabel.Text = 'Gamma';
            app.gamma = uislider(app.histogram_panel);
            app.gamma.Limits = [0 5];
            app.gamma.MajorTicks = [];
            app.gamma.MajorTickLabels = {};
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.gamma.ValueChangingFcn = createCallbackFcn(app, @imageControls, true);
            app.gamma.MinorTicks = [];
            app.gamma.Enable = 'off';
            app.gamma.Value = 1;
            app.brightness_reset = uibutton(app.histogram_panel, 'push');
            app.brightness_reset.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.brightness_reset.Enable = 'off';
            app.brightness_reset.Text = '50%';
            app.contrast_reset = uibutton(app.histogram_panel, 'push');
            app.contrast_reset.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.contrast_reset.Enable = 'off';
            app.contrast_reset.Text = '50%';
            app.gamma_reset = uibutton(app.histogram_panel, 'push');
            app.gamma_reset.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.gamma_reset.Enable = 'off';
            app.gamma_reset.Text = '1';
            app.imStats = uitable(app.BrightnessContrastTab);
            app.imStats.ColumnName = {'Image stats'};
            app.imStats.RowName = {'min'; 'max'; 'mean'; 'std'; 'sum'};
            app.histLog = uibutton(app.BrightnessContrastTab, 'state');
            app.histLog.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.histLog.Text = 'log[ counts ]';
            app.diffractionPanel = uipanel(app.guiLayout);
            app.diffractionPanelGrid = uigridlayout(app.diffractionPanel);
            app.diffPatternGrid = uigridlayout(app.diffractionPanelGrid);
            app.diffPattern = uiaxes(app.diffPatternGrid);
            app.diffPattern.XTick = [];
            app.diffPattern.YTick = [];
            app.diffPattern.YTickLabel = {'[ ]'};
            app.probeX = uispinner(app.diffPatternGrid);
            app.probeX.ValueChangingFcn = createCallbackFcn(app, @patternViewer, true);
            app.probeX.Limits = [1 Inf];
            app.probeX.RoundFractionalValues = 'on';
            app.probeX.ValueDisplayFormat = 'x:%8.0f';
            app.probeX.ValueChangedFcn = createCallbackFcn(app, @patternViewer, true);
            app.probeX.Value = 256;
            app.probeY = uispinner(app.diffPatternGrid);
            app.probeY.ValueChangingFcn = createCallbackFcn(app, @patternViewer, true);
            app.probeY.Limits = [1 Inf];
            app.probeY.RoundFractionalValues = 'on';
            app.probeY.ValueDisplayFormat = 'y:%8.0f';
            app.probeY.ValueChangedFcn = createCallbackFcn(app, @patternViewer, true);
            app.probeY.Value = 256;
            app.stackSlider = uislider(app.diffractionPanelGrid);
            app.stackSlider.MajorTicks = [];
            app.stackSlider.MajorTickLabels = {};
            app.stackSlider.ValueChangedFcn = createCallbackFcn(app, @patternViewer, true);
            app.stackSlider.ValueChangingFcn = createCallbackFcn(app, @patternViewer, true);
            app.stackSlider.MinorTicks = [];
            app.diffControlPanel = uipanel(app.diffractionPanelGrid);
            app.diffControlGrid = uigridlayout(app.diffControlPanel);
            app.dpLog = uibutton(app.diffControlGrid, 'state');
            app.dpLog.ValueChangedFcn = createCallbackFcn(app, @patternViewer, true);
            app.dpLog.Enable = 'off';
            app.dpLog.Text = 'log[ ]';
            app.dpReset = uibutton(app.diffControlGrid, 'push');
            app.dpReset.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.dpReset.Enable = 'off';
            app.dpReset.Text = 'Reset Zoom';
            app.dpPan = uibutton(app.diffControlGrid, 'state');
            app.dpPan.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.dpPan.Enable = 'off';
            app.dpPan.Icon = 'pan.png';
            app.dpPan.Text = '';
            app.dpZoom = uibutton(app.diffControlGrid, 'state');
            app.dpZoom.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.dpZoom.Enable = 'off';
            app.dpZoom.Icon = 'zoom.png';
            app.dpZoom.Text = '';
            app.alignmentPanel = uitabgroup(app.guiLayout);
            app.alignmentTab = uitab(app.alignmentPanel);
            app.alignmentTab.Title = 'Alignment';
            app.alignmentTabGrid = uigridlayout(app.alignmentTab);
            app.scanRotPanelParent = uipanel(app.alignmentTabGrid);
            app.scanRotPanelGrid = uigridlayout(app.scanRotPanelParent);
            app.alignScanRot = uibutton(app.scanRotPanelGrid, 'state');
            app.alignScanRot.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.alignScanRot.Enable = 'off';
            app.alignScanRot.Text = 'Align Scan Direction';
            app.scanRotPanel = uipanel(app.scanRotPanelGrid);
            app.scanRotPanel.Title = 'Scan Rotation';
            app.scanRotMinus = uibutton(app.scanRotPanel, 'push');
            app.scanRotMinus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.scanRotMinus.Icon = 'minus_icon.png';
            app.scanRotMinus.Enable = 'off';
            app.scanRotMinus.Text = '';
            app.scanRotInput = uieditfield(app.scanRotPanel, 'numeric');
            app.scanRotInput.Limits = [-180 180];
            app.scanRotInput.ValueDisplayFormat = '%.2f';
            app.scanRotInput.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.scanRotInput.Enable = 'off';
            app.det_angle_deg_2 = uilabel(app.scanRotPanel);
            app.det_angle_deg_2.Enable = 'off';
            app.det_angle_deg_2.Text = '°';
            app.scanRotPlus = uibutton(app.scanRotPanel, 'push');
            app.scanRotPlus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.scanRotPlus.Icon = 'plus_icon.png';
            app.scanRotPlus.Enable = 'off';
            app.scanRotPlus.Text = '';
            app.scanRotMirror = uicheckbox(app.scanRotPanel);
            app.scanRotMirror.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.scanRotMirror.Enable = 'off';
            app.scanRotMirror.Text = 'Mirror';
            app.ScanRotationLabel = uilabel(app.scanRotPanel);
            app.ScanRotationLabel.Enable = 'off';
            app.ScanRotationLabel.Text = 'Scan Rotation';
            app.scanRot = uislider(app.scanRotPanel);
            app.scanRot.Limits = [-3.14159 3.14159];
            app.scanRot.MajorTicks = [];
            app.scanRot.MajorTickLabels = {};
            app.scanRot.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.scanRot.ValueChangingFcn = createCallbackFcn(app, @alignment, true);
            app.scanRot.MinorTicks = [];
            app.scanRot.Enable = 'off';
            app.trans_panel = uipanel(app.alignmentTabGrid);
            app.trans_beam_x_label = uilabel(app.trans_panel);
            app.trans_beam_x_label.Enable = 'off';
            app.trans_beam_x_label.Text = 'Transmitted Beam X';
            app.trans_beam_x = uislider(app.trans_panel);
            app.trans_beam_x.MajorTicks = [];
            app.trans_beam_x.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_x.ValueChangingFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_x.MinorTicks = [];
            app.trans_beam_x.Enable = 'off';
            app.trans_beam_y_label = uilabel(app.trans_panel);
            app.trans_beam_y_label.Enable = 'off';
            app.trans_beam_y_label.Text = 'Transmitted Beam Y';
            app.trans_beam_y = uislider(app.trans_panel);
            app.trans_beam_y.MajorTicks = [];
            app.trans_beam_y.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_y.ValueChangingFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_y.MinorTicks = [];
            app.trans_beam_y.Enable = 'off';
            app.trans_beam_radius_label = uilabel(app.trans_panel);
            app.trans_beam_radius_label.Enable = 'off';
            app.trans_beam_radius_label.Text = 'Transmitted Beam Radius';
            app.trans_beam_radius = uislider(app.trans_panel);
            app.trans_beam_radius.MajorTicks = [];
            app.trans_beam_radius.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_radius.ValueChangingFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_radius.MinorTicks = [];
            app.trans_beam_radius.Enable = 'off';
            app.conv_angle_label = uilabel(app.trans_panel);
            app.conv_angle_label.Enable = 'off';
            app.conv_angle_label.Text = 'Convergence Angle (mrad)';
            app.alpha = uieditfield(app.trans_panel, 'numeric');
            app.alpha.ValueDisplayFormat = '%.1f';
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.alpha.Enable = 'off';
            app.alpha.Value = 12;
            app.trans_beam_x_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_x_plus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_x_plus.Icon = 'right_arrow.jpg';
            app.trans_beam_x_plus.Enable = 'off';
            app.trans_beam_x_plus.Text = '';
            app.trans_beam_x_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_x_minus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_x_minus.Icon = 'left_arrow.png';
            app.trans_beam_x_minus.Enable = 'off';
            app.trans_beam_x_minus.Text = '';
            app.trans_beam_y_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_y_plus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_y_plus.Icon = 'up_arrow.png';
            app.trans_beam_y_plus.Enable = 'off';
            app.trans_beam_y_plus.Text = '';
            app.trans_beam_y_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_y_minus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_y_minus.Icon = 'down_arrow.png';
            app.trans_beam_y_minus.Enable = 'off';
            app.trans_beam_y_minus.Text = '';
            app.trans_beam_rad_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_rad_plus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_rad_plus.Icon = 'plus_icon.png';
            app.trans_beam_rad_plus.Enable = 'off';
            app.trans_beam_rad_plus.Text = '';
            app.trans_beam_rad_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_rad_minus.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.trans_beam_rad_minus.Icon = 'minus_icon.png';
            app.trans_beam_rad_minus.Enable = 'off';
            app.trans_beam_rad_minus.Text = '';
            app.align_trans_beam = uibutton(app.trans_panel, 'state');
            app.align_trans_beam.ValueChangedFcn = createCallbackFcn(app, @alignment, true);
            app.align_trans_beam.Enable = 'off';
            app.align_trans_beam.Text = 'Align Transmitted Beam';
            app.AutoAlignButton = uibutton(app.trans_panel, 'push');
            app.AutoAlignButton.ButtonPushedFcn = createCallbackFcn(app, @alignment, true);
            app.AutoAlignButton.Visible = 'off';
            app.AutoAlignButton.Text = 'Auto Align';
            app.ManualCalButton = uibutton(app.trans_panel, 'push');
            app.ManualCalButton.Enable = 'off';
            app.ManualCalButton.Visible = 'off';
            app.ManualCalButton.Text = 'Manual Calibration';
            app.CalibrationTab = uitab(app.alignmentPanel);
            app.CalibrationTab.Title = 'Calibration';
            app.calibrationTabGrid = uigridlayout(app.CalibrationTab);
            app.DiffractionCalibration = uibuttongroup(app.calibrationTabGrid);
            app.DiffractionCalibration.SelectionChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.DiffractionCalibration.Title = 'Diffraction Calibration';
            app.mradButton = uitogglebutton(app.DiffractionCalibration);
            app.mradButton.Text = 'mrad';
            app.mradButton.Value = true;
            app.invAButton = uitogglebutton(app.DiffractionCalibration);
            app.invAButton.Text = 'invA';
            app.pixelsmradEditFieldLabel = uilabel(app.DiffractionCalibration);
            app.pixelsmradEditFieldLabel.Text = 'pixels / mrad';
            app.transCal = uieditfield(app.DiffractionCalibration, 'numeric');
            app.transCal.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.transCal.Editable = 'off';
            app.keVLabel = uilabel(app.DiffractionCalibration);
            app.keVLabel.Text = 'keV';
            app.keV = uieditfield(app.DiffractionCalibration, 'numeric');
            app.keV.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.keV.Editable = 'off';
            app.keV.Value = 300;
            app.TransmittedDiskPosition = uipanel(app.calibrationTabGrid);
            app.TransmittedDiskPosition.Title = 'Transmitted Disk Position';
            app.XEditFieldLabel = uilabel(app.TransmittedDiskPosition);
            app.XEditFieldLabel.Text = 'X';
            app.centerX = uieditfield(app.TransmittedDiskPosition, 'numeric');
            app.centerX.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.centerX.Editable = 'off';
            app.centerX.Value = 64;
            app.centerX_label = uilabel(app.TransmittedDiskPosition);
            app.centerX_label.Text = 'pixels';
            app.YEditFieldLabel = uilabel(app.TransmittedDiskPosition);
            app.YEditFieldLabel.Text = 'Y';
            app.centerY = uieditfield(app.TransmittedDiskPosition, 'numeric');
            app.centerY.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.centerY.Editable = 'off';
            app.centerY.Value = 64;
            app.centerY_label = uilabel(app.TransmittedDiskPosition);
            app.centerY_label.Text = 'pixels';
            app.TransmittedDiskRadius = uibuttongroup(app.calibrationTabGrid);
            app.TransmittedDiskRadius.SelectionChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.TransmittedDiskRadius.Title = 'Transmitted Disk Radius';
            app.transRad_pix = uitogglebutton(app.TransmittedDiskRadius);
            app.transRad_pix.Text = 'pixels';
            app.transRad_pix.Value = true;
            app.transRad_mrad = uitogglebutton(app.TransmittedDiskRadius);
            app.transRad_mrad.Text = 'mrad';
            app.transRad = uieditfield(app.TransmittedDiskRadius, 'numeric');
            app.transRad.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.transRad.Editable = 'off';
            app.transRad_invA = uitogglebutton(app.TransmittedDiskRadius);
            app.transRad_invA.Text = 'invA';
            app.EditManualCalButton = uibutton(app.calibrationTabGrid, 'state');
            app.EditManualCalButton.ValueChangedFcn = createCallbackFcn(app, @calibration_TODO, true);
            app.EditManualCalButton.Enable = 'off';
            app.EditManualCalButton.Text = 'Edit';
            app.colorwheelTab = uitab(app.alignmentPanel);
            app.colorwheelTab.Title = 'Colorwheel';
            app.colorwheel_plot = uiaxes(app.colorwheelTab);
            app.colorwheel_plot.XTick = [];
            app.colorwheel_plot.YTick = [];
            app.colorwheel_plot.YTickLabel = {'[ ]'};
            app.colorwheel_plot.ZTick = [];
            app.virtualApertureTab = uitab(app.alignmentPanel);
            app.virtualApertureTab.Title = 'Virtual Aperture';
            app.virtualApertureGrid = uigridlayout(app.virtualApertureTab);
            app.SymmetryoperationsPanel = uipanel(app.virtualApertureGrid);
            app.SymmetryoperationsPanel.Title = 'Symmetry operations:';
            app.mirrorPanel = uipanel(app.SymmetryoperationsPanel);
            app.ap_mirror_minus = uibutton(app.mirrorPanel, 'push');
            app.ap_mirror_minus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_minus.Icon = 'left_rotate.png';
            app.ap_mirror_minus.Enable = 'off';
            app.ap_mirror_minus.Text = '';
            app.MirrorPlaneRotationLabel = uilabel(app.mirrorPanel);
            app.MirrorPlaneRotationLabel.Enable = 'off';
            app.MirrorPlaneRotationLabel.Text = 'Mirror Plane Rotation';
            app.ap_mirror_angle = uislider(app.mirrorPanel);
            app.ap_mirror_angle.Limits = [-3.14159265358979 3.14159265358979];
            app.ap_mirror_angle.MajorTicks = [];
            app.ap_mirror_angle.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_angle.ValueChangingFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_angle.MinorTicks = [];
            app.ap_mirror_angle.Enable = 'off';
            app.aperture_y_Label_2 = uilabel(app.mirrorPanel);
            app.aperture_y_Label_2.Enable = 'off';
            app.aperture_y_Label_2.Text = '°';
            app.ap_mirror_input = uieditfield(app.mirrorPanel, 'numeric');
            app.ap_mirror_input.ValueDisplayFormat = '%.1f';
            app.ap_mirror_input.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_input.Enable = 'off';
            app.ap_mirror_plus = uibutton(app.mirrorPanel, 'push');
            app.ap_mirror_plus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_plus.Icon = 'right_rotate.png';
            app.ap_mirror_plus.Enable = 'off';
            app.ap_mirror_plus.Text = '';
            app.ap_mirror_reset = uibutton(app.mirrorPanel, 'push');
            app.ap_mirror_reset.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror_reset.Enable = 'off';
            app.ap_mirror_reset.Text = '0';
            app.ap_mirror = uicheckbox(app.SymmetryoperationsPanel);
            app.ap_mirror.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_mirror.Enable = 'off';
            app.ap_mirror.Text = 'Mirror plane';
            app.ApertureSymmetrySpinnerLabel = uilabel(app.SymmetryoperationsPanel);
            app.ApertureSymmetrySpinnerLabel.Enable = 'off';
            app.ApertureSymmetrySpinnerLabel.Text = 'Rotational Symmetry';
            app.aperture_symmetry = uispinner(app.SymmetryoperationsPanel);
            app.aperture_symmetry.Limits = [1 6];
            app.aperture_symmetry.RoundFractionalValues = 'on';
            app.aperture_symmetry.ValueDisplayFormat = '%.0f';
            app.aperture_symmetry.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_symmetry.Enable = 'off';
            app.aperture_symmetry.Value = 1;
            app.ap_negative = uicheckbox(app.SymmetryoperationsPanel);
            app.ap_negative.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_negative.Text = 'Negative mask';
            app.ap_lattice = uicheckbox(app.SymmetryoperationsPanel);
            app.ap_lattice.Enable = 'off';
            app.ap_lattice.Text = 'Aperture lattice';
            app.virtualAperturePanelParent = uipanel(app.virtualApertureGrid);
            app.CoordinatesButtonGroup = uibuttongroup(app.virtualAperturePanelParent);
            app.CoordinatesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.CoordinatesButtonGroup.Title = 'Coordinates:';
            app.CartesianButton = uiradiobutton(app.CoordinatesButtonGroup);
            app.CartesianButton.Enable = 'off';
            app.CartesianButton.Text = 'Cartesian';
            app.PolarButton = uiradiobutton(app.CoordinatesButtonGroup);
            app.PolarButton.Enable = 'off';
            app.PolarButton.Text = 'Polar';
            app.PolarButton.Value = true;
            app.virtualAperturePanel = uipanel(app.virtualAperturePanelParent);
            app.DistanceLabel = uilabel(app.virtualAperturePanel);
            app.DistanceLabel.Enable = 'off';
            app.DistanceLabel.Text = 'Distance';
            app.aperture_x = uislider(app.virtualAperturePanel);
            app.aperture_x.MajorTicks = [];
            app.aperture_x.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_x.ValueChangingFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_x.MinorTicks = [];
            app.aperture_x.Enable = 'off';
            app.RotationLabel = uilabel(app.virtualAperturePanel);
            app.RotationLabel.Enable = 'off';
            app.RotationLabel.Text = 'Rotation';
            app.aperture_y = uislider(app.virtualAperturePanel);
            app.aperture_y.Limits = [-100 100];
            app.aperture_y.MajorTicks = [];
            app.aperture_y.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_y.ValueChangingFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_y.MinorTicks = [];
            app.aperture_y.Enable = 'off';
            app.ap_x_plus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_x_plus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_x_plus.Icon = 'plus_icon.png';
            app.ap_x_plus.Enable = 'off';
            app.ap_x_plus.Text = '';
            app.ap_x_minus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_x_minus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_x_minus.Icon = 'minus_icon.png';
            app.ap_x_minus.Enable = 'off';
            app.ap_x_minus.Text = '';
            app.ap_y_plus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_y_plus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_y_plus.Icon = 'plus_icon.png';
            app.ap_y_plus.Enable = 'off';
            app.ap_y_plus.Text = '';
            app.ap_y_minus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_y_minus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_y_minus.Icon = 'minus_icon.png';
            app.ap_y_minus.Enable = 'off';
            app.ap_y_minus.Text = '';
            app.ap_x_reset = uibutton(app.virtualAperturePanel, 'push');
            app.ap_x_reset.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_x_reset.Enable = 'off';
            app.ap_x_reset.Text = '0';
            app.ap_y_reset = uibutton(app.virtualAperturePanel, 'push');
            app.ap_y_reset.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_y_reset.Enable = 'off';
            app.ap_y_reset.Text = '0';
            app.adf_inner_mrad_2 = uilabel(app.virtualAperturePanel);
            app.adf_inner_mrad_2.Enable = 'off';
            app.adf_inner_mrad_2.Text = 'mrad';
            app.aperture_x_input = uieditfield(app.virtualAperturePanel, 'numeric');
            app.aperture_x_input.ValueDisplayFormat = '%.2f';
            app.aperture_x_input.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_x_input.Enable = 'off';
            app.aperture_y_Label = uilabel(app.virtualAperturePanel);
            app.aperture_y_Label.Enable = 'off';
            app.aperture_y_Label.Text = '°';
            app.aperture_y_input = uieditfield(app.virtualAperturePanel, 'numeric');
            app.aperture_y_input.ValueDisplayFormat = '%.2f';
            app.aperture_y_input.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_y_input.Enable = 'off';
            app.ap_size_plus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_size_plus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_size_plus.Icon = 'plus_icon.png';
            app.ap_size_plus.Enable = 'off';
            app.ap_size_plus.Text = '';
            app.ap_size_minus = uibutton(app.virtualAperturePanel, 'push');
            app.ap_size_minus.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_size_minus.Icon = 'minus_icon.png';
            app.ap_size_minus.Enable = 'off';
            app.ap_size_minus.Text = '';
            app.SizeLabel = uilabel(app.virtualAperturePanel);
            app.SizeLabel.Enable = 'off';
            app.SizeLabel.Text = 'Size';
            app.aperture_size = uislider(app.virtualAperturePanel);
            app.aperture_size.MajorTicks = [];
            app.aperture_size.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_size.ValueChangingFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_size.MinorTicks = [];
            app.aperture_size.Enable = 'off';
            app.adf_inner_mrad_4 = uilabel(app.virtualAperturePanel);
            app.adf_inner_mrad_4.Enable = 'off';
            app.adf_inner_mrad_4.Text = 'mrad';
            app.aperture_size_input = uieditfield(app.virtualAperturePanel, 'numeric');
            app.aperture_size_input.ValueDisplayFormat = '%.2f';
            app.aperture_size_input.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.aperture_size_input.Enable = 'off';
            app.ap_rotAngle = uidropdown(app.virtualAperturePanel);
            app.ap_rotAngle.Items = {'90°', '60°', '45°', '30°', '22.5°', '10°', '5°', '2°', '1°', '0.5°'};
            app.ap_rotAngle.ItemsData = {'90', '60', '45', '30', '22.5', '10', '5', '2', '1', '0.5'};
            app.ap_rotAngle.ValueChangedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_rotAngle.Enable = 'off';
            app.ap_rotAngle.Value = '90';
            app.ap_left_rot = uibutton(app.virtualAperturePanel, 'push');
            app.ap_left_rot.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_left_rot.Icon = 'left_rotate.png';
            app.ap_left_rot.Enable = 'off';
            app.ap_left_rot.Text = '';
            app.ap_right_rot = uibutton(app.virtualAperturePanel, 'push');
            app.ap_right_rot.ButtonPushedFcn = createCallbackFcn(app, @virtualAperture, true);
            app.ap_right_rot.Icon = 'right_rotate.png';
            app.ap_right_rot.Enable = 'off';
            app.ap_right_rot.Text = '';
            app.outputPanel = uitabgroup(app.guiLayout);
            app.bfdfTab = uitab(app.outputPanel);
            app.bfdfTab.Title = 'BF/DF';
            app.bfdfGrid = uigridlayout(app.bfdfTab);
            app.bfdf_image = uiaxes(app.bfdfGrid);
            app.bfdf_image.XTick = [];
            app.bfdf_image.XTickLabel = {'[ ]'};
            app.bfdf_image.YTick = [];
            app.bfdf_image.Visible = 'off';
            app.comTab = uitab(app.outputPanel);
            app.comTab.Title = 'Center of Mass';
            app.comGrid = uigridlayout(app.comTab);
            app.comx = uiaxes(app.comGrid);
            title(app.comx, '')
            xlabel(app.comx, '')
            ylabel(app.comx, '')
            app.comx.XTick = [];
            app.comx.YTick = [];
            app.comx.ZTick = [];
            app.comMag = uiaxes(app.comGrid);
            title(app.comMag, '')
            xlabel(app.comMag, '')
            ylabel(app.comMag, '')
            app.comMag.XTick = [];
            app.comMag.YTick = [];
            app.comMag.ZTick = [];
            app.comy = uiaxes(app.comGrid);
            title(app.comy, '')
            xlabel(app.comy, '')
            ylabel(app.comy, '')
            app.comy.XTick = [];
            app.comy.YTick = [];
            app.comy.ZTick = [];
            app.comPhase = uiaxes(app.comGrid);
            title(app.comPhase, '')
            xlabel(app.comPhase, '')
            ylabel(app.comPhase, '')
            app.comPhase.XTick = [];
            app.comPhase.YTick = [];
            app.comPhase.ZTick = [];
            app.comxPanel = uipanel(app.comGrid);
            app.comxGrid = uigridlayout(app.comxPanel);
            app.comxButtonGroup = uibuttongroup(app.comxGrid);
            app.comxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.comx_ADF = uitogglebutton(app.comxButtonGroup);
            app.comx_ADF.Text = 'ADF';
            app.comx_comx = uitogglebutton(app.comxButtonGroup);
            app.comx_comx.Text = 'CoMx';
            app.comx_comx.Value = true;
            app.comx_comMag = uitogglebutton(app.comxButtonGroup);
            app.comx_comMag.Text = '| CoM |';
            app.comMagPanel = uipanel(app.comGrid);
            app.comMagGrid = uigridlayout(app.comMagPanel);
            app.comMagButtonGroup = uibuttongroup(app.comMagGrid);
            app.comMagButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.comMag_dcom = uitogglebutton(app.comMagButtonGroup);
            app.comMag_dcom.Text = 'dCoM';
            app.comMag_comMag = uitogglebutton(app.comMagButtonGroup);
            app.comMag_comMag.Text = '| CoM |';
            app.comMag_comMag.Value = true;
            app.comMag_icom = uitogglebutton(app.comMagButtonGroup);
            app.comMag_icom.Text = 'iCoM';
            app.comyPanel = uipanel(app.comGrid);
            app.comyGrid = uigridlayout(app.comyPanel);
            app.comyButtonGroup = uibuttongroup(app.comyGrid);
            app.comyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.comy_ADF = uitogglebutton(app.comyButtonGroup);
            app.comy_ADF.Text = 'ADF';
            app.comy_comy = uitogglebutton(app.comyButtonGroup);
            app.comy_comy.Text = 'CoMy';
            app.comy_comy.Value = true;
            app.comy_comPhase = uitogglebutton(app.comyButtonGroup);
            app.comy_comPhase.Text = 'CoM Phase';
            app.comAnglePanel = uipanel(app.comGrid);
            app.comAngleGrid = uigridlayout(app.comAnglePanel);
            app.comAngleButtonGroup = uibuttongroup(app.comAngleGrid);
            app.comAngleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.comAngle_dcom = uitogglebutton(app.comAngleButtonGroup);
            app.comAngle_dcom.Text = 'dCoM';
            app.comAngle_com = uitogglebutton(app.comAngleButtonGroup);
            app.comAngle_com.Text = 'CoM';
            app.comAngle_com.Value = true;
            app.comAngle_icom = uitogglebutton(app.comAngleButtonGroup);
            app.comAngle_icom.Text = 'iCoM';
            app.vectorFieldTab = uitab(app.outputPanel);
            app.vectorFieldTab.Title = 'Vector FIeld';
            app.vectorFieldGrid = uigridlayout(app.vectorFieldTab);
            app.vectorField = uiaxes(app.vectorFieldGrid);
            app.vectorField.XTick = [];
            app.vectorField.XTickLabel = {'[ ]'};
            app.vectorField.YTick = [];
            app.vectorField.Visible = 'off';
            app.vectorFieldPanel = uipanel(app.vectorFieldGrid);
            app.vectorFieldPanelGrid = uigridlayout(app.vectorFieldPanel);
            app.vectZoom = uibutton(app.vectorFieldPanelGrid, 'state');
            app.vectZoom.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.vectZoom.Icon = 'zoom.png';
            app.vectZoom.Text = '';
            app.vectPan = uibutton(app.vectorFieldPanelGrid, 'state');
            app.vectPan.ValueChangedFcn = createCallbackFcn(app, @imageControls, true);
            app.vectPan.Icon = 'pan.png';
            app.vectPan.Text = '';
            app.vectReset = uibutton(app.vectorFieldPanelGrid, 'push');
            app.vectReset.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.vectReset.Text = 'Reset Zoom';
            app.saveVecField = uibutton(app.vectorFieldPanelGrid, 'push');
            app.saveVecField.ButtonPushedFcn = createCallbackFcn(app, @imageControls, true);
            app.saveVecField.Text = 'Save';
            app.vectSubsample = uidropdown(app.vectorFieldPanelGrid);
            app.vectSubsample.Items = {'Subsample', '1:1', '1:2', '1:4', '1:8', '1:16'};
            app.vectSubsample.ValueChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.vectSubsample.Value = 'Subsample';
            app.vectButtonGroup = uibuttongroup(app.vectorFieldPanelGrid);
            app.vectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @firstMoment, true);
            app.VectorButton = uiradiobutton(app.vectButtonGroup);
            app.VectorButton.Text = 'Vector';
            app.VectorButton.Value = true;
            app.MagnitudeButton = uiradiobutton(app.vectButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.PhaseButton = uiradiobutton(app.vectButtonGroup);
            app.PhaseButton.Text = 'Phase';
            % Show the figure after all components are created
            app.Quant4D_Fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quant4D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Quant4D_Fig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Quant4D_Fig)
        end
    end
end