classdef import4Dstem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        import_4d              matlab.ui.Figure
        import4dGrid           matlab.ui.container.GridLayout
        framesLabel            matlab.ui.control.Label
        nopix                  matlab.ui.control.NumericEditField
        nopiy                  matlab.ui.control.NumericEditField
        probePositionsLabel    matlab.ui.control.Label
        framesx                matlab.ui.control.NumericEditField
        framesy                matlab.ui.control.NumericEditField
        headerLabel            matlab.ui.control.Label
        header                 matlab.ui.control.NumericEditField
        gapLlabel              matlab.ui.control.Label
        footer                 matlab.ui.control.NumericEditField
        bitdepthDropdown       matlab.ui.container.Panel
        bitdepth               matlab.ui.control.DropDown
        convertFloat           matlab.ui.control.CheckBox
        endiannessPanel        matlab.ui.container.Panel
        endiannessGrid         matlab.ui.container.GridLayout
        endiannessButtonGroup  matlab.ui.container.ButtonGroup
        bigEndian              matlab.ui.control.RadioButton
        littleEndian           matlab.ui.control.RadioButton
        estFilesizeLabel       matlab.ui.control.Label
        estFilesize            matlab.ui.control.Label
        actualFilesizeLabel    matlab.ui.control.Label
        actualFilesize         matlab.ui.control.Label
        memoryNotes            matlab.ui.control.TextArea
        gpuPanel               matlab.ui.container.Panel
        gpuPanelGrid           matlab.ui.container.GridLayout
        gpu                    matlab.ui.control.DropDown
        importData             matlab.ui.control.Button
        cancel                 matlab.ui.control.Button
        detectorTypeGroup      matlab.ui.container.ButtonGroup
        EMPADButton            matlab.ui.control.ToggleButton
        MedipixButton          matlab.ui.control.ToggleButton
        SimulatedButton        matlab.ui.control.ToggleButton
        OtherButton            matlab.ui.control.ToggleButton
        MRCButton              matlab.ui.control.ToggleButton
        detectorHelp           matlab.ui.control.Image
        DM4Button              matlab.ui.control.ToggleButton
        memoryTypeGroup        matlab.ui.container.ButtonGroup
        PhysicalMemoryButton   matlab.ui.control.ToggleButton
        VirtualMemoryButton    matlab.ui.control.ToggleButton
        memHelp                matlab.ui.control.Image
        notesPanel             matlab.ui.container.Panel
        notesPanelGrid         matlab.ui.container.GridLayout
        notes                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        bitsize
        type
        ordering
        filesize
        s
        current_bitdepth
        CallingApp
        bitdepth_options
        mem
        params
        device
    end
    
    methods (Access = public)
        
        function checkMem(app)
            % compare 
            if app.s.bytes>app.mem.max && app.filesize>app.mem.max
                app.actualFilesize.FontColor='r';
            elseif app.s.bytes>app.mem.max && app.filesize<app.mem.max
                app.actualFilesize.FontColor='r';
            elseif app.filesize>app.mem.max
                app.estFilesize.FontColor='r';
            else
                app.actualFilesize.FontColor='k';
            end
            
            % Display estimated size in green if input parameters match actual file size
            if app.filesize==app.s.bytes
                app.estFilesize.FontColor=[0 0.5 0];
            else
                app.estFilesize.FontColor='r';
            end
        end
    
        function getGPU(app)
            % Check for parallel computing toolbox install for GPU
            % acceleration
            test = ver;
            if any(strcmpi('Parallel Computing Toolbox',{test.Name}))
                app.params.ngpu = gpuDeviceCount;
            else
                app.params.ngpu = 0;
            end
            
            % Check for the presence of a GPU device and turn on/off GPU support
            switch app.params.ngpu
                case 0 % no GPU devices
                    app.device = 0;
                    app.gpu.Visible = 'off';
                    app.memoryNotes.Value='';
                otherwise % 1 or more devices found
                    app.gpu.Visible = 'on';
                    app.memoryNotes.Value = '';
                    for ii=1:app.params.ngpu
                        app.mem.gpu(ii) = gpuDevice(ii).TotalMemory;
                        app.gpu.Items(ii)={sprintf('GPU %d',ii)};
                        app.memoryNotes.Value = sprintf('%sGPU %d: %0.2f GB\n',app.memoryNotes.Value{:},ii,app.mem.gpu(ii)/1e9);
                    end
                    app.device=gpuDevice(1); % select first GPU by default
                    app.gpu.Items(end+1)={'GPU Off'};
                    app.gpu.Value = app.gpu.Items(1);
            end
        end
        
        function getMem(app)
            % Check usable system memory and make sure the file will fit
            if ispc
                [~,tmp]=memory;
                app.mem.max  = tmp.PhysicalMemory.Total;
                app.mem.free = tmp.PhysicalMemory.Available;
            elseif ismac
                [~,app.mem.max]=system('sysctl -n hw.memsize');app.mem.max=str2double(app.mem.max);
                [~,app.mem.page_size] = system("vm_stat | grep 'page size' | awk '{print $8}'");app.mem.page_size = str2double(app.mem.page_size);
                [~,app.mem.wired] = system("vm_stat | grep 'wired' | awk '{print $4}'");app.mem.wired = str2double(app.mem.wired)*app.mem.page_size;
                app.mem.free = app.mem.max - app.mem.wired;
            elseif isunix
                [~,app.mem.max]=system("cat /proc/meminfo | grep MemTotal: | awk '{print $2}'");
                app.mem.max=str2double(app.mem.max)*1000;% memory output is in kB
                [~,app.mem.free]=system("cat /proc/meminfo | grep MemAvailable: | awk '{print $2}'");
                app.mem.free=str2double(app.mem.free)*1000;% memory output is in kB
            end
        end
        
        function checkGPU(app)
            % Estimate the amount of memory needed to hold the largest
            % arrays in memory
            max_dist = floor(sqrt(sum(([app.nopix.Value app.nopiy.Value]/2).^2))); % estimate of the distance to the far corner of the diffraciton pattern
            
            app.mem.total_mem_est = app.framesx.Value*app.framesy.Value*app.nopix.Value*app.nopiy.Value + ...           % app.data
                                    app.nopix.Value*app.nopiy.Value*size(0:0.5:max_dist,2) + ...                        % app.adf.images
                                    app.framesx.Value*app.framesy.Value*10 + app.nopix.Value*app.nopiy.Value*2 + ...    % app.images
                                    app.nopix.Value*app.nopiy.Value*(size(0:0.5:max_dist,2)+10);                        % app.mask
            
            if app.convertFloat.Value % if converting from int=>float
                if app.current_bitdepth>4 % double precision float
                    app.mem.total_est = app.mem.total_mem_est*8;
                else % single precision float
                    app.mem.total_est = app.mem.total_mem_est*4;
                end
            else % floating point input, or dont't convert int=>float
                app.mem.total_est = app.mem.total_mem_est*app.current_bitdepth;
            end
            
            if strcmp(app.gpu.Value,'GPU Off')
                app.memoryNotes.Value = 'GPU acceleration disabled.';
            elseif app.mem.total_est < app.mem.gpu(strcmp(app.gpu.Value,app.gpu.Items(1:end-1))) && app.nopix.Value*app.nopiy.Value*app.framesx.Value*app.framesy.Value <= intmax('int32')
                app.memoryNotes.Value = strcat(gpuDevice(strmatch(app.gpu.Value,app.gpu.Items(1:end-1))).Name,' (',num2str(gpuDevice(strmatch(app.gpu.Value,app.gpu.Items(1:end-1))).TotalMemory/1e9),' GB) has enough memory for the selected dataset and will be used unless you disable it.');
                app.gpu.Enable = 'on';
            elseif app.mem.total_est < app.mem.gpu(strcmp(app.gpu.Value,app.gpu.Items(1:end-1))) && app.nopix.Value*app.nopiy.Value*app.framesx.Value*app.framesy.Value > intmax('int32')
                app.memoryNotes.Value = strcat(gpuDevice(strmatch(app.gpu.Value,app.gpu.Items(1:end-1))).Name,' (',num2str(gpuDevice(strmatch(app.gpu.Value,app.gpu.Items(1:end-1))).TotalMemory/1e9),' GB) disabled. Dataset has more elements than CUDA max (2^31-1).');
                app.gpu.Value = 'GPU Off';
                app.gpu.Enable = 'off';
            else
                app.memoryNotes.Value = strcat(gpuDevice(strfind(app.gpu.Value,app.gpu.Items(1:end-1))).Name,' (',num2str(gpuDevice(strfind(app.gpu.Value,app.gpu.Items(1:end-1))).TotalMemory/1e9),' GB) does not have enough memory for the dataset and will not be utilized.');
                app.gpu.Enable = 'on';
                app.gpu.Value='GPU Off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appMain, filename)
            app.import_4d.Visible='off';
            app.CallingApp=appMain;
            app.import_4d.Name=filename;
            app.s=dir(filename);
            
%             app.bitdepth_options={'1-bit Unsigned','uint8';  % Medipix data are saved as a .mib file of varying bit-depth
%                 '6-bit Unsigned','uint8';                    % as unsigned integers of the next highest class
%                 '8-bit Unsigned','uint8';                    % (i.e. 12-bit is saved as a 16-bit unsigned integer)
%                 '12-bit Unsigned','uint16';                  % with big-endian ordering.
%                 '16-bit Signed','int16';                     %
%                 '16-bit Unsigned','uint16';                  % EMPAD data are saved as a .raw file with 32-bit depth
%                 '24-bit Unsigned','uint32';                  % with little endian ordering.
%                 '32-bit Real','single';                      %
%                 '32-bit Signed','int32';                     % Fiji/ImageJ tend to save their files with big-endian ordering,
%                 '32-bit Unsigned','uint32';                  % though this is also system- and user-dependent.
%                 '64-bit Real','double'};                     %
            app.bitsize={'uint8',1;
                         'int8',1;
                         'uint16',2;
                         'int16',2;
                         'int32',4;
                         'uint32',4;
                         'single',4
                         'double',8};
            
            app.ordering='l';
            
            if contains(filename,'.mib','IgnoreCase',1) % Medipix data
                app.bitdepth.Items={'1-bit';'6-bit';'12-bit';'24-bit'};
                app.bitdepth.ItemsData={'uint8';'uint8';'uint16';'uint32'};
        %%app.bitdepth.Value='12-bit';app.current_bitdepth=2;app.type={'uint16'};
                app.bitdepth.Value='uint16';app.current_bitdepth=2;app.type={'uint16'};
                app.header.Value=384;
                app.footer.Value=0;
         %%app.bitsize=[1;1;2;4];
                app.bigEndian.Value=true;
                app.ordering='b';
                app.nopiy.Value=256;
                app.nopix.Value=256;
                app.MedipixButton.Value=1;
                app.notes.Value = {sprintf('Pixels:\t256x256');sprintf('Header:\t384');sprintf('Footer:\t0');'n-bit Integer';'Big endian'};
            else % other data (EMPAD, MRC, ImageJ, simulation, etc.)
                app.bitdepth.Items={'8-bit Unsigned';
                    '16-bit Signed';
                    '16-bit Unsigned';
                    '32-bit Real';
                    '32-bit Signed';
                    '32-bit Unsigned';
                    '64-bit Real'};
                app.bitdepth.ItemsData={'uint8';
                    'int16';
                    'uint16';
                    'single';
                    'int32';
                    'uint32';
                    'double'};
          %%app.bitsize=[1;2;2;4;4;4;8];
                app.current_bitdepth=app.bitsize{strcmp(app.bitdepth.Value,app.bitsize(:,1)),2};
                if contains(filename,'.mrc','IgnoreCase',1) % mrc data
                    app.notes.Value = {sprintf('Pixels:\t\nHeader:\t1024\nFooter:\t0\nSigned integer\nLittle endian\n\n\n** File includes a single 1024 byte header, no footer')};
                    app.MRCButton.Value=1;
                    
                    % Read in MRC header - first 1024 bytes
                    fid = fopen(filename,'r');                    
                    app.nopix.Value = fread(fid,1,'uint32');      % number of pixels in x
                    app.nopiy.Value = fread(fid,1,'uint32');      %                     y
                    frames = fread(fid,1,'uint32');               % total number of probe positions
                  
                    app.framesx.Value = round(sqrt(frames));
                    app.framesy.Value = round(frames/app.framesx.Value);
                    
                    switch fread(fid,1,'uint32')
                        case 0 % 0: 8-bit signed integer (range -128:127)
                            app.import_4d.Visible='on';
                            uialert(app.import_4d,'Sorry - the data type indicated in the .mrc header is not yet supported.','Invalid data type!')
                            closePushed(app, event); % not yet supported
                        case 1 % 1: 16-bit signed integer
                            app.bitdepth.Value='int16';app.current_bitdepth=2;app.type={'int16'};
                        case 2 % 2: 32-bit signed real
                            app.bitdepth.Value='single';app.current_bitdepth=4;app.type={'single'};
                        case 3 % 3: transform : complex 16-bit integers
                            app.import_4d.Visible='on';
                            uialert(app.import_4d,'Sorry - the data type indicated in the .mrc header is not yet supported.','Invalid data type!')
                            closePushed(app, event); % not yet supported
                        case 4 % 4: transform : complex 32-bit reals
                            app.import_4d.Visible='on';
                            uialert(app.import_4d,'Sorry - the data type indicated in the .mrc header is not yet supported.','Invalid data type!')
                            closePushed(app, event); % not yet supported
                        case 6 % 6: 16-bit unsigned integer
                            app.bitdepth.Value='uint16';app.current_bitdepth=2;app.type={'uint16'};
                    end
                    fclose(fid);
                    
                    app.header.Value=1024;
                    app.footer.Value=0;   
                elseif contains(filename,'.dm4','IgnoreCase',1) % Gatan K2/K3 data
                    app.DM4Button.Value=1;
                    % Read .dm4 tag values, output as a txt file, use as
                    % inputs for import GUI
                    outputData = dm4Reader(filename,1); % from Peter Ercius: https://bitbucket.org/ercius/openncem/src/master/
                    
                    % Find 4D dataset index in dm4 tags
                    if sum(sum(outputData.dataSize~=1)==4) == 1
                        dataIndex = find(sum(outputData.dataSize~=1)==4);
                    else % more than one 4D dataset, select the largest one
                        dataIndex = find(prod(outputData.dataSize)==max(prod(outputData.dataSize)));
                    end
                    dataSize = outputData.dataSize(:,dataIndex);
                    app.nopix.Value   = dataSize(1);
                    app.nopiy.Value   = dataSize(2);
                    app.framesx.Value = dataSize(3);
                    app.framesy.Value = dataSize(4);
                
                    % Find offset to largest dataset
                    app.header.Value = outputData.binaryOffset(:,dataIndex);
                    app.footer.Value = 0;
                    
                    % Find datatype of largest dataset
                    app.type=outputData.dataType(dataIndex);
         %app.bitdepth.Value = intersect(app.bitdepth.Items,app.bitdepth_options(strcmp(app.bitdepth_options(:,2),app.type)));
                    app.bitdepth.Value = app.bitdepth.Items(strcmp(app.type,app.bitdepth.ItemsData));
         %%app.current_bitdepth = app.bitsize(strmatch(app.bitdepth.Value,app.bitdepth.Items));
                    app.current_bitdepth=app.bitsize{strcmp(app.bitdepth.Value,app.bitsize(:,1)),2};
                    
                    % find endianness
                    fid = fopen(filename,'r');
                    fread(fid,3,'uint32',0,'b'); %skip version and filesize
                    switch fread(fid,1,'uint32',0,'b')
                        case 0
                            app.endiannessButtonGroup.SelectedObject = app.bigEndian;
                        case 1
                            app.endiannessButtonGroup.SelectedObject = app.littleEndian;
                    end
                    fclose(fid);
                    
                    app.notes.Value = {sprintf('Pixels:%dx%d\t\nHeader:\t%d\nFooter:\t0\n%s\n%s\n\n\n** Largest dataset was selected at an offset of %d bytes.',app.nopix.Value,app.nopiy.Value,app.header.Value,app.bitdepth.Value,app.endiannessButtonGroup.SelectedObject.Text,app.header.Value)};
                    app.s.bytes = (app.nopix.Value*app.nopiy.Value*app.framesx.Value*app.framesy.Value)*app.current_bitdepth + app.header.Value;
                    clear outputData dataIndex dataSize fid 
                else
                   app.notes.Value = {sprintf('Pixels:\t128x128\nHeader:\t0\nFooter:\t1024\n32-bit Real\nLittle endian')}; 
                end
            end
            
            % Check for dataset size at the end of the filename in the following style:
            %   filename='dataset_arg1xarg2xarg3xarg4.ext'
            %   where:  arg1=nopix
            %           arg2=nopiy
            %           arg3=framesx
            %           arg4=framesy
            if contains(filename,'_') && size(strfind(filename(max(strfind(filename,'_'))+1:end-4),'x'),2)==3
                input_args=strsplit(filename(max(strfind(filename,'_'))+1:end-4),'x');
                app.nopix.Value=str2double(input_args{1});
                app.nopiy.Value=str2double(input_args{2});
                app.framesx.Value=str2double(input_args{3});
                app.framesy.Value=str2double(input_args{4});
            else % Make an initial guess at the file dimensions (assume ~square field of view: framesx≈framesy)
                if rem(sqrt(app.s.bytes/(app.nopix.Value*app.nopiy.Value*app.current_bitdepth+app.header.Value+app.footer.Value)),1)==0
                    app.framesx.Value=sqrt(app.s.bytes/(app.nopix.Value*app.nopiy.Value*app.current_bitdepth+app.header.Value+app.footer.Value));
                    app.framesy.Value=app.framesx.Value;
                elseif strcmp(filename(end-2:end),'mrc') % mrc data
                    app.framesx.Value = round(sqrt((app.s.bytes - 1024)/(app.current_bitdepth*app.nopix.Value*app.nopiy.Value)));
                    app.framesy.Value = app.framesx.Value;
                elseif ~contains(filename,'.dm4','IgnoreCase',1)
                    app.framesx.Value = round(sqrt((app.s.bytes - app.framesx.Value*app.framesy.Value*(app.footer.Value+app.header.Value))/(app.current_bitdepth*app.nopix.Value*app.nopiy.Value)));
                    app.framesy.Value = app.framesx.Value;
                end
            end
            
            % Display actual file size in bytes and GB
            app.actualFilesize.Text=sprintf('%.0f bytes\n%.3f GB',app.s.bytes,app.s.bytes/1e9);
            
            % figure out how much RAM is available
            getMem(app)
            
            % look for gpu(s)
            getGPU(app)
            
            % finish the initialization
            boxInput(app)
            
            movegui(app.import_4d,'center')
            app.import_4d.Visible='on';
            figure(app.import_4d)
        end

        % Callback function: bitdepth, convertFloat, 
        % endiannessButtonGroup, footer, framesx, framesy, gpu, 
        % header, memoryTypeGroup, nopix, nopiy
        function boxInput(app, event)

      %%app.current_bitdepth=app.bitsize(strcmp(app.bitdepth.Value,app.bitdepth.ItemsData));
            app.current_bitdepth=app.bitsize{strcmp(app.bitdepth.Value,app.bitsize(:,1)),2};
      %%app.type=app.bitdepth_options(strcmp(app.bitdepth.Value,app.bitdepth_options),2);
            app.type = {app.bitdepth.Value};
            
            if app.MRCButton.Value || app.DM4Button.Value % MRC and DM4 files have a single offset header and no footer to read
                app.filesize=app.nopiy.Value*app.nopix.Value*app.framesy.Value*app.framesx.Value*app.current_bitdepth+app.header.Value;
            else % it is assumed that other fileypes have a header and footer around each diffraction pattern
               app.filesize=app.nopiy.Value*app.nopix.Value*app.framesy.Value*app.framesx.Value*app.current_bitdepth+...
                           (app.framesx.Value*app.framesy.Value)*(app.footer.Value+app.header.Value);
            end

            app.estFilesize.Text=sprintf('%.0f bytes\n%.3f GB',app.filesize,app.filesize/1e9);
            
            % Allow for virtual memory mapping in some situations
            if (app.header.Value == 0 && app.footer.Value == 0) || any(strcmp(app.detectorTypeGroup.SelectedObject.Text,{'MRC','DM4'}))
                app.VirtualMemoryButton.Enable = 1;
            else
                app.VirtualMemoryButton.Enable = 0;
                app.PhysicalMemoryButton.Value = 1;
            end
            
            % only allow float conversion if the input type is integer and
            % "Virtual Memory" is not selected
            if ~any(strcmp(app.bitdepth.Value,{'single','double'})) && app.PhysicalMemoryButton.Value
                app.convertFloat.Visible=1;
            else
                app.convertFloat.Visible=0;
                app.convertFloat.Value=0;
            end
            
            checkMem(app)
            if app.params.ngpu > 0
                checkGPU(app)
            end
        end

        % Button pushed function: cancel
        function closePushed(app, event)
            app.params.nopix = 0;
            app.params.nopiy = 0;
            app.params.framesx = 0;
            app.params.framesy = 0;
            app.params.header = 0;
            app.params.footer = 0;
            app.params.ordering = {'0'};
            app.params.bitdepth = {'0'};
            app.params.gpu      = 0;
            
            app.mem.type={'0'};app.mem.convertFloat=0;
            
            import4D(app.CallingApp,app.params,app.mem);
            delete(app)
        end

        % Button pushed function: importData
        function importDataPushed(app, event)
            if ~contains(app.CallingApp.params.filename,{'.mrc','.dm4'},'IgnoreCase',1)
                app.footer.Value=app.footer.Value/app.current_bitdepth;
                app.header.Value=app.header.Value/app.current_bitdepth;
            end
            
            if app.littleEndian.Value==1
                app.ordering='l';
            elseif app.bigEndian.Value==1
                app.ordering='b';
            end
            
            switch app.memoryTypeGroup.SelectedObject.Text
                case 'Virtual Memory'
                    app.mem.type='v';
                case 'Physical Memory'
                    app.mem.type='p';
            end
            
            app.mem.convertFloat=app.convertFloat.Value;
            app.params.nopix    = app.nopix.Value;
            app.params.nopiy    = app.nopiy.Value;
            app.params.framesx  = app.framesx.Value;
            app.params.framesy  = app.framesy.Value;
            app.params.header   = app.header.Value;
            app.params.footer   = app.footer.Value;
            app.params.ordering = app.ordering;
            app.params.bitdepth = app.type;
            if strcmp(app.gpu.Value,'GPU Off')
                app.params.gpu  = 0;
            else
                app.params.gpu   = strmatch(app.gpu.Value,app.gpu.Items(1:end-1)); %#ok<*MATCH2> % device number
            end
            
            import4D(app.CallingApp,app.params,app.mem);
            delete(app)
        end

        % Selection changed function: detectorTypeGroup
        function detectorType_callback(app, event)
            app.bitdepth.Items={'8-bit Unsigned';
                                '16-bit Signed';
                                '16-bit Unsigned';
                                '32-bit Real';
                                '32-bit Signed';
                                '32-bit Unsigned';
                                '64-bit Real'};
            app.bitdepth.ItemsData={'uint8';
                                    'int16';
                                    'uint16';
                                    'single';
                                    'int32';
                                    'uint32';
                                    'double'};
            
            switch app.detectorTypeGroup.SelectedObject.Text
                case 'EMPAD'
                    app.bitdepth.Value='single';app.current_bitdepth=4;app.type={'single'};
                    app.header.Value=0;
                    app.footer.Value=1024;
                    app.littleEndian.Value=true;
                    app.notes.Value = {sprintf('Pixels:\t128x128');sprintf('Header:\t0');sprintf('Footer:\t1024');'32-bit Real';'Little endian'};
                case 'Medipix'
                    app.bitdepth.Items={'1-bit';
                                        '6-bit';
                                        '12-bit';
                                        '24-bit'};
                    app.bitdepth.ItemsData={'uint8';
                                            'uint8';
                                            'uint16';
                                            'uint32'};
                    app.bitdepth.Value='uint16';app.current_bitdepth=2;app.type={'uint16'};
                    app.header.Value=384;
                    app.footer.Value=0;
                    app.bigEndian.Value=true;
                    app.notes.Value = {sprintf('Pixels:\t256x256');sprintf('Header:\t384');sprintf('Footer:\t0');'Unsigned integer';'Big endian'};
                case 'MRC'
                    app.bitdepth.Value='int16';app.current_bitdepth=2;app.type={'int16'};
                    app.header.Value=1024;
                    app.footer.Value=0;
                    app.littleEndian.Value=true;
                    app.notes.Value = {sprintf('Pixels:\t');sprintf('Header:\t1024');sprintf('Footer:\t0');'Signed integer';'Little endian';'';'';'** File includes a single 1024 byte header, no footer'};
                case 'DM4'
                    app.bitdepth.Value='uint32';app.current_bitdepth=4;app.type={'uint32'};
                    app.header.Value=0;
                    app.footer.Value=0;
                    app.littleEndian.Value=true;
                    app.notes.Value = {sprintf('Pixels:\t\nHeader:\t\nFooter:\t\n\n\n\n\n** Largest dataset selected at an offset of ?? bytes.')};
                otherwise
                    app.bitdepth.Value='single';app.current_bitdepth=4;app.type={'single'};
                    app.header.Value=0;
                    app.footer.Value=0;
                    app.littleEndian.Value=true;
                    app.notes.Value = {sprintf('Pixels:\t');sprintf('Header:\t');sprintf('Footer:\t');'';''};
            end
            
        %%app.current_bitdepth=app.bitsize(strcmp(app.bitdepth.Value,app.bitdepth.Items));
            app.current_bitdepth=app.bitsize{strcmp(app.bitdepth.Value,app.bitsize(:,1)),2};
            
            boxInput(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.import_4d = uifigure('Visible', 'off');
            app.import_4d.Name = 'Import Data';
            app.import4dGrid = uigridlayout(app.import_4d);
            app.framesLabel = uilabel(app.import4dGrid);
            app.framesLabel.Text = 'Pixels in the diffraction pattern:';
            app.nopix = uieditfield(app.import4dGrid, 'numeric');
            app.nopix.Limits = [0 Inf];
            app.nopix.RoundFractionalValues = 'on';
            app.nopix.ValueDisplayFormat = 'x:\t%6.0f';
            app.nopix.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.nopix.Value = 128;
            app.nopiy = uieditfield(app.import4dGrid, 'numeric');
            app.nopiy.Limits = [0 Inf];
            app.nopiy.RoundFractionalValues = 'on';
            app.nopiy.ValueDisplayFormat = 'y:\t%6.0f';
            app.nopiy.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.nopiy.Value = 128;
            app.probePositionsLabel = uilabel(app.import4dGrid);
            app.probePositionsLabel.Text = 'Number of probe positions:';
            app.framesx = uieditfield(app.import4dGrid, 'numeric');
            app.framesx.Limits = [0 Inf];
            app.framesx.RoundFractionalValues = 'on';
            app.framesx.ValueDisplayFormat = 'x:\t%6.0f bytes';
            app.framesx.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.framesx.Value = 128;
            app.framesy = uieditfield(app.import4dGrid, 'numeric');
            app.framesy.Limits = [0 Inf];
            app.framesy.RoundFractionalValues = 'on';
            app.framesy.ValueDisplayFormat = 'y:\t%6.0f bytes';
            app.framesy.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.framesy.Value = 128;
            app.headerLabel = uilabel(app.import4dGrid);
            app.headerLabel.Text = 'Header:';
            app.header = uieditfield(app.import4dGrid, 'numeric');
            app.header.Limits = [0 Inf];
            app.header.RoundFractionalValues = 'on';
            app.header.ValueDisplayFormat = '%.0f bytes';
            app.header.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.header.Tooltip = {'FOOTER is the number of bytes after each diffraction pattern.'};
            app.gapLlabel = uilabel(app.import4dGrid);
            app.gapLlabel.Text = 'Footer:';
            app.footer = uieditfield(app.import4dGrid, 'numeric');
            app.footer.Limits = [0 Inf];
            app.footer.RoundFractionalValues = 'on';
            app.footer.ValueDisplayFormat = '%.0f bytes';
            app.footer.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.footer.Tooltip = {'HEADER is the number of bytes before each diffraction pattern.'};
            app.footer.Value = 1024;
            app.bitdepthDropdown = uipanel(app.import4dGrid);
            app.bitdepth = uidropdown(app.bitdepthDropdown);
            app.bitdepth.Items = {'8-bit Unsigned', '16-bit Signed', '16-bit Unsigned', '32-bit Real', '32-bit Signed', '32-bit Unsigned', '64-bit Real'};
            app.bitdepth.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.bitdepth.Value = '32-bit Real';
            app.convertFloat = uicheckbox(app.bitdepthDropdown);
            app.convertFloat.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.convertFloat.Visible = 'off';
            app.convertFloat.Tooltip = {'Float conversion can improve performance; however, it will increase memory usage for integer types lower than int32. For example int16 => single will double the amout of memory used.'};
            app.convertFloat.Text = 'convert to float';
            app.endiannessPanel = uipanel(app.import4dGrid);
            app.endiannessGrid = uigridlayout(app.endiannessPanel);
            app.endiannessButtonGroup = uibuttongroup(app.endiannessGrid);
            app.endiannessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.bigEndian = uiradiobutton(app.endiannessButtonGroup);
            app.bigEndian.Text = 'Big endian';
            app.littleEndian = uiradiobutton(app.endiannessButtonGroup);
            app.littleEndian.Text = 'Little endian';
            app.littleEndian.Value = true;
            app.estFilesizeLabel = uilabel(app.import4dGrid);
            app.estFilesizeLabel.Text = 'Estimated size:';
            app.estFilesize = uilabel(app.import4dGrid);
            app.estFilesize.Text = '';
            app.actualFilesizeLabel = uilabel(app.import4dGrid);
            app.actualFilesizeLabel.Text = 'Actual file size:';
            app.actualFilesize = uilabel(app.import4dGrid);
            app.actualFilesize.Text = '';
            app.memoryNotes = uitextarea(app.import4dGrid);
            app.memoryNotes.Editable = 'off';
            app.memoryNotes.Tooltip = {'GPU acceleration can provide a significant improvement in performance.'};
            app.gpuPanel = uipanel(app.import4dGrid);
            app.gpuPanelGrid = uigridlayout(app.gpuPanel);
            app.gpu = uidropdown(app.gpuPanelGrid);
            app.gpu.Items = {'GPU Off'};
            app.gpu.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.gpu.Visible = 'off';
            app.gpu.Value = 'GPU Off';
            app.importData = uibutton(app.import4dGrid, 'push');
            app.importData.ButtonPushedFcn = createCallbackFcn(app, @importDataPushed, true);
            app.importData.Text = 'Import Data';
            app.cancel = uibutton(app.import4dGrid, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.cancel.Text = 'Close';
            app.detectorTypeGroup = uibuttongroup(app.import4dGrid);
            app.detectorTypeGroup.SelectionChangedFcn = createCallbackFcn(app, @detectorType_callback, true);
            app.detectorTypeGroup.Tooltip = {''};
            app.detectorTypeGroup.Title = 'Data type:';
            app.EMPADButton = uitogglebutton(app.detectorTypeGroup);
            app.EMPADButton.Text = 'EMPAD';
            app.EMPADButton.Value = true;
            app.MedipixButton = uitogglebutton(app.detectorTypeGroup);
            app.MedipixButton.Text = 'Medipix';
            app.SimulatedButton = uitogglebutton(app.detectorTypeGroup);
            app.SimulatedButton.Text = 'Simulated';
            app.OtherButton = uitogglebutton(app.detectorTypeGroup);
            app.OtherButton.Text = 'Other';
            app.MRCButton = uitogglebutton(app.detectorTypeGroup);
            app.MRCButton.Text = 'MRC';
            app.detectorHelp = uiimage(app.detectorTypeGroup);
            app.detectorHelp.Tooltip = {'EMPAD'; '128x128 pixels'; 'Header: 0 bytes'; 'Footer: 1024 bytes'; '32-bit Real, Little endian'; ''; 'MEDIPIX'; '256x256 pixels'; 'Header: 384 bytes'; 'Footer: 0 bytes'; 'Unsigned integer, Big endian'; ''; 'MRC'; 'variable # of pixels'; 'Header: 1024 bytes'; 'Footer: 0 bytes'; 'Signed integer, Little endian'};
            app.detectorHelp.ImageSource = 'help.png';
            app.DM4Button = uitogglebutton(app.detectorTypeGroup);
            app.DM4Button.Text = 'DM4';
            app.memoryTypeGroup = uibuttongroup(app.import4dGrid);
            app.memoryTypeGroup.SelectionChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.memoryTypeGroup.Tooltip = {''};
            app.memoryTypeGroup.Title = 'Memory:';
            app.PhysicalMemoryButton = uitogglebutton(app.memoryTypeGroup);
            app.PhysicalMemoryButton.Text = 'Physical Memory';
            app.PhysicalMemoryButton.Value = true;
            app.VirtualMemoryButton = uitogglebutton(app.memoryTypeGroup);
            app.VirtualMemoryButton.Text = 'Virtual Memory';
            app.memHelp = uiimage(app.memoryTypeGroup);
            app.memHelp.Tooltip = {'VIRTUAL MEMORY is best for most large datasets as it does not physically load the dataset into RAM. This can be significantly faster depending on drive speed and file size. HOWEVER - it is currently only beneficial for datasets with no header of footer.'; ''; 'Virtual mapping to the GPU is not recommended at this point. It is extremely fast to load, but still requires significant GPU memory resources.'};
            app.memHelp.ImageSource = 'help.png';
            app.notesPanel = uipanel(app.import4dGrid);
            app.notesPanel.Title = 'Notes:';
            app.notesPanelGrid = uigridlayout(app.notesPanel);
            app.notes = uitextarea(app.notesPanelGrid);
            app.notes.Editable = 'off';
            app.notes.Value = {'128x128 pixels'; 'Header: 0 bytes'; 'Footer: 1024 bytes'; '32-bit Real'; 'Little endian'};
            % Show the figure after all components are created
            app.import_4d.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = import4Dstem(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.import_4d)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.import_4d)
        end
    end
end