classdef save4Dimages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        saveImages_Fig           matlab.ui.Figure
        SelectimagestosaveLabel  matlab.ui.control.Label
        save_list                matlab.ui.control.ListBox
        SaveImagesButton         matlab.ui.control.Button
        FilenamePrefixLabel      matlab.ui.control.Label
        prefix_field             matlab.ui.control.EditField
        SaveAllCheckBox          matlab.ui.control.CheckBox
        CloseButton              matlab.ui.control.Button
        AppendtonamePanel        matlab.ui.container.Panel
        InnerAngleCheckBox       matlab.ui.control.CheckBox
        OuterAngleCheckBox       matlab.ui.control.CheckBox
    end


    properties (Access = private)
        image_list
        current_list
        previous_list
        path
        CallingApp
    end

    methods (Access = public)
    
        function saveImages(app)
            if strcmp(app.saveImages_Fig.Visible,'on')
                % Give the user a progress bar in case it takes some time to save
                import_bar = uiprogressdlg(app.saveImages_Fig,'Title','Save','Message','Saving Images...','Indeterminate',"on");
            end
            % Save as 32-bit unscaled, uncompressed Tiff images
            tagstruct.Compression = Tiff.Compression.None;
            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
            tagstruct.BitsPerSample = 32;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            
            % PACBED image
            if ismember('PACBED.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'PACBED.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.pacbed, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.pacbed, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.pacbed,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.pacbed)));
                t.close();
            end
            
            % BF/DF Image
            if ismember('image.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'image.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.adf, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.adf, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.adf,3);
                t.setTag(tagstruct);
                t.write(gather(single(gather(app.CallingApp.images.adf))));
                t.close();
            end
            
            % DPCx image
            if ismember('DPCx.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'DPCx.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comx, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comx, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comx,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comx)));
                t.close();
            end
            
            % DPCy image
            if ismember('DPCy.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'DPCy.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comy, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comy, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comy,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comy)));
                t.close();
            end 
            
            % CoMx image
            if ismember('CoMx.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'CoMx.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comx, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comx, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comx,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comx)));
                t.close();
            end
            
            % CoMy image
            if ismember('CoMy.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'CoMy.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comy, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comy, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comy,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comy)));
                t.close();
            end 

            % Detector mask(s)
            if ismember('detectorMask.tif',app.current_list)
                switch app.CallingApp.detectorTypeButtonGroup.SelectedObject
                    case app.CallingApp.dpc % DPC
                        % Save all mask segments into a TIFF stack
                        for j=1:app.CallingApp.nrung.Value
                            for i=1:app.CallingApp.nseg.Value
                                imwrite(gather(uint8(reshape(app.CallingApp.mask.all(app.CallingApp.nseg.Value*(j-1)+i,:),[app.CallingApp.params.nopix app.CallingApp.params.nopiy]))),app.save_list.Value{ismember(app.current_list,'detectorMask.tif')},'WriteMode','append');
                            end
                        end
                    case app.CallingApp.com % COM
                        imwrite(gather(uint8(reshape(app.CallingApp.mask.sum,[app.CallingApp.params.nopix app.CallingApp.params.nopiy]))),app.save_list.Value{ismember(app.current_list,'detectorMask.tif')});
                    otherwise % BF/DF and Virtual Aperture
                        imwrite(gather(uint8(reshape(app.CallingApp.mask.bfdf,[app.CallingApp.params.nopix app.CallingApp.params.nopiy]))),app.save_list.Value{ismember(app.current_list,'detectorMask.tif')});
                end
            end

            % CoM magnitude image
            if ismember('CoMMag.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'CoMMag.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comMag, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comMag, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comMag,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comMag)));
                t.close();
            end 

            % DPC magnitude image
            if ismember('DPCMag.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'DPCMag.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comMag, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comMag, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comMag,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comMag)));
                t.close();
            end 
            
            % CoM phase image
            if ismember('CoMPhase.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'CoMPhase.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comPhase, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comPhase, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comPhase,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comPhase)));
                t.close();
            end
            
            % DPC phase image
            if ismember('DPCPhase.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'DPCPhase.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.comPhase, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.comPhase, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.comPhase,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.comPhase)));
                t.close();
            end
            
            % CoM vector image
            if ismember('CoM.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'CoM.tif')};
                com=uint8(app.CallingApp.images.comPhase/(2*pi)*2^8);
                temp_colormap=hsv(2^8);
                for i=1:size(temp_colormap,1)
                    com_rgb(com==i,1)=temp_colormap(i,1); %#ok<*AGROW> 
                    com_rgb(com==i,2)=temp_colormap(i,2);
                    com_rgb(com==i,3)=temp_colormap(i,3);
                end
                com_rgb=reshape(com_rgb,[app.CallingApp.params.framesy,app.CallingApp.params.framesx,3]);
                imwrite(gather(double(com_rgb.*app.CallingApp.images.comMagNorm)),filename);
            end
            
            % DPC vector image
            if ismember('DPC.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'DPC.tif')};
                com=uint8(app.CallingApp.images.comPhase/(2*pi)*2^8);
                temp_colormap=hsv(2^8);
                for i=1:size(temp_colormap,1)
                    com_rgb(com==i,1)=temp_colormap(i,1); %#ok<*AGROW> 
                    com_rgb(com==i,2)=temp_colormap(i,2);
                    com_rgb(com==i,3)=temp_colormap(i,3);
                end
                com_rgb=reshape(com_rgb,[app.CallingApp.params.framesy,app.CallingApp.params.framesx,3]);
                imwrite(gather(double(com_rgb.*app.CallingApp.images.comMagNorm)),filename);
            end
            
            % dCoM image
            if ismember('dCoM.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'dCoM.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.dcom, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.dcom, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.dcom,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.dcom)));
                t.close();
            end 
            
            % dDPC image
            if ismember('dDPC.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'dDPC.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.dcom, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.dcom, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.dcom,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.dcom)));
                t.close();
            end 
            
            % iCoM image
            if ismember('iCoM.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'iCoM.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.icom, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.icom, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.icom,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.icom)));
                t.close();
            end 
            % iDPC image
            if ismember('iDPC.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'iDPC.tif')};
                t = Tiff(filename, 'w');
                tagstruct.ImageLength = size(app.CallingApp.images.icom, 1);
                tagstruct.ImageWidth = size(app.CallingApp.images.icom, 2);
                tagstruct.SamplesPerPixel = size(app.CallingApp.images.icom,3);
                t.setTag(tagstruct);
                t.write(gather(single(app.CallingApp.images.icom)));
                t.close();
            end 
            
            % Vector color wheel
            if ismember('colorWheel.tif',app.current_list)
                filename = app.save_list.Value{ismember(app.current_list,'colorWheel.tif')};
                colorwheel=uint8(app.CallingApp.images.colorWheel/(2*pi)*(2^8-1))+1;
                [xgrid, ygrid] = meshgrid(1:app.CallingApp.params.nopix, 1:app.CallingApp.params.nopiy);
                mask=((xgrid-app.CallingApp.centerX.Value).^2 + (ygrid-app.CallingApp.centerY.Value).^2) <= (app.CallingApp.params.nopix/2)^2;
                temp_colormap=hsv(2^8);
                for i=1:size(temp_colormap,1)
                    colorwheel_rgb(colorwheel==i,1)=temp_colormap(i,1);
                    colorwheel_rgb(colorwheel==i,2)=temp_colormap(i,2);
                    colorwheel_rgb(colorwheel==i,3)=temp_colormap(i,3);
                end
                colorwheel_rgb=reshape(colorwheel_rgb,[app.CallingApp.params.nopix,app.CallingApp.params.nopix,3]).*mask;
                imwrite(gather(colorwheel_rgb),filename);
            end
            
            
            % Pass save preferences back to the main app
            saveImagePrefs(app.CallingApp,app.prefix_field.Value,app.InnerAngleCheckBox.Value,app.OuterAngleCheckBox.Value,app.SaveAllCheckBox.Value,app.previous_list)
            
            try close(import_bar);catch;end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appMain, savePrefs)
           app.saveImages_Fig.Visible='off';
            
            % Read in data from the main GUI
            app.CallingApp=appMain;
            
            % Define images available to be saved based on the image
            % condiiton in the main GUI
            switch app.CallingApp.detectorTypeButtonGroup.SelectedObject
                case app.CallingApp.adf %BF/DF
                app.image_list={'image.tif';
                                'PACBED.tif';
                                'detectorMask.tif'};
                case app.CallingApp.vdf % Virtual aperture
                app.image_list={'image.tif';
                                'PACBED.tif';
                                'detectorMask.tif'};
                case app.CallingApp.dpc % DPC
                app.image_list={'image.tif';
                                'PACBED.tif';
                                'DPCx.tif';
                                'DPCy.tif';
                                'DPCMag.tif';
                                'DPCPhase.tif';
                                'dDPC.tif';
                                'DPC.tif';
                                'iDPC.tif';
                                'colorWheel.tif';
                                'detectorMask.tif'};
                case app.CallingApp.com % COM
                app.image_list={'image.tif';
                                'PACBED.tif';
                                'CoMx.tif';
                                'CoMy.tif';
                                'CoMMag.tif';
                                'CoMPhase.tif';
                                'dCoM.tif';
                                'CoM.tif';
                                'iCoM.tif';
                                'colorWheel.tif';
                                'detectorMask.tif'};
            end
            
            % Apply available images to the save list
            app.save_list.Items=app.image_list;
            
            % Read in save prefernces
            app.prefix_field.Value       = savePrefs.prefix;
            app.previous_list            = savePrefs.previous_list;
            app.InnerAngleCheckBox.Value = savePrefs.InnerAngleCheckBox.Value;
            app.OuterAngleCheckBox.Value = savePrefs.OuterAngleCheckBox.Value;
            app.SaveAllCheckBox.Value    = savePrefs.SaveAllCheckBox.Value;
            
            % Get GUI to apply preferences and prefix if specified
            tmp.Source.Type = 'uieditfield';
            tmp.Value=app.prefix_field.Value;
            prefix_Callback(app,tmp)
            
            % pre-select which images to save based on save preferences
            app.save_list.Value=app.save_list.Items(app.previous_list~=0);
            
            % Define the list of currently selected images
            app.current_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
            
            if savePrefs.autosave
                % Set the directory
                app.path=pwd;
                % Save images
                saveImages(app);
                saveImagePrefs(app.CallingApp,app.prefix_field.Value,app.InnerAngleCheckBox.Value,app.OuterAngleCheckBox.Value,app.SaveAllCheckBox.Value,app.previous_list);
                % Close any/all save dialogs still open
                if ~isempty(findall(0,'Name','Save Images'))
                    close(findall(0,'Name','Save Images'))
                end
            else
                app.saveImages_Fig.Visible='on';
            end
            
        end

        % Callback function: InnerAngleCheckBox, OuterAngleCheckBox, 
        % prefix_field, prefix_field
        function prefix_Callback(app, event)
            % Typing in the prefix box or checking/unchecking tickboxes?
            switch event.Source.Type
                case "uieditfield" % Prefix specified
                    if isempty(event.Value) % empty
                        % Include inner angle, but not outer angle
                        if app.InnerAngleCheckBox.Value && ~app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2fmrad_',app.CallingApp.adfInnerField.Value),app.image_list);
                        % Include outer angle, but not inner angle
                        elseif app.OuterAngleCheckBox.Value && ~app.InnerAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2fmrad_',app.CallingApp.adfOuterField.Value),app.image_list);
                        % Include both inner and outer angles
                        elseif app.InnerAngleCheckBox.Value && app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2f_to_%06.2fmrad_',app.CallingApp.adfInnerField.Value,app.CallingApp.adfOuterField.Value),app.image_list);
                        % Do not include either inner or outer angles
                        else
                            app.save_list.Items=app.image_list;
                        end
                    else % prefix specified
                        % Include inner angle, but not outer angle
                        if app.InnerAngleCheckBox.Value && ~app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(event.Value,'_',sprintf('%06.2fmrad_',app.CallingApp.adfInnerField.Value),app.image_list);
                        % Include outer angle, but not inner angle
                        elseif app.OuterAngleCheckBox.Value && ~app.InnerAngleCheckBox.Value
                            app.save_list.Items=strcat(event.Value,'_',sprintf('%06.2fmrad_',app.CallingApp.adfOuterField.Value),app.image_list);
                        % Include both inner and outer angles
                        elseif app.InnerAngleCheckBox.Value && app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(event.Value,'_',sprintf('%06.2f_to_%06.2fmrad_',app.CallingApp.adfInnerField.Value,app.CallingApp.adfOuterField.Value),app.image_list);
                        % Do not include either inner or outer angles
                        else
                            app.save_list.Items=strcat(event.Value,'_',app.image_list);
                        end
                        
                    end
                case "uicheckbox" % Check boxes for image info (inner angle, outer angle)
                    if isempty(app.prefix_field.Value) % No prefix field specified
                        % Include inner angle, but not outer angle
                        if app.InnerAngleCheckBox.Value && ~app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2fmrad_',app.CallingApp.adfInnerField.Value),app.image_list);
                        % Include outer angle, but not inner angle
                        elseif app.OuterAngleCheckBox.Value && ~app.InnerAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2fmrad_',app.CallingApp.adfOuterField.Value),app.image_list);
                        % Include both inner and outer angles
                        elseif app.InnerAngleCheckBox.Value && app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(sprintf('%06.2f_to_%06.2fmrad_',app.CallingApp.adfInnerField.Value,app.CallingApp.adfOuterField.Value),app.image_list);
                        % Do not include either inner or outer angles
                        else
                            app.save_list.Items=app.image_list;
                        end
                    else % Prefix specified
                        % Include inner angle, but not outer angle
                        if app.InnerAngleCheckBox.Value && ~app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(app.prefix_field.Value,'_',sprintf('%06.2fmrad_',app.CallingApp.adfInnerField.Value),app.image_list);
                        % Include outer angle, but not inner angle
                        elseif app.OuterAngleCheckBox.Value && ~app.InnerAngleCheckBox.Value
                            app.save_list.Items=strcat(app.prefix_field.Value,'_',sprintf('%06.2fmrad_',app.CallingApp.adfOuterField.Value),app.image_list);
                        % Include both inner and outer angles
                        elseif app.InnerAngleCheckBox.Value && app.OuterAngleCheckBox.Value
                            app.save_list.Items=strcat(app.prefix_field.Value,'_',sprintf('%06.2f_to_%06.2fmrad_',app.CallingApp.adfInnerField.Value,app.CallingApp.adfOuterField.Value),app.image_list);
                        % Do not include either inner or outer angles
                        else
                            app.save_list.Items=strcat(app.prefix_field.Value,'_',app.image_list);
                        end
                    end
            end
            
            % If "Save All" checkbox is selected, keep all images in the list selected
            switch app.SaveAllCheckBox.Value
                case 1 % Checked
                    app.previous_list = 1:size(app.save_list.Items,2);
            end
            app.save_list.Value=app.save_list.Items(app.previous_list~=0);
            
        end

        % Value changed function: SaveAllCheckBox
        function save_all_callback(app, event)
            % Just in case app.previous_list is empty
            if isempty(app.previous_list)
                app.previous_list=1; end
            
            % Set which values are selected
            switch app.SaveAllCheckBox.Value
                case 1 % Checked
                    app.save_list.Value=app.save_list.Items;
                case 0 % Unchecked
                    app.save_list.Value=app.save_list.Items(app.previous_list~=0);
            end
            app.current_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));        
        end

        % Value changed function: save_list
        function selection_Callback(app, event)
            % Define the list of previously selected images
            app.previous_list=ismember(app.save_list.Items,app.save_list.Value).*(1:size(app.save_list.Items,2));
            if isempty(app.previous_list)
                app.previous_list=1; end
            
            % Define the list of currently selected images
            app.current_list=app.image_list(ismember(app.save_list.Items,app.save_list.Value));
            
            % If user selects all of the images in the list, tick the
            % "Save All" checkbox
            switch sum(ismember(app.save_list.Items,app.save_list.Value))==size(app.save_list.Items,2)
                case 1 % All items selected
                    app.SaveAllCheckBox.Value=1;
                case 0 % All items not selected
                    app.SaveAllCheckBox.Value=0;
            end            
        end

        % Button pushed function: SaveImagesButton
        function save_Callback(app, event)
            % Set the directory
            app.path=pwd;
            
            % Save images
            saveImages(app);
            
            % Close the window
            closereq
        end

        % Button pushed function: CloseButton
        function closePushed(app, event)
            % Pass save preferences back to the main app
            saveImagePrefs(app.CallingApp,app.prefix_field.Value,app.InnerAngleCheckBox.Value,app.OuterAngleCheckBox.Value,app.SaveAllCheckBox.Value,app.previous_list)
            
            % Close the window
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.saveImages_Fig = uifigure('Visible', 'off');
            app.saveImages_Fig.Name = 'Save Images';
            app.SelectimagestosaveLabel = uilabel(app.saveImages_Fig);
            app.SelectimagestosaveLabel.Text = 'Select images to save';
            app.save_list = uilistbox(app.saveImages_Fig);
            app.save_list.Items = {};
            app.save_list.Multiselect = 'on';
            app.save_list.ValueChangedFcn = createCallbackFcn(app, @selection_Callback, true);
            app.save_list.Value = {};
            app.SaveImagesButton = uibutton(app.saveImages_Fig, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save_Callback, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.FilenamePrefixLabel = uilabel(app.saveImages_Fig);
            app.FilenamePrefixLabel.Text = 'Filename Prefix:';
            app.prefix_field = uieditfield(app.saveImages_Fig, 'text');
            app.prefix_field.ValueChangedFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.prefix_field.ValueChangingFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.SaveAllCheckBox = uicheckbox(app.saveImages_Fig);
            app.SaveAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @save_all_callback, true);
            app.SaveAllCheckBox.Text = 'Save all';
            app.CloseButton = uibutton(app.saveImages_Fig, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.CloseButton.Text = 'Close';
            app.AppendtonamePanel = uipanel(app.saveImages_Fig);
            app.AppendtonamePanel.Title = 'Append to name:';
            app.InnerAngleCheckBox = uicheckbox(app.AppendtonamePanel);
            app.InnerAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.InnerAngleCheckBox.Text = 'Inner Angle';
            app.OuterAngleCheckBox = uicheckbox(app.AppendtonamePanel);
            app.OuterAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @prefix_Callback, true);
            app.OuterAngleCheckBox.Text = 'Outer Angle';
            % Show the figure after all components are created
            app.saveImages_Fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = save4Dimages(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.saveImages_Fig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.saveImages_Fig)
        end
    end
end